classdef SACCURATE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyUIFigure                     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ReadEyeMovementFileMenu        matlab.ui.container.Menu
        SaveEyeMovementFileMenu        matlab.ui.container.Menu
        SaveEyeMovementFileAsMenu      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ViewHelpMenu                   matlab.ui.container.Menu
        ToolPanel                      matlab.ui.container.Panel
        GoEndButton                    matlab.ui.control.Button
        GoStartButton                  matlab.ui.control.Button
        UndoAllButton                  matlab.ui.control.Button
        UndoButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        RecordingDropDown              matlab.ui.control.DropDown
        RecordingLabel                 matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        OutlierPercentagePanel         matlab.ui.container.Panel
        DefaultDetectOutlierButton     matlab.ui.control.Button
        DetectOutlierButton            matlab.ui.control.Button
        OutlierPercentageSlider        matlab.ui.control.Slider
        OutlierPercentageLabel         matlab.ui.control.Label
        SlashLabel_2                   matlab.ui.control.Label
        SlashLabel_1                   matlab.ui.control.Label
        ROISaccadeTotalNumberLabel     matlab.ui.control.Label
        ROISaccadeCounterNumberLabel   matlab.ui.control.Label
        ROISaccadeCounterLabel         matlab.ui.control.Label
        GlobalSaccadeTotalNumberLabel  matlab.ui.control.Label
        GlobalSaccadeCounterNumberLabel  matlab.ui.control.Label
        GlobalSaccadeCounterLabel      matlab.ui.control.Label
        YAxisLogScaleCheckBox          matlab.ui.control.CheckBox
        MainSequenceXFeatureDropDown   matlab.ui.control.DropDown
        MainSequenceYFeatureDropDown   matlab.ui.control.DropDown
        TraceLabel_3                   matlab.ui.control.Label
        TraceLabel_2                   matlab.ui.control.Label
        XAxisLogScaleCheckBox          matlab.ui.control.CheckBox
        MainSequenceShowingModeDropDown  matlab.ui.control.DropDown
        ModeLabel                      matlab.ui.control.Label
        MainSequenceSelectedTraceDropDown  matlab.ui.control.DropDown
        TraceLabel                     matlab.ui.control.Label
        SelectedOnTraceNumberLabel     matlab.ui.control.Label
        SelectedOnTraceLabel           matlab.ui.control.Label
        WindowTimeDurationNumberLabel  matlab.ui.control.Label
        WindowTimeDurationLabel        matlab.ui.control.Label
        MoveLengthNumberLabel          matlab.ui.control.Label
        MoveLengthLabel                matlab.ui.control.Label
        FrequencyNumberLabel           matlab.ui.control.Label
        FrequencyLabel                 matlab.ui.control.Label
        HelpImage                      matlab.ui.control.Image
        TraceUIAxes                    matlab.ui.control.UIAxes
        MainSequenceUIAxes             matlab.ui.control.UIAxes
        SaccadeReferenceUIAxes         matlab.ui.control.UIAxes
        XUIAxes                        matlab.ui.control.UIAxes
        VUIAxes                        matlab.ui.control.UIAxes
        YUIAxes                        matlab.ui.control.UIAxes
        SaccadeUIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
        inf_small;
        min_draw_saccade_time_duration;
        cutoff_freq;
        b_butter;
        a_butter;
        min_ind_length_for_butter;
        comparison_time_tolerance;
        current_task_list;
        current_task;
        primary_target_amplitude;
        secondary_target_amplitude;
        X_origin;
        Y_origin;
        TrialList;
        X_primary_target_for_trial;
        Y_primary_target_for_trial;
        X_secondary_target_for_trial;
        Y_secondary_target_for_trial;
        curation_metadata;
        X;
        Y;
        nan_distance_check_list;
        X_target;
        Y_target;
        V;
        SaccadeReference;
        SaccadeTag;
        Saccade;
        TrialTag;
        trial_start_distance_check_list;
        trial_end_distance_check_list
        Probability;
        signal_bin_length;
        frequency;
        time;
        Saccade_Detail_Feature
        Saccade_Detail_Feature_Text;
        Saccade_Detail;
        signal_time_length
        window_time_onset;
        window_time_duration;
        window_time_offset;
        move_time_length;
        ylim_down_Saccade;
        ylim_up_Saccade;
        ylim_down_X;
        ylim_up_X;
        ylim_down_Y;
        ylim_up_Y;
        ylim_down_V;
        ylim_up_V;
        ylim_down_SaccadeReference;
        ylim_up_SaccadeReference;
        trace_plot_magnification_rate;
        main_sequence_x_lower_bound;
        main_sequence_x_higher_bound;
        main_sequence_y_lower_bound;
        main_sequence_y_higher_bound;
        main_sequence_selected_trace;
        main_sequence_showing_mode;
        main_sequence_x_feature;
        main_sequence_y_feature;
        select_time_first;
        select_time_second;
        labeled_saccade_trace_class;
        labeled_saccade_onset_time;
        labeled_saccade_offset_time;
        main_sequence_anchor_x;
        main_sequence_anchor_y;
        main_sequence_anchor_bounds;
        ROI_position;
        ROI_x_feature;
        ROI_y_feature;
        global_saccade_total;
        global_saccade_counter;
        ROI_saccade_total;
        ROI_saccade_counter;
        mouse_down_flag;
        reference_flag;
        show_target_trace_flag;
        click_type;
        click_type_exclude_open;
        patch_handle_ROI;
        line_handle_trace_plot;
        scatter_handle_trace_plot_origin_layout;
        scatter_handle_trace_plot_primary_layout;
        scatter_handle_trace_plot_secondary_layout;
        scatter_handle_trace_plot_primary_target;
        scatter_handle_trace_plot_secondary_target;
        line_handle_trace_plot_assistant_cross1;
        line_handle_trace_plot_assistant_cross2;
        scatter_handle_main_sequence_valid_labeled;
        scatter_handle_main_sequence_invalid_labeled;
        scatter_handle_main_sequence_valid_in_ROI;
        scatter_handle_main_sequence_invalid_in_ROI;
        scatter_handle_main_sequence_valid_rest;
        scatter_handle_main_sequence_invalid_rest;
        line_handle_Saccade;
        line_handle_Saccade_probability;
        xline_handle_Saccade_trial_number;
        line_handle_X_eye;
        line_handle_X_target;
        xline_handle_X_trial_number;
        xline_handle_X_saccade_onset;
        line_handle_Y_eye;
        line_handle_Y_target;
        xline_handle_Y_trial_number;
        xline_handle_Y_saccade_onset;
        line_handle_V_eye;
        xline_handle_V_trial_number;
        xline_handle_V_saccade_onset;
        line_handle_SaccadeReference;
        line_handle_SaccadeReference_probability;
        line_handle_SaccadeReference_assistant_cross1;
        line_handle_SaccadeReference_assistant_cross2;
        text_handle_SaccadeReference_saccade_tag;
        xline_handle_SaccadeReference_trial_number;
        patch_handle_Saccade_on_mouse_region_left;
        patch_handle_Saccade_on_mouse_region_right;
        patch_handle_Saccade_on_labeled_region;
        patch_handle_X_on_mouse_region_left;
        patch_handle_X_on_mouse_region_right;
        patch_handle_X_on_labeled_region;
        patch_handle_Y_on_mouse_region_left;
        patch_handle_Y_on_mouse_region_right;
        patch_handle_Y_on_labeled_region;
        patch_handle_V_on_mouse_region_left;
        patch_handle_V_on_mouse_region_right;
        patch_handle_V_on_labeled_region;
        patch_handle_SaccadeReference_on_mouse_region_left;
        patch_handle_SaccadeReference_on_mouse_region_right;
        patch_handle_SaccadeReference_on_labeled_region;
        eye_movement_file_path;
        session_directory;
        recording_id_list;
        recording_id;
        stack_Saccade;
        stack_Saccade_Detail;
        backup_Saccade;
        backup_Saccade_Detail;
        outlier_percentage_threshold;
    end
    
    methods (Access = private)

        function time_range_plot_and_highlight_init(app)
            app.line_handle_Saccade = plot(app.SaccadeUIAxes, nan, nan);
            hold(app.SaccadeUIAxes, "on");
            app.line_handle_Saccade_probability = plot(app.SaccadeUIAxes, nan, nan, 'color', [0, 0, 0]);
            app.line_handle_Saccade_probability.Color = [app.line_handle_Saccade_probability.Color, 0.2];
            hold(app.SaccadeUIAxes, "off");
            app.xline_handle_Saccade_trial_number = xline(app.SaccadeUIAxes, -1, '-k', '');
            app.line_handle_X_eye = plot(app.XUIAxes, nan, nan);
            hold(app.XUIAxes, "on");
            app.line_handle_X_target = plot(app.XUIAxes, nan, nan, '--');
            hold(app.XUIAxes, "off")
            app.xline_handle_X_trial_number = xline(app.XUIAxes, -1, '-k', '');
            app.xline_handle_X_saccade_onset = xline(app.XUIAxes, -1, '-g');
            app.line_handle_Y_eye = plot(app.YUIAxes, nan, nan);
            hold(app.YUIAxes, "on");
            app.line_handle_Y_target = plot(app.YUIAxes, nan, nan, '--');
            hold(app.YUIAxes, "off")
            app.xline_handle_Y_trial_number = xline(app.YUIAxes, -1, '-k', '');
            app.xline_handle_Y_saccade_onset = xline(app.YUIAxes, -1, '-g');
            app.line_handle_V_eye = plot(app.VUIAxes, nan, nan);
            app.xline_handle_V_trial_number = xline(app.VUIAxes, -1, '-k', '');
            app.xline_handle_V_saccade_onset = xline(app.VUIAxes, -1, '-g');
            app.line_handle_SaccadeReference = plot(app.SaccadeReferenceUIAxes, nan, nan);
            hold(app.SaccadeReferenceUIAxes, "on");
            app.line_handle_SaccadeReference_probability = plot(app.SaccadeReferenceUIAxes, nan, nan, 'color', [0, 0, 0]);
            app.line_handle_SaccadeReference_probability.Color = [app.line_handle_SaccadeReference_probability.Color, 0.2];
            app.line_handle_SaccadeReference_assistant_cross1 = plot(app.SaccadeReferenceUIAxes, nan, nan, 'color', [0.5, 0.5, 0.5]);
            app.line_handle_SaccadeReference_assistant_cross2 = plot(app.SaccadeReferenceUIAxes, nan, nan, 'color', [0.5, 0.5, 0.5]);
            hold(app.SaccadeReferenceUIAxes, "off")
            app.text_handle_SaccadeReference_saccade_tag = text(app.SaccadeReferenceUIAxes, nan, nan, '', 'HorizontalAlignment', 'center');
            app.xline_handle_SaccadeReference_trial_number = xline(app.SaccadeReferenceUIAxes, -1, '-k', '');
            app.patch_handle_Saccade_on_mouse_region_left = patch(app.SaccadeUIAxes, nan, nan, 'k', 'facecolor', [0 0 1], ...
                        'edgecolor', [0 0 1], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_Saccade_on_mouse_region_right = patch(app.SaccadeUIAxes, nan, nan, 'k', 'facecolor', [1 0 0], ...
                        'edgecolor', [1 0 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_Saccade_on_labeled_region = patch(app.SaccadeUIAxes, nan, nan, 'k', 'facecolor', [0 1 0], ...
                    'edgecolor', [0 1 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_X_on_mouse_region_left = patch(app.XUIAxes, nan, nan, 'k', 'facecolor', [0 0 1], ...
                        'edgecolor', [0 0 1], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_X_on_mouse_region_right = patch(app.XUIAxes, nan, nan, 'k', 'facecolor', [1 0 0], ...
                        'edgecolor', [1 0 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_X_on_labeled_region = patch(app.XUIAxes, nan, nan, 'k', 'facecolor', [0 1 0], ...
                    'edgecolor', [0 1 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_Y_on_mouse_region_left = patch(app.YUIAxes, nan, nan, 'k', 'facecolor', [0 0 1], ...
                        'edgecolor', [0 0 1], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_Y_on_mouse_region_right = patch(app.YUIAxes, nan, nan, 'k', 'facecolor', [1 0 0], ...
                        'edgecolor', [1 0 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_Y_on_labeled_region = patch(app.YUIAxes, nan, nan, 'k', 'facecolor', [0 1 0], ...
                    'edgecolor', [0 1 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_V_on_mouse_region_left = patch(app.VUIAxes, nan, nan, 'k', 'facecolor', [0 0 1], ...
                        'edgecolor', [0 0 1], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_V_on_mouse_region_right = patch(app.VUIAxes, nan, nan, 'k', 'facecolor', [1 0 0], ...
                        'edgecolor', [1 0 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_V_on_labeled_region = patch(app.VUIAxes, nan, nan, 'k', 'facecolor', [0 1 0], ...
                    'edgecolor', [0 1 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_SaccadeReference_on_mouse_region_left = patch(app.SaccadeReferenceUIAxes, nan, nan, 'k', 'facecolor', [0 0 1], ...
                        'edgecolor', [0 0 1], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_SaccadeReference_on_mouse_region_right = patch(app.SaccadeReferenceUIAxes, nan, nan, 'k', 'facecolor', [1 0 0], ...
                        'edgecolor', [1 0 0], 'facealpha', 0.3, 'edgealpha', 0.3);
            app.patch_handle_SaccadeReference_on_labeled_region = patch(app.SaccadeReferenceUIAxes, nan, nan, 'k', 'facecolor', [0 1 0], ...
                    'edgecolor', [0 1 0], 'facealpha', 0.3, 'edgealpha', 0.3);
        end
        
        function time_range_plot(app)
            delete(app.xline_handle_Saccade_trial_number);
            delete(app.xline_handle_X_trial_number);
            delete(app.xline_handle_Y_trial_number);
            delete(app.xline_handle_V_trial_number);
            delete(app.xline_handle_X_saccade_onset);
            delete(app.xline_handle_Y_saccade_onset);
            delete(app.xline_handle_V_saccade_onset);
            delete(app.text_handle_SaccadeReference_saccade_tag);
            delete(app.xline_handle_SaccadeReference_trial_number);
            clear_mouse_patch(app)
            clear_labeled_patch(app)
            ylim(app.SaccadeUIAxes, 'auto');
            ylim(app.XUIAxes, 'auto');
            ylim(app.YUIAxes, 'auto');
            ylim(app.VUIAxes, 'auto');
            ylim(app.SaccadeReferenceUIAxes, 'auto');
            mask = app.time>=app.window_time_onset-app.inf_small & app.time<=app.window_time_offset+app.inf_small;
            time_regional = app.time(mask);
            if ~isempty(app.TrialTag)
                [trial_label, trial_start_ind] = unique(app.TrialTag(mask), "first");
                trial_label = arrayfun(@(a)num2str(a),trial_label,'uni',0);
                trial_start_time = time_regional(trial_start_ind);
            end
            set(app.line_handle_Saccade, 'XData', time_regional, 'YData', app.Saccade(mask))
            if isempty(app.Probability)
                set(app.line_handle_Saccade_probability, 'XData', nan, 'YData', nan)
            else
                set(app.line_handle_Saccade_probability, 'XData', time_regional, 'YData', app.Probability(mask))
            end
            xlim(app.SaccadeUIAxes,[app.window_time_onset, app.window_time_offset]);
            ylim(app.SaccadeUIAxes, [0, 1]);
            if ~isempty(app.TrialTag)
                app.xline_handle_Saccade_trial_number = xline(app.SaccadeUIAxes, trial_start_time, '-k', trial_label);
            end
            set(app.line_handle_X_eye, 'XData', time_regional, 'YData', app.X(mask))
            if app.show_target_trace_flag && ~isempty(app.X_target) && ~isempty(app.Y_target)
                set(app.line_handle_X_target, 'XData', time_regional, 'YData', app.X_target(mask))
            else
                set(app.line_handle_X_target, 'XData', nan, 'YData', nan)
            end
            xlim(app.XUIAxes,[app.window_time_onset, app.window_time_offset]);
            if ~isempty(app.TrialTag)
                app.xline_handle_X_trial_number = xline(app.XUIAxes, trial_start_time, '-k', trial_label);
            end
            set(app.line_handle_Y_eye, 'XData', time_regional, 'YData', app.Y(mask))
            if app.show_target_trace_flag && ~isempty(app.X_target) && ~isempty(app.Y_target)
                set(app.line_handle_Y_target, 'XData', time_regional, 'YData', app.Y_target(mask))
            else
                set(app.line_handle_Y_target, 'XData', nan, 'YData', nan)
            end
            xlim(app.YUIAxes,[app.window_time_onset, app.window_time_offset]);
            if ~isempty(app.TrialTag)
                app.xline_handle_Y_trial_number = xline(app.YUIAxes, trial_start_time, '-k', trial_label);
            end
            set(app.line_handle_V_eye, 'XData', time_regional, 'YData', app.V(mask))
            xlim(app.VUIAxes,[app.window_time_onset, app.window_time_offset]);
            if ~isempty(app.TrialTag)
                app.xline_handle_V_trial_number = xline(app.VUIAxes, trial_start_time, '-k', trial_label);
            end
            if app.reference_flag
                SaccadeReference_regional = app.SaccadeReference(mask);
                set(app.line_handle_SaccadeReference, 'XData', time_regional, 'YData', SaccadeReference_regional)
                set(app.line_handle_SaccadeReference_assistant_cross1, 'XData', nan, 'YData', nan)
                set(app.line_handle_SaccadeReference_assistant_cross2, 'XData', nan, 'YData', nan)
                if isempty(app.Probability)
                    set(app.line_handle_SaccadeReference_probability, 'XData', nan, 'YData', nan)
                else
                    set(app.line_handle_SaccadeReference_probability, 'XData', time_regional, 'YData', app.Probability(mask))
                end
                temp_SaccadeReference = SaccadeReference_regional;
                temp_SaccadeReference_diff = diff(temp_SaccadeReference);
                temp_SaccadeReference_onset = find(temp_SaccadeReference_diff > 0);
                temp_SaccadeReference_offset = find(temp_SaccadeReference_diff < 0);
                temp_SaccadeReference_onset_time = time_regional(temp_SaccadeReference_onset+1);
                if ~isempty(temp_SaccadeReference_onset_time)
                    app.xline_handle_X_saccade_onset = xline(app.XUIAxes, temp_SaccadeReference_onset_time, '-g');
                    app.xline_handle_Y_saccade_onset = xline(app.YUIAxes, temp_SaccadeReference_onset_time, '-g');
                    app.xline_handle_V_saccade_onset = xline(app.VUIAxes, temp_SaccadeReference_onset_time, '-g');
                end
                if ~isempty(app.SaccadeTag)
                    if temp_SaccadeReference(1) == 1 && ~isempty(temp_SaccadeReference_offset)
                        tag_SaccadeReference_offset = temp_SaccadeReference_offset(2:end);
                    else
                        tag_SaccadeReference_offset = temp_SaccadeReference_offset;
                    end
                    if temp_SaccadeReference(end) == 1 && ~isempty(temp_SaccadeReference_onset)
                        tag_SaccadeReference_onset = temp_SaccadeReference_onset(1:end-1);
                    else
                        tag_SaccadeReference_onset = temp_SaccadeReference_onset;
                    end
                    assert(length(tag_SaccadeReference_onset)==length(tag_SaccadeReference_offset), 'debug here');
                    if ~isempty(tag_SaccadeReference_onset)
                        temp_SaccadeTag = app.SaccadeTag(mask);
                        tag_ind = ceil((tag_SaccadeReference_onset+tag_SaccadeReference_offset)/2);
                        tag = temp_SaccadeTag(tag_ind);
                        if ~iscell(tag)
                            tag = arrayfun(@(a)num2str(a),tag,'uni',0);
                        end
                        tag_x = time_regional(tag_ind);
                        tag_x = tag_x(:);
                        tag_y = 0.5.*ones(length(tag_x), 1);
                        app.text_handle_SaccadeReference_saccade_tag = text(app.SaccadeReferenceUIAxes, tag_x, tag_y, tag, 'HorizontalAlignment', 'center');
                    end
                end
                xlim(app.SaccadeReferenceUIAxes, [app.window_time_onset, app.window_time_offset]);
                ylim(app.SaccadeReferenceUIAxes, [0, 1]);
                if ~isempty(app.TrialTag)
                    app.xline_handle_SaccadeReference_trial_number =  xline(app.SaccadeReferenceUIAxes, trial_start_time, '-k', trial_label);
                end
            else
                set(app.line_handle_SaccadeReference, 'XData', nan, 'YData', nan)
                set(app.line_handle_SaccadeReference_assistant_cross1, 'XData', time_regional, 'YData', linspace(0, 1, sum(mask)))
                set(app.line_handle_SaccadeReference_assistant_cross2, 'XData', time_regional, 'YData', linspace(1, 0, sum(mask)))
                set(app.line_handle_SaccadeReference_probability, 'XData', nan, 'YData', nan)
                xlim(app.SaccadeReferenceUIAxes, [app.window_time_onset, app.window_time_offset]);
                ylim(app.SaccadeReferenceUIAxes, [0, 1]);
            end
            temp = app.SaccadeUIAxes.YLim;
            app.ylim_down_Saccade = temp(1);
            app.ylim_up_Saccade = temp(2);
            temp = app.XUIAxes.YLim;
            app.ylim_down_X = temp(1);
            app.ylim_up_X = temp(2);
            temp = app.YUIAxes.YLim;
            app.ylim_down_Y = temp(1);
            app.ylim_up_Y = temp(2);
            temp = app.VUIAxes.YLim;
            app.ylim_down_V = temp(1);
            app.ylim_up_V = temp(2);
            temp = app.SaccadeReferenceUIAxes.YLim;
            app.ylim_down_SaccadeReference = temp(1);
            app.ylim_up_SaccadeReference = temp(2);
        end

        function main_sequence_plot_and_highlight_init(app)
            app.scatter_handle_main_sequence_valid_rest = scatter(app.MainSequenceUIAxes, nan, nan, 5, "black", "filled");
            hold(app.MainSequenceUIAxes, "on");
            app.scatter_handle_main_sequence_invalid_rest= scatter(app.MainSequenceUIAxes, nan, nan, 20, "red", "LineWidth", 2);
            app.scatter_handle_main_sequence_valid_in_ROI = scatter(app.MainSequenceUIAxes, nan, nan, 5, "magenta", "filled");
            app.scatter_handle_main_sequence_invalid_in_ROI= scatter(app.MainSequenceUIAxes, nan, nan, 20, "magenta", "LineWidth", 2);
            app.scatter_handle_main_sequence_valid_labeled = scatter(app.MainSequenceUIAxes, nan, nan, 5+20, "green", "filled");
            app.scatter_handle_main_sequence_invalid_labeled = scatter(app.MainSequenceUIAxes, nan, nan, 20+20, "green", "LineWidth", 2);
            app.patch_handle_ROI = patch(app.MainSequenceUIAxes, nan, nan, 'm', 'facecolor', [1 0 1], ...
                    'edgecolor', [1 0 1], 'facealpha', 0.3, 'edgealpha', 0.3);
            hold(app.MainSequenceUIAxes, "off");
        end

        function main_sequence_plot(app)
            x = app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.main_sequence_x_feature);
            y = app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.main_sequence_y_feature);
            trace = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trace");
            label = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="label");
            validity = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="validity");
            trial = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trial");
            if ~isempty(app.TrialTag)
                validity_filter = (validity==1)&(~isnan(trial));
            else
                validity_filter = (validity==1);
            end
            if strcmp(app.main_sequence_selected_trace,'nonreference')
                mask = trace == 0;
            elseif strcmp(app.main_sequence_selected_trace,'reference')
                mask = trace == 1;
            else
                assert(false, 'app.main_sequence_selected_trace should be either nonreference, or reference, debug the code!')
            end
            if strcmp(app.main_sequence_showing_mode,'all')
                mask_valid = mask & validity_filter;
                mask_invalid = mask & (~validity_filter);
            elseif strcmp(app.main_sequence_showing_mode,'inclusive')
                mask = mask & (label == 1);
                mask_valid = mask & validity_filter;
                mask_invalid = mask & (~validity_filter);
            elseif strcmp(app.main_sequence_showing_mode,'exclusive')
                mask = mask & (label == 0);
                mask_valid = mask & validity_filter;
                mask_invalid = mask & (~validity_filter);
            else
                assert(false, 'app.main_sequence_showing_mode should be either all, inclusive or exclusive, debug the code!')
            end
            if strcmp(app.labeled_saccade_trace_class, app.main_sequence_selected_trace)
                onset_time_list = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="onset_time");
                offset_time_list = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="offset_time");
                mask_labeled = onset_time_list > app.labeled_saccade_onset_time-app.inf_small & ...
                offset_time_list < app.labeled_saccade_offset_time+app.inf_small;
            else
                mask_labeled = false(size(mask_valid));
            end
            if ~isempty(app.ROI_position)
                ROI = images.roi.Polygon("Position", app.ROI_position);
                mask_in_ROI = inROI(ROI,app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_x_feature), ...
                    app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_y_feature));
                delete(ROI);
            else
                mask_in_ROI = false(size(mask_valid));
            end
            mask_in_ROI = mask_in_ROI & (~mask_labeled);
            mask_rest = ~(mask_in_ROI | mask_labeled);
            mask_valid_labeled = mask_valid & mask_labeled;
            mask_invalid_labeled = mask_invalid & mask_labeled;
            mask_valid_in_ROI = mask_valid & mask_in_ROI;
            mask_invalid_in_ROI = mask_invalid & mask_in_ROI;
            mask_valid_rest = mask_valid & mask_rest;
            mask_invalid_rest = mask_invalid & mask_rest;
            set(app.scatter_handle_main_sequence_valid_rest, 'XData', x(mask_valid_rest), 'YData', y(mask_valid_rest))
            set(app.scatter_handle_main_sequence_invalid_rest, 'XData', x(mask_invalid_rest), 'YData', y(mask_invalid_rest))
            set(app.scatter_handle_main_sequence_valid_in_ROI, 'XData', x(mask_valid_in_ROI), 'YData', y(mask_valid_in_ROI))
            set(app.scatter_handle_main_sequence_invalid_in_ROI, 'XData', x(mask_invalid_in_ROI), 'YData', y(mask_invalid_in_ROI))
            set(app.scatter_handle_main_sequence_valid_labeled, 'XData', x(mask_valid_labeled), 'YData', y(mask_valid_labeled))
            set(app.scatter_handle_main_sequence_invalid_labeled, 'XData', x(mask_invalid_labeled), 'YData', y(mask_invalid_labeled))
            xlim(app.MainSequenceUIAxes,[app.main_sequence_x_lower_bound, app.main_sequence_x_higher_bound]);
            ylim(app.MainSequenceUIAxes, [app.main_sequence_y_lower_bound, app.main_sequence_y_higher_bound]);
        end

        function main_sequence_and_ROI_auto_scaled_plot(app)
            clear_ROI_patch(app);
            highlight_ROI_plot(app);
            main_sequence_plot(app);
            app.MainSequenceUIAxes.XLimMode = 'auto';
            app.MainSequenceUIAxes.YLimMode = 'auto';
            rate = 1.1;
            x_anchor = 0.5*(app.MainSequenceUIAxes.XLim(1)+app.MainSequenceUIAxes.XLim(2));
            y_anchor = 0.5*(app.MainSequenceUIAxes.YLim(1)+app.MainSequenceUIAxes.YLim(2));
            if app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                x_lower = real(exp(rate*log(app.MainSequenceUIAxes.XLim(1))+(1-rate)*log(x_anchor)));
                x_higher = real(exp(rate*log(app.MainSequenceUIAxes.XLim(2))+(1-rate)*log(x_anchor)));
                y_lower = real(exp(rate*log(app.MainSequenceUIAxes.YLim(1))+(1-rate)*log(y_anchor)));
                y_higher = real(exp(rate*log(app.MainSequenceUIAxes.YLim(2))+(1-rate)*log(y_anchor)));
            elseif app.XAxisLogScaleCheckBox.Value && ~app.YAxisLogScaleCheckBox.Value
                x_lower = real(exp(rate*log(app.MainSequenceUIAxes.XLim(1))+(1-rate)*log(x_anchor)));
                x_higher = real(exp(rate*log(app.MainSequenceUIAxes.XLim(2))+(1-rate)*log(x_anchor)));
                y_lower = rate*app.MainSequenceUIAxes.YLim(1)+(1-rate)*y_anchor;
                y_higher = rate*app.MainSequenceUIAxes.YLim(2)+(1-rate)*y_anchor;
            elseif ~app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                x_lower = rate*app.MainSequenceUIAxes.XLim(1)+(1-rate)*x_anchor;
                x_higher = rate*app.MainSequenceUIAxes.XLim(2)+(1-rate)*x_anchor;
                y_lower = real(exp(rate*log(app.MainSequenceUIAxes.YLim(1))+(1-rate)*log(y_anchor)));
                y_higher = real(exp(rate*log(app.MainSequenceUIAxes.YLim(2))+(1-rate)*log(y_anchor)));
            else
                x_lower = rate*app.MainSequenceUIAxes.XLim(1)+(1-rate)*x_anchor;
                x_higher = rate*app.MainSequenceUIAxes.XLim(2)+(1-rate)*x_anchor;
                y_lower = rate*app.MainSequenceUIAxes.YLim(1)+(1-rate)*y_anchor;
                y_higher = rate*app.MainSequenceUIAxes.YLim(2)+(1-rate)*y_anchor;
            end
            change_main_sequence_higher_lower_bound(app, x_lower, x_higher, y_lower, y_higher);
            main_sequence_plot(app);
            highlight_ROI_plot(app);
        end


        function trace_plot_init(app)
            app.line_handle_trace_plot = plot(app.TraceUIAxes, nan, nan, 'color','black', 'LineWidth', 2);
            drawnow;
            hold(app.TraceUIAxes, 'on')
            app.scatter_handle_trace_plot_origin_layout = scatter(app.TraceUIAxes, nan, nan, 20, [0.2, 0, 0.2], "LineWidth", 1);
            app.scatter_handle_trace_plot_primary_layout = scatter(app.TraceUIAxes, nan, nan, 20, [0.5, 0, 0.5], "LineWidth", 1);
            app.scatter_handle_trace_plot_secondary_layout = scatter(app.TraceUIAxes, nan, nan, 20, 'magenta', "LineWidth", 1);
            app.scatter_handle_trace_plot_primary_target = scatter(app.TraceUIAxes, nan, nan, 40, 'blue', 'filled');
            app.scatter_handle_trace_plot_secondary_target = scatter(app.TraceUIAxes, nan, nan, 40, 'red', 'filled');
            app.line_handle_trace_plot_assistant_cross1 = plot(app.TraceUIAxes, nan, nan, 'color', [0.5, 0.5, 0.5]);
            app.line_handle_trace_plot_assistant_cross2 = plot(app.TraceUIAxes, nan, nan, 'color', [0.5, 0.5, 0.5]);
            hold(app.TraceUIAxes, 'off')
        end

        function trace_plot(app)
            if ~isnan(app.primary_target_amplitude) && ~isnan(app.secondary_target_amplitude) && ...
                    ~isnan(app.X_origin) && ~isnan(app.Y_origin)
                set(app.line_handle_trace_plot_assistant_cross1, 'XData', nan, 'YData', nan)
                set(app.line_handle_trace_plot_assistant_cross2, 'XData', nan, 'YData', nan)
                rate = app.trace_plot_magnification_rate;
                X_lower_bound = rate*(app.X_origin-app.primary_target_amplitude-app.secondary_target_amplitude)+(1-rate)*app.X_origin;
                X_higher_bound = rate*(app.X_origin+app.primary_target_amplitude+app.secondary_target_amplitude)+(1-rate)*app.X_origin;
                Y_lower_bound = rate*(app.Y_origin-app.primary_target_amplitude-app.secondary_target_amplitude)+(1-rate)*app.Y_origin;
                Y_higher_bound = rate*(app.Y_origin+app.primary_target_amplitude+app.secondary_target_amplitude)+(1-rate)*app.Y_origin;
                xlim(app.TraceUIAxes, [X_lower_bound, X_higher_bound]);
                ylim(app.TraceUIAxes, [Y_lower_bound, Y_higher_bound]);
                X_primary = app.primary_target_amplitude*cos(linspace(0, 1.75*pi, 8))+app.X_origin;
                Y_primary = app.primary_target_amplitude*sin(linspace(0, 1.75*pi, 8))+app.Y_origin;
                X_base =  repmat(X_primary, 8, 1);
                X_base = X_base(:)';
                Y_base =  repmat(Y_primary, 8, 1);
                Y_base = Y_base(:)';
                X_secondary = repmat(app.secondary_target_amplitude*cos(linspace(0, 1.75*pi, 8)), 1, 8) + X_base;
                Y_secondary = repmat(app.secondary_target_amplitude*sin(linspace(0, 1.75*pi, 8)), 1, 8) + Y_base;
                set(app.scatter_handle_trace_plot_origin_layout, 'XData', app.X_origin, 'YData', app.Y_origin)
                set(app.scatter_handle_trace_plot_primary_layout, 'XData', X_primary, 'YData', Y_primary)
                set(app.scatter_handle_trace_plot_secondary_layout, 'XData', X_secondary, 'YData', Y_secondary)
                if(app.labeled_saccade_offset_time-app.labeled_saccade_onset_time > app.inf_small)
                    mask = app.time>=app.labeled_saccade_onset_time-app.inf_small & app.time<=app.labeled_saccade_offset_time+app.inf_small;
                    X_masked = app.X(mask);
                    Y_masked = app.Y(mask);
                    cd = [uint8(flip(gray(sum(mask)))*255) uint8(ones(sum(mask),1))]';
                    set(app.line_handle_trace_plot.Edge, 'ColorBinding','interpolated', 'ColorData',cd);
                    set(app.line_handle_trace_plot, 'XData', X_masked, 'YData', Y_masked)
                    if ~isempty(app.TrialList) && ~isempty(app.X_primary_target_for_trial) && ~isempty(app.Y_primary_target_for_trial) && ...
                            ~isempty(app.X_secondary_target_for_trial) && ~isempty(app.Y_secondary_target_for_trial) && ~isempty(app.TrialTag)
                        temp = ismember(app.TrialList, unique(app.TrialTag(mask)));
                        X_primary_for_trial = app.X_primary_target_for_trial(temp);
                        Y_primary_for_trial = app.Y_primary_target_for_trial(temp);
                        X_secondary_for_trial = app.X_secondary_target_for_trial(temp);
                        Y_secondary_for_trial = app.Y_secondary_target_for_trial(temp);
                        set(app.scatter_handle_trace_plot_primary_target, 'XData', X_primary_for_trial, 'YData', Y_primary_for_trial)
                        set(app.scatter_handle_trace_plot_secondary_target, 'XData', X_secondary_for_trial, 'YData', Y_secondary_for_trial)
                    end
                end
            else
                set(app.scatter_handle_trace_plot_origin_layout, 'XData', nan, 'YData', nan)
                set(app.scatter_handle_trace_plot_primary_layout, 'XData', nan, 'YData', nan)
                set(app.scatter_handle_trace_plot_secondary_layout, 'XData', nan, 'YData', nan)
                if(app.labeled_saccade_offset_time-app.labeled_saccade_onset_time > app.inf_small)
                    set(app.line_handle_trace_plot_assistant_cross1, 'XData', nan, 'YData', nan)
                    set(app.line_handle_trace_plot_assistant_cross2, 'XData', nan, 'YData', nan)
                    app.TraceUIAxes.XLimMode = "auto";
                    app.TraceUIAxes.YLimMode = "auto";
                    mask = app.time>=app.labeled_saccade_onset_time-app.inf_small & app.time<=app.labeled_saccade_offset_time+app.inf_small;
                    X_masked = app.X(mask);
                    Y_masked = app.Y(mask);
                    cd = [uint8(flip(gray(sum(mask)))*255) uint8(ones(sum(mask),1))]';
                    set(app.line_handle_trace_plot.Edge, 'ColorBinding','interpolated', 'ColorData',cd);
                    set(app.line_handle_trace_plot, 'XData', X_masked, 'YData', Y_masked)
                    if ~isempty(app.TrialList) && ~isempty(app.X_primary_target_for_trial) && ~isempty(app.Y_primary_target_for_trial) && ...
                            ~isempty(app.X_secondary_target_for_trial) && ~isempty(app.Y_secondary_target_for_trial)
                        temp = ismember(app.TrialList, unique(app.TrialTag(mask)));
                        X_primary_for_trial = app.X_primary_target_for_trial(temp);
                        Y_primary_for_trial = app.Y_primary_target_for_trial(temp);
                        X_secondary_for_trial = app.X_secondary_target_for_trial(temp);
                        Y_secondary_for_trial = app.Y_secondary_target_for_trial(temp);
                        set(app.scatter_handle_trace_plot_primary_target, 'XData', X_primary_for_trial, 'YData', Y_primary_for_trial)
                        set(app.scatter_handle_trace_plot_secondary_target, 'XData', X_secondary_for_trial, 'YData', Y_secondary_for_trial)
                    end
                    rate = app.trace_plot_magnification_rate;
                    X_lower_bound = rate*(app.TraceUIAxes.XLim(1))+(1-rate)*(app.TraceUIAxes.XLim(1)+app.TraceUIAxes.XLim(2))/2;
                    X_higher_bound = rate*(app.TraceUIAxes.XLim(2))+(1-rate)*(app.TraceUIAxes.XLim(1)+app.TraceUIAxes.XLim(2))/2;
                    Y_lower_bound = rate*(app.TraceUIAxes.YLim(1))+(1-rate)*(app.TraceUIAxes.YLim(1)+app.TraceUIAxes.YLim(2))/2;
                    Y_higher_bound = rate*(app.TraceUIAxes.YLim(2))+(1-rate)*(app.TraceUIAxes.YLim(1)+app.TraceUIAxes.YLim(2))/2;
                    xlim(app.TraceUIAxes, [X_lower_bound, X_higher_bound]);
                    ylim(app.TraceUIAxes, [Y_lower_bound, Y_higher_bound]);
                else
                    set(app.line_handle_trace_plot, 'XData', nan, 'YData', nan)
                    set(app.scatter_handle_trace_plot_primary_target, 'XData', nan, 'YData', nan)
                    set(app.scatter_handle_trace_plot_secondary_target, 'XData', nan, 'YData', nan)
                    X_lim = app.TraceUIAxes.XLim;
                    Y_lim = app.TraceUIAxes.YLim;
                    set(app.line_handle_trace_plot_assistant_cross1, 'XData', X_lim, 'YData', Y_lim)
                    set(app.line_handle_trace_plot_assistant_cross2, 'XData', X_lim, 'YData', flip(Y_lim))
                    xlim(app.TraceUIAxes, X_lim);
                    ylim(app.TraceUIAxes, Y_lim);
                end
            end   
        end
        
        function highlight_ROI_plot(app)
            if ~isempty(app.ROI_position)
                if strcmp(app.main_sequence_x_feature, app.ROI_x_feature) && strcmp(app.main_sequence_y_feature, app.ROI_y_feature)
                    set(app.patch_handle_ROI, 'XData', app.ROI_position(:, 1), 'YData', app.ROI_position(:, 2))
                end
            end
        end

        function highlight_mouse_region_plot(app, select_time_first, select_time_second)
            highlight_region_left = min([select_time_first select_time_second app.window_time_offset]);
            highlight_region_left = max([highlight_region_left, app.window_time_onset]);
            highlight_region_right = max([select_time_first select_time_second app.window_time_onset]);
            highlight_region_right = min([highlight_region_right, app.window_time_offset]);
            if(highlight_region_right-highlight_region_left > app.min_draw_saccade_time_duration+app.inf_small)
                if strcmp(app.click_type, 'normal')
                    set(app.patch_handle_Saccade_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_Saccade app.ylim_down_Saccade app.ylim_up_Saccade app.ylim_up_Saccade])
                    set(app.patch_handle_X_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_X app.ylim_down_X app.ylim_up_X app.ylim_up_X])
                    set(app.patch_handle_Y_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_Y app.ylim_down_Y app.ylim_up_Y app.ylim_up_Y])
                    set(app.patch_handle_V_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_V app.ylim_down_V app.ylim_up_V app.ylim_up_V])
                    set(app.patch_handle_SaccadeReference_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_SaccadeReference app.ylim_down_SaccadeReference app.ylim_up_SaccadeReference app.ylim_up_SaccadeReference])
                    set(app.patch_handle_Saccade_on_mouse_region_right, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_X_on_mouse_region_right, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_Y_on_mouse_region_right, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_V_on_mouse_region_right, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_SaccadeReference_on_mouse_region_right, 'XData', nan, 'YData', nan)
                elseif strcmp(app.click_type, 'alt')
                    set(app.patch_handle_Saccade_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_Saccade app.ylim_down_Saccade app.ylim_up_Saccade app.ylim_up_Saccade])
                    set(app.patch_handle_X_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_X app.ylim_down_X app.ylim_up_X app.ylim_up_X])
                    set(app.patch_handle_Y_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_Y app.ylim_down_Y app.ylim_up_Y app.ylim_up_Y])
                    set(app.patch_handle_V_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_V app.ylim_down_V app.ylim_up_V app.ylim_up_V])
                    set(app.patch_handle_SaccadeReference_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                        [app.ylim_down_SaccadeReference app.ylim_down_SaccadeReference app.ylim_up_SaccadeReference app.ylim_up_SaccadeReference])
                    set(app.patch_handle_Saccade_on_mouse_region_left, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_X_on_mouse_region_left, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_Y_on_mouse_region_left, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_V_on_mouse_region_left, 'XData', nan, 'YData', nan)
                    set(app.patch_handle_SaccadeReference_on_mouse_region_left, 'XData', nan, 'YData', nan)
                elseif strcmp(app.click_type, 'open')
                    if strcmp(app.click_type_exclude_open, 'normal')
                        set(app.patch_handle_Saccade_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_Saccade app.ylim_down_Saccade app.ylim_up_Saccade app.ylim_up_Saccade])
                        set(app.patch_handle_X_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_X app.ylim_down_X app.ylim_up_X app.ylim_up_X])
                        set(app.patch_handle_Y_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_Y app.ylim_down_Y app.ylim_up_Y app.ylim_up_Y])
                        set(app.patch_handle_V_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_V app.ylim_down_V app.ylim_up_V app.ylim_up_V])
                        set(app.patch_handle_SaccadeReference_on_mouse_region_left, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_SaccadeReference app.ylim_down_SaccadeReference app.ylim_up_SaccadeReference app.ylim_up_SaccadeReference])
                        set(app.patch_handle_Saccade_on_mouse_region_right, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_X_on_mouse_region_right, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_Y_on_mouse_region_right, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_V_on_mouse_region_right, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_SaccadeReference_on_mouse_region_right, 'XData', nan, 'YData', nan)
                    elseif strcmp(app.click_type_exclude_open, 'alt')
                        set(app.patch_handle_Saccade_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_Saccade app.ylim_down_Saccade app.ylim_up_Saccade app.ylim_up_Saccade])
                        set(app.patch_handle_X_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_X app.ylim_down_X app.ylim_up_X app.ylim_up_X])
                        set(app.patch_handle_Y_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_Y app.ylim_down_Y app.ylim_up_Y app.ylim_up_Y])
                        set(app.patch_handle_V_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_V app.ylim_down_V app.ylim_up_V app.ylim_up_V])
                        set(app.patch_handle_SaccadeReference_on_mouse_region_right, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                            [app.ylim_down_SaccadeReference app.ylim_down_SaccadeReference app.ylim_up_SaccadeReference app.ylim_up_SaccadeReference])
                        set(app.patch_handle_Saccade_on_mouse_region_left, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_X_on_mouse_region_left, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_Y_on_mouse_region_left, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_V_on_mouse_region_left, 'XData', nan, 'YData', nan)
                        set(app.patch_handle_SaccadeReference_on_mouse_region_left, 'XData', nan, 'YData', nan)
                    else
                        assert(true, 'something wrong with last_click_type_exclude_open here, please check if click type is nan or values other than normal and alt');
                    end
                else
                    assert(true, 'something wrong with click_type here, please check if click type is nan or values other than normal, alt and open');
                end
                ylim(app.SaccadeUIAxes, [app.ylim_down_Saccade, app.ylim_up_Saccade]);
                ylim(app.XUIAxes, [app.ylim_down_X, app.ylim_up_X]);
                ylim(app.YUIAxes, [app.ylim_down_Y, app.ylim_up_Y]);
                ylim(app.VUIAxes, [app.ylim_down_V, app.ylim_up_V]);
                ylim(app.SaccadeReferenceUIAxes, [app.ylim_down_SaccadeReference, app.ylim_up_SaccadeReference]);
            else
                clear_mouse_patch(app);
            end
        end

        function highlight_labeled_region_plot(app)
            highlight_region_left = min([app.labeled_saccade_onset_time app.labeled_saccade_offset_time app.window_time_offset]);
            highlight_region_left = max([highlight_region_left, app.window_time_onset]);
            highlight_region_right = max([app.labeled_saccade_onset_time app.labeled_saccade_offset_time app.window_time_onset]);
            highlight_region_right = min([highlight_region_right, app.window_time_offset]);
            if(highlight_region_right-highlight_region_left > app.inf_small)
                set(app.patch_handle_Saccade_on_labeled_region, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                    [app.ylim_down_Saccade app.ylim_down_Saccade app.ylim_up_Saccade app.ylim_up_Saccade])
                set(app.patch_handle_X_on_labeled_region, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                    [app.ylim_down_X app.ylim_down_X app.ylim_up_X app.ylim_up_X])
                set(app.patch_handle_Y_on_labeled_region, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                    [app.ylim_down_Y app.ylim_down_Y app.ylim_up_Y app.ylim_up_Y])
                set(app.patch_handle_V_on_labeled_region, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                    [app.ylim_down_V app.ylim_down_V app.ylim_up_V app.ylim_up_V])
                set(app.patch_handle_SaccadeReference_on_labeled_region, 'XData', [highlight_region_left highlight_region_right highlight_region_right highlight_region_left], 'YData', ...
                    [app.ylim_down_SaccadeReference app.ylim_down_SaccadeReference app.ylim_up_SaccadeReference app.ylim_up_SaccadeReference])
                ylim(app.SaccadeUIAxes, [app.ylim_down_Saccade, app.ylim_up_Saccade]);
                ylim(app.XUIAxes, [app.ylim_down_X, app.ylim_up_X]);
                ylim(app.YUIAxes, [app.ylim_down_Y, app.ylim_up_Y]);
                ylim(app.VUIAxes, [app.ylim_down_V, app.ylim_up_V]);
                ylim(app.SaccadeReferenceUIAxes, [app.ylim_down_SaccadeReference, app.ylim_up_SaccadeReference]);
            end
        end
        
        function change_time_onset_and_time_offset(app, time_onset)
            app.window_time_onset = max(time_onset, 0);
            app.window_time_onset = min(app.window_time_onset, app.signal_time_length-app.window_time_duration);
            app.window_time_offset = min(app.window_time_duration+app.window_time_onset, ...
                app.signal_time_length);
        end

        function change_main_sequence_higher_lower_bound(app, x_lower, x_higher, y_lower, y_higher)
            app.main_sequence_x_lower_bound = x_lower;
            app.main_sequence_x_higher_bound = x_higher;
            app.main_sequence_y_lower_bound = y_lower;
            app.main_sequence_y_higher_bound = y_higher;
        end

        
        function change_time_duration(app, time_duration)
            if time_duration > 0
                app.window_time_duration = time_duration;
                app.window_time_offset = min(app.window_time_duration+app.window_time_onset, ...
                    app.signal_time_length);
            else
                disp('Show time duration change failed: duration must be larger than 0')
            end
        end
        
        function change_frequency(app, frequency) 
            % this function is currently not used.
            if frequency > 0
                app.frequency = frequency;
                app.time = (0:app.signal_bin_length-1)./app.frequency.*1000;
                app.time = app.time';
                app.signal_time_length = app.time(end);
                app.window_time_onset = 0;
                app.window_time_offset = min(app.window_time_duration+app.window_time_onset, ...
                    app.signal_time_length);
                app.select_time_first = -1;
                app.select_time_second = -1;
            else
                disp('Frequency change failed: frequency mush be larger than 0')
            end
        end
        
        function change_eye_movement_data(app, eye_movement_data)
            if isfield(eye_movement_data, 'CurationMetadata')
                temp_curation_metadata = eye_movement_data.CurationMetadata;
            else
                temp_curation_metadata.curator = [];
                temp_curation_metadata.time = [];
            end
            assert(isfield(eye_movement_data, 'X'), 'Eye movement data should contain X!')
            assert(isfield(eye_movement_data, 'Y'), 'Eye movement data should contain Y!')
            assert(isfield(eye_movement_data, 'Saccade'), 'Eye movement data should contain Saccade!')
            temp_X = eye_movement_data.X;
            temp_Y = eye_movement_data.Y;
            temp_Saccade = eye_movement_data.Saccade;
            assert(ismatrix(temp_X)&&ismatrix(temp_Y)&&ismatrix(temp_Saccade), 'Both X, Y and Saccade should be at least a matrix!')
            assert(all(size(temp_X) == size(temp_Y) & size(temp_X) == size(temp_Saccade)), 'The shape of X, Y and Saccade should be equal!')
            assert(ismember(1, unique(size(temp_X))), 'X, Y and Saccade should be one dimension matrix (array)!')
            Saccade_unique = unique(temp_Saccade);
            Saccade_check = length(Saccade_unique)==2 && ismember(0, Saccade_unique) && ismember(1, Saccade_unique);
            assert(Saccade_check, 'Saccade should contain only 0 and 1')

            if isfield(eye_movement_data, 'Frequency')
                temp_frequency = eye_movement_data.Frequency;
            elseif isfield(eye_movement_data, 'Time')
                temp_frequency = 1/median(diff(eye_movement_data.Time));
            else
                temp_frequency = app.frequency;
            end

            temp_X = temp_X(:);
            temp_Y = temp_Y(:);
            mask_nan = isnan(temp_X) & isnan(temp_Y);
            if any(mask_nan)
                temp_nan_distance = double(bwdist(mask_nan))./app.frequency.*1000;
            else
                temp_nan_distance = [];
            end
            vx = diff(temp_X).*temp_frequency;
            vx = [vx; vx(end)]./1000;
            vy = diff(temp_Y).*temp_frequency;
            vy = [vy; vy(end)]./1000;
            temp_V = sqrt(vx.^2 + vy.^2);

            if isfield(eye_movement_data, 'SaccadeReference')
                temp_SaccadeReference = eye_movement_data.SaccadeReference;
                SaccadeReferece_unique = unique(temp_SaccadeReference);
                Saccade_check = length(SaccadeReferece_unique)==2 && ...
                    ismember(0, SaccadeReferece_unique) && ismember(1, SaccadeReferece_unique) &&...
                    ismatrix(temp_SaccadeReference) && all(size(temp_X) == size(temp_SaccadeReference)) && ...
                    ismember(1, unique(size(temp_SaccadeReference)));
                if Saccade_check
                    temp_SaccadeReference = temp_SaccadeReference(:);
                    temp_reference_flag = true;
                    if isfield(eye_movement_data, 'SaccadeTag')
                        temp_SaccadeTag = eye_movement_data.SaccadeTag;
                        SaccadeTag_check = ismatrix(temp_SaccadeTag) && all(size(temp_X) == size(temp_SaccadeTag)) && ...
                            ismember(1, unique(size(temp_SaccadeTag)));
                        if SaccadeTag_check
                            temp_SaccadeTag = temp_SaccadeTag(:);
                        else
                            disp('SaccadeTag should be an array containing numbers as tag with the same size as X, Y, SaccadeReference and Saccade')
                            temp_SaccadeTag = [];
                        end
                    else
                        disp('SaccadeTag not detected')
                        temp_SaccadeTag = [];
                    end
                else
                    disp('SaccadeReference should be an array containing only 0 and 1 with the same size as X, Y and Saccade')
                    temp_SaccadeReference = [];
                    temp_SaccadeTag = [];
                    temp_reference_flag = false;
                end
            else
                disp('SaccadeReference not detected')
                temp_SaccadeReference = [];
                temp_SaccadeTag = [];
                temp_reference_flag = false;
            end

            layout_check = isfield(eye_movement_data, 'PrimaryTargetAmplitude') && ...
                isfield(eye_movement_data, 'SecondaryTargetAmplitude') && ...
                isfield(eye_movement_data, 'XOrigin') && isfield(eye_movement_data, 'YOrigin');
            if layout_check
                temp_primary_target_amplitude = eye_movement_data.PrimaryTargetAmplitude;
                temp_secondary_target_amplitude = eye_movement_data.SecondaryTargetAmplitude;
                temp_X_origin = eye_movement_data.XOrigin;
                temp_Y_origin = eye_movement_data.YOrigin;
            else
                disp('Target layout not detected')
                temp_primary_target_amplitude = nan;
                temp_secondary_target_amplitude = nan;
                temp_X_origin = nan;
                temp_Y_origin = nan;
            end
            target_for_trial_check = isfield(eye_movement_data, 'TrialList') && ...
                isfield(eye_movement_data, 'XPrimaryTargetForTrial') && ...
                isfield(eye_movement_data, 'YPrimaryTargetForTrial') && ...
                isfield(eye_movement_data, 'XSecondaryTargetForTrial') && ...
                isfield(eye_movement_data, 'YSecondaryTargetForTrial');
            if target_for_trial_check
                temp_TrialList = eye_movement_data.TrialList;
                temp_X_primary_target_for_trial = eye_movement_data.XPrimaryTargetForTrial;
                temp_Y_primary_target_for_trial = eye_movement_data.YPrimaryTargetForTrial;
                temp_X_secondary_target_for_trial = eye_movement_data.XSecondaryTargetForTrial;
                temp_Y_secondary_target_for_trial = eye_movement_data.YSecondaryTargetForTrial;
                temp = all(size(temp_TrialList)==size(temp_X_primary_target_for_trial) & ...
                    size(temp_TrialList)==size(temp_Y_primary_target_for_trial) & ...
                    size(temp_TrialList)==size(temp_X_secondary_target_for_trial) & ...
                    size(temp_TrialList)==size(temp_Y_secondary_target_for_trial) & ...
                    ismember(1, unique(size(temp_TrialList))));
                if temp
                    temp_X_primary_target_for_trial = temp_X_primary_target_for_trial(:);
                    temp_Y_primary_target_for_trial = temp_Y_primary_target_for_trial(:);
                    temp_X_secondary_target_for_trial = temp_X_secondary_target_for_trial(:);
                    temp_Y_secondary_target_for_trial = temp_Y_secondary_target_for_trial(:);
                else
                    disp(['XPrimaryTargetForTrial YPrimaryTargetForTrial XSecondaryTargetForTrial ' ...
                        'XSecondaryTargetForTrial should be array with the same size.'])
                    temp_TrialList = [];
                    temp_X_primary_target_for_trial = [];
                    temp_Y_primary_target_for_trial = [];
                    temp_X_secondary_target_for_trial = [];
                    temp_Y_secondary_target_for_trial = [];
                end
            else
                disp('Target for each trial not detected')
                temp_TrialList = [];
                temp_X_primary_target_for_trial = [];
                temp_Y_primary_target_for_trial = [];
                temp_X_secondary_target_for_trial = [];
                temp_Y_secondary_target_for_trial = [];
            end
            target_trace_check = isfield(eye_movement_data, 'XTarget') && ...
                isfield(eye_movement_data, 'YTarget');
            if target_trace_check
                temp_X_target = eye_movement_data.XTarget;
                temp_Y_target = eye_movement_data.YTarget;
                temp = all(size(temp_X)==size(temp_X_target) & ...
                    size(temp_X)==size(temp_Y_target));
                if temp
                    temp_X_target = temp_X_target(:);
                    temp_Y_target = temp_Y_target(:);
                else
                    disp('XTarget and YTarget should should be array with the same size to X Y and Saccade.');
                    temp_X_target = [];
                    temp_Y_target = [];
                end
            else
                disp('Target trace not detected')
                temp_X_target = [];
                temp_Y_target = [];
            end
            trial_tag_trace_check = isfield(eye_movement_data, 'TrialTag');
            if trial_tag_trace_check
                temp_TrialTag = eye_movement_data.TrialTag;
                temp = all(size(temp_X)==size(temp_TrialTag)) && ...
                    min(unique(temp_TrialTag))==1 && ...
                    all(ismember(unique(diff(temp_TrialTag)), [0 1]));
                if temp
                    temp_TrialTag = temp_TrialTag(:);
                    [~, trial_start_ind] = unique(temp_TrialTag, "first");
                    trial_end_ind = trial_start_ind(2:end)-1;
                    trial_end_ind(end+1) = length(temp_TrialTag);
                    trial_start_time_check_list = interp1(trial_start_ind, trial_start_ind-1, 1:length(temp_TrialTag), 'previous', 'extrap')./temp_frequency.*1000;
                    trial_end_time_check_list = interp1(trial_end_ind, trial_end_ind-1, 1:length(temp_TrialTag), 'next', 'extrap')./temp_frequency.*1000;
                    temp_trial_start_distance_check_list = (0:length(temp_TrialTag)-1)./temp_frequency.*1000 - trial_start_time_check_list;
                    temp_trial_end_distance_check_list = trial_end_time_check_list - (0:length(temp_TrialTag)-1)./temp_frequency.*1000;
                else
                    disp(['TrialTag should should be array with the same size to X Y and Saccade, ' ...
                        'and the trial number should start at 1 with increment of 1 for following trials']);
                    temp_TrialTag = [];
                    temp_trial_start_distance_check_list = [];
                    temp_trial_end_distance_check_list = [];
                end
            else
                disp('Trial tag trace not detected')
                temp_TrialTag = [];
                temp_trial_start_distance_check_list = [];
                temp_trial_end_distance_check_list = [];
            end
           probability_trace_check = isfield(eye_movement_data, 'Probability');
            if probability_trace_check
                temp_Probability = eye_movement_data.Probability;
                temp = all(size(temp_X)==size(temp_Probability)) && ...
                    all(temp_Probability>=0) && all(temp_Probability <=1);
                if temp
                    temp_Probability = temp_Probability(:);
                else
                    disp('Probability should should be array with the same size to X Y and Saccade, 0<=p<=1');
                    temp_Probability = [];
                end
            else
                disp('Probability trace not detected')
                temp_Probability = [];
            end
            app.curation_metadata = temp_curation_metadata;
            app.SaccadeReference = int8(temp_SaccadeReference(:));
            app.SaccadeTag = temp_SaccadeTag(:);
            app.reference_flag = temp_reference_flag;
            app.primary_target_amplitude = temp_primary_target_amplitude;
            app.secondary_target_amplitude = temp_secondary_target_amplitude;
            app.X_origin = temp_X_origin;
            app.Y_origin = temp_Y_origin;
            app.TrialList = temp_TrialList(:);
            app.X_primary_target_for_trial = temp_X_primary_target_for_trial(:);
            app.Y_primary_target_for_trial = temp_Y_primary_target_for_trial(:);
            app.X_secondary_target_for_trial = temp_X_secondary_target_for_trial(:);
            app.Y_secondary_target_for_trial = temp_Y_secondary_target_for_trial(:);
            app.X = temp_X(:);
            app.Y = temp_Y(:);
            app.nan_distance_check_list = temp_nan_distance(:);
            app.X_target = temp_X_target(:);
            app.Y_target = temp_Y_target(:);
            app.V = temp_V(:);
            app.Saccade = int8(temp_Saccade(:));
            app.TrialTag = temp_TrialTag(:);
            app.trial_start_distance_check_list = temp_trial_start_distance_check_list(:);
            app.trial_end_distance_check_list = temp_trial_end_distance_check_list(:);
            app.Probability = temp_Probability(:);
            app.frequency = temp_frequency;
            app.cutoff_freq = 100.0;
            [app.b_butter,app.a_butter] = butter(3,(app.cutoff_freq/(app.frequency/2)), 'low');
            app.min_ind_length_for_butter = max(length(app.b_butter)-1,length(app.a_butter)-1)*3 + 1;
            app.signal_bin_length = size(app.X, 1);
            app.time = (0:app.signal_bin_length-1)./app.frequency.*1000;
            app.time = app.time';
        end

        
        function change_move_time_length(app, move_time_length)
            if (move_time_length > 0)
                app.move_time_length = move_time_length;
            else
                disp('Move time length change failed: length must be larger than 0')
            end
        end

        function clear_mouse_patch(app)
            set(app.patch_handle_Saccade_on_mouse_region_left, 'XData', nan, 'YData', nan)
            set(app.patch_handle_X_on_mouse_region_left, 'XData', nan, 'YData', nan)
            set(app.patch_handle_Y_on_mouse_region_left, 'XData', nan, 'YData', nan)
            set(app.patch_handle_V_on_mouse_region_left, 'XData', nan, 'YData', nan)
            set(app.patch_handle_SaccadeReference_on_mouse_region_left, 'XData', nan, 'YData', nan)
            set(app.patch_handle_Saccade_on_mouse_region_right, 'XData', nan, 'YData', nan)
            set(app.patch_handle_X_on_mouse_region_right, 'XData', nan, 'YData', nan)
            set(app.patch_handle_Y_on_mouse_region_right, 'XData', nan, 'YData', nan)
            set(app.patch_handle_V_on_mouse_region_right, 'XData', nan, 'YData', nan)
            set(app.patch_handle_SaccadeReference_on_mouse_region_right, 'XData', nan, 'YData', nan)
        end

        function clear_labeled_patch(app)
            % This function is currently not used
            set(app.patch_handle_Saccade_on_labeled_region, 'XData', nan, 'YData', nan)
            set(app.patch_handle_X_on_labeled_region, 'XData', nan, 'YData', nan)
            set(app.patch_handle_Y_on_labeled_region, 'XData', nan, 'YData', nan)
            set(app.patch_handle_V_on_labeled_region, 'XData', nan, 'YData', nan)
            set(app.patch_handle_SaccadeReference_on_labeled_region, 'XData', nan, 'YData', nan)
        end

        function clear_ROI(app)
            clear_ROI_patch(app);
            app.ROI_x_feature = '';
            app.ROI_y_feature = '';
            if (~isempty(app.ROI_position))
                app.ROI_position = [];
            end
        end

        function clear_ROI_patch(app)
            set(app.patch_handle_ROI, 'XData', nan, 'YData', nan)
        end

        function calc_saccade_detail(app)
            app.Saccade_Detail = [];
            if app.reference_flag
                app.Saccade(1) = 0;
                app.Saccade(end) = 0;
                app.SaccadeReference(1) = 0;
                app.SaccadeReference(end) = 0;
                diff_sac = diff(app.Saccade);
                diff_sac_ref = diff(app.SaccadeReference);
                sac_onset_inds = find(diff_sac==1)+1;
                sac_offset_inds = find(diff_sac==-1);
                sac_ref_onset_inds = find(diff_sac_ref==1)+1;
                sac_ref_offset_inds = find(diff_sac_ref==-1);
                assert(length(sac_onset_inds)==length(sac_offset_inds), "length of onset should equal length of offset, debug the code!")
                assert(length(sac_ref_onset_inds)==length(sac_ref_offset_inds), "length of onset should equal length of offset for reference, debug the code!")
                if ~isempty(sac_onset_inds) && ~isempty(sac_ref_onset_inds)
                    [sac_inc_onset_mask, sac_inc_onset_link] = ismembertol(sac_onset_inds, ...
                        sac_ref_onset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_onset_inds(end), sac_ref_onset_inds(end)), "OutputAllIndices",true);
                    [sac_inc_offset_mask, sac_inc_offset_link] = ismembertol(sac_offset_inds, ...
                        sac_ref_offset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_offset_inds(end), sac_ref_offset_inds(end)), "OutputAllIndices",true);
                    sac_inc_mask = sac_inc_onset_mask & sac_inc_offset_mask & cellfun(@isequal, sac_inc_onset_link, sac_inc_offset_link);
                else % we want empty results here
                    sac_inc_mask = false(size(sac_onset_inds));
                end
                if ~isempty(sac_onset_inds) && ~isempty(sac_ref_onset_inds)
                    [sac_ref_inc_onset_mask, sac_ref_inc_onset_link] = ismembertol(sac_ref_onset_inds, ...
                        sac_onset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_onset_inds(end), sac_ref_onset_inds(end)), "OutputAllIndices",true);
                    [sac_ref_inc_offset_mask, sac_ref_inc_offset_link] = ismembertol(sac_ref_offset_inds, ...
                        sac_offset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_offset_inds(end), sac_ref_offset_inds(end)), "OutputAllIndices",true);
                    sac_ref_inc_mask = sac_ref_inc_onset_mask & sac_ref_inc_offset_mask & cellfun(@isequal, sac_ref_inc_onset_link, sac_ref_inc_offset_link);
                else % we want empty results here
                    sac_ref_inc_mask = false(size(sac_ref_onset_inds));
                end
                assert(length(sac_onset_inds)==length(sac_inc_mask), "length of mask should equal number of saccade, debug the code!")
                assert(length(sac_ref_onset_inds)==length(sac_ref_inc_mask), "length of mask should equal number of saccade for reference, debug the code!")
                for counter_sac = 1:1:length(sac_onset_inds)
                    onset_ind = sac_onset_inds(counter_sac);
                    offset_ind = sac_offset_inds(counter_sac);
                    onset_time = app.time(onset_ind);
                    offset_time = app.time(offset_ind);
                    if offset_ind - onset_ind >= app.min_ind_length_for_butter
                        X_saccade = app.X(onset_ind:offset_ind);
                        Y_saccade = app.Y(onset_ind:offset_ind);
                        if (all(~isnan(X_saccade))&&all(~isnan(Y_saccade)))
                            X_saccade_filt = filtfilt(app.b_butter,app.a_butter, X_saccade);
                            Y_saccade_filt = filtfilt(app.b_butter,app.a_butter, Y_saccade);
                            Vx_filt = diff(X_saccade_filt)*app.frequency;
                            Vx_filt = [Vx_filt; Vx_filt(end)];
                            Vy_filt = diff(Y_saccade_filt)*app.frequency;
                            Vy_filt = [Vy_filt; Vy_filt(end)];
                            V_filt = sqrt(Vx_filt.^2 + Vy_filt.^2);
                            [Vm, Vm_ind_saccade] = max(V_filt);
                            Vm_ind = Vm_ind_saccade + onset_ind -1;
                            Vm_time = app.time(Vm_ind);
                            amp_filt = sqrt((X_saccade_filt-X_saccade_filt(1)).^2+(Y_saccade_filt-Y_saccade_filt(1)).^2);
                            Am = max(amp_filt);
                            duration = (offset_ind-onset_ind + 1) / app.frequency * 1000;
                            validity = true;
                            mean_velocity = mean(V_filt);
                            Q_factor = Vm/mean_velocity;
                            acceleration_time = max([Vm_time-onset_time, 1/app.frequency*1000]);
                            deceleration_time = max([offset_time-Vm_time, 1/app.frequency*1000]);
                            acc_dec_ratio = acceleration_time/deceleration_time;
                            amplitude = amp_filt(end);
                            if ~isempty(app.Probability)
                                confidence = mean(app.Probability(onset_ind:offset_ind));
                            else
                                confidence = 1;
                            end
                            start_point_x = X_saccade_filt(1);
                            start_point_y = Y_saccade_filt(1);
                            start_point_radius = sqrt(start_point_x^2+start_point_y^2);
                            end_point_x = X_saccade_filt(end);
                            end_point_y = Y_saccade_filt(end);
                            end_point_radius = sqrt(end_point_x^2+end_point_y^2);
                            [angle, ~] = cart2pol(end_point_x-start_point_x, end_point_y-start_point_y);
                            angle = angle/pi*180;
                            if ~isempty(app.nan_distance_check_list)
                                nan_distance = app.nan_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                nan_distance = 0;
                            end
                            if ~isempty(app.trial_start_distance_check_list)
                                trial_start_distance = app.trial_start_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_start_distance = 0;
                            end
                            if ~isempty(app.trial_end_distance_check_list)
                                trial_end_distance = app.trial_end_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_end_distance = 0;
                            end
                        else
                            Vm = 0;
                            Vm_time = nan;
                            Am = 0;
                            duration = (offset_ind-onset_ind + 1) / app.frequency;
                            validity = false;
                            mean_velocity = 0;
                            Q_factor = 0;
                            acceleration_time = 0;
                            deceleration_time = 0;
                            acc_dec_ratio = 0;
                            amplitude = 0;
                            confidence = 0;
                            start_point_x = 0;
                            start_point_y = 0;
                            start_point_radius = 0;
                            end_point_x = 0;
                            end_point_y = 0;
                            end_point_radius = 0;
                            angle = 0;
                            nan_distance = 0;
                            trial_start_distance = 0;
                            trial_end_distance = 0;
                        end
                    else
                        Vm = 0;
                        Vm_time = nan;
                        Am = 0;
                        duration = (offset_ind-onset_ind + 1) / app.frequency;
                        validity = false;
                        mean_velocity = 0;
                        Q_factor = 0;
                        acceleration_time = 0;
                        deceleration_time = 0;
                        acc_dec_ratio = 0;
                        amplitude = 0;
                        confidence = 0;
                        start_point_x = 0;
                        start_point_y = 0;
                        start_point_radius = 0;
                        end_point_x = 0;
                        end_point_y = 0;
                        end_point_radius = 0;
                        angle = 0;
                        nan_distance = 0;
                        trial_start_distance = 0;
                        trial_end_distance = 0;
                    end
                    trace = false;
                    label = sac_inc_mask(counter_sac);
                    if ~isempty(app.TrialTag)
                        trial_tags = unique(app.TrialTag(onset_ind:offset_ind));
                        if length(trial_tags) == 1
                            trial = trial_tags(1);
                        else
                            trial = nan;
                        end
                    else
                        trial = nan;
                    end
                    tag = 0;
                    eval(['app.Saccade_Detail = [app.Saccade_Detail; ' app.Saccade_Detail_Feature_Text '];'])
                end
                for counter_sac = 1:1:length(sac_ref_onset_inds)
                    onset_ind = sac_ref_onset_inds(counter_sac);
                    offset_ind = sac_ref_offset_inds(counter_sac);
                    onset_time = app.time(onset_ind);
                    offset_time = app.time(offset_ind);
                    if offset_ind - onset_ind >= app.min_ind_length_for_butter
                        X_saccade = app.X(onset_ind:offset_ind);
                        Y_saccade = app.Y(onset_ind:offset_ind);
                        if (all(~isnan(X_saccade))&&all(~isnan(Y_saccade)))
                            X_saccade_filt = filtfilt(app.b_butter,app.a_butter, X_saccade);
                            Y_saccade_filt = filtfilt(app.b_butter,app.a_butter, Y_saccade);
                            Vx_filt = diff(X_saccade_filt)*app.frequency;
                            Vx_filt = [Vx_filt; Vx_filt(end)];
                            Vy_filt = diff(Y_saccade_filt)*app.frequency;
                            Vy_filt = [Vy_filt; Vy_filt(end)];
                            V_filt = sqrt(Vx_filt.^2 + Vy_filt.^2);
                            [Vm, Vm_ind_saccade] = max(V_filt);
                            Vm_ind = Vm_ind_saccade + onset_ind -1;
                            Vm_time = app.time(Vm_ind);
                            amp_filt = sqrt((X_saccade_filt-X_saccade_filt(1)).^2+(Y_saccade_filt-Y_saccade_filt(1)).^2);
                            Am = max(amp_filt);
                            duration = (offset_ind-onset_ind + 1) / app.frequency * 1000;
                            validity = true;
                            mean_velocity = mean(V_filt);
                            Q_factor = Vm/mean_velocity;
                            acceleration_time = max([Vm_time-onset_time, 1/app.frequency*1000]);
                            deceleration_time = max([offset_time-Vm_time, 1/app.frequency*1000]);
                            acc_dec_ratio = acceleration_time/deceleration_time;
                            amplitude = amp_filt(end);
                            if ~isempty(app.Probability)
                                confidence = mean(app.Probability(onset_ind:offset_ind));
                            else
                                confidence = 1;
                            end
                            start_point_x = X_saccade_filt(1);
                            start_point_y = Y_saccade_filt(1);
                            start_point_radius = sqrt(start_point_x^2+start_point_y^2);
                            end_point_x = X_saccade_filt(end);
                            end_point_y = Y_saccade_filt(end);
                            end_point_radius = sqrt(end_point_x^2+end_point_y^2);
                            [angle, ~] = cart2pol(end_point_x-start_point_x, end_point_y-start_point_y);
                            angle = angle/pi*180;
                            if ~isempty(app.nan_distance_check_list)
                                nan_distance = app.nan_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                nan_distance = 0;
                            end
                            if ~isempty(app.trial_start_distance_check_list)
                                trial_start_distance = app.trial_start_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_start_distance = 0;
                            end
                            if ~isempty(app.trial_end_distance_check_list)
                                trial_end_distance = app.trial_end_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_end_distance = 0;
                            end
                        else
                            Vm = 0;
                            Vm_time = nan;
                            Am = 0;
                            duration = (offset_ind-onset_ind + 1) / app.frequency;
                            validity = false;
                            mean_velocity = 0;
                            Q_factor = 0;
                            acceleration_time = 0;
                            deceleration_time = 0;
                            acc_dec_ratio = 0;
                            amplitude = 0;
                            confidence = 0;
                            start_point_x = 0;
                            start_point_y = 0;
                            start_point_radius = 0;
                            end_point_x = 0;
                            end_point_y = 0;
                            end_point_radius = 0;
                            angle = 0;
                            nan_distance = 0;
                            trial_start_distance = 0;
                            trial_end_distance = 0;
                        end
                    else
                        Vm = 0;
                        Vm_time = nan;
                        Am = 0;
                        duration = (offset_ind-onset_ind + 1) / app.frequency;
                        validity = false;
                        mean_velocity = 0;
                        Q_factor = 0;
                        acceleration_time = 0;
                        deceleration_time = 0;
                        acc_dec_ratio = 0;
                        amplitude = 0;
                        confidence = 0;
                        start_point_x = 0;
                        start_point_y = 0;
                        start_point_radius = 0;
                        end_point_x = 0;
                        end_point_y = 0;
                        end_point_radius = 0;
                        angle = 0;
                        nan_distance = 0;
                        trial_start_distance = 0;
                        trial_end_distance = 0;
                    end
                    trace = true;
                    label = sac_ref_inc_mask(counter_sac);
                    if ~isempty(app.TrialTag)
                        trial_tags = unique(app.TrialTag(onset_ind:offset_ind));
                        if length(trial_tags) == 1
                            trial = trial_tags(1);
                        else
                            trial = nan;
                        end
                    else
                        trial = nan;
                    end
                    if ~isempty(app.SaccadeTag)
                        tag = app.SaccadeTag(ceil((onset_ind+offset_ind)/2));
                    else
                        tag = 0;
                    end
                    eval(['app.Saccade_Detail = [app.Saccade_Detail; ' app.Saccade_Detail_Feature_Text '];'])
                end
            else
                app.Saccade(1) = 0;
                app.Saccade(end) = 0;
                diff_sac = diff(app.Saccade);
                sac_onset_inds = find(diff_sac==1)+1;
                sac_offset_inds = find(diff_sac==-1);
                assert(length(sac_onset_inds)==length(sac_offset_inds), "length of onset should equal length of offset, debug the code!")
                for counter_sac = 1:1:length(sac_onset_inds)
                    onset_ind = sac_onset_inds(counter_sac);
                    offset_ind = sac_offset_inds(counter_sac);
                    onset_time = app.time(onset_ind);
                    offset_time = app.time(offset_ind);
                    if offset_ind - onset_ind >= app.min_ind_length_for_butter
                        X_saccade = app.X(onset_ind:offset_ind);
                        Y_saccade = app.Y(onset_ind:offset_ind);
                        if (all(~isnan(X_saccade))&&all(~isnan(Y_saccade)))
                            X_saccade_filt = filtfilt(app.b_butter,app.a_butter, X_saccade);
                            Y_saccade_filt = filtfilt(app.b_butter,app.a_butter, Y_saccade);
                            Vx_filt = diff(X_saccade_filt)*app.frequency;
                            Vx_filt = [Vx_filt; Vx_filt(end)];
                            Vy_filt = diff(Y_saccade_filt)*app.frequency;
                            Vy_filt = [Vy_filt; Vy_filt(end)];
                            V_filt = sqrt(Vx_filt.^2 + Vy_filt.^2);
                            [Vm, Vm_ind_saccade] = max(V_filt);
                            Vm_ind = Vm_ind_saccade + onset_ind -1;
                            Vm_time = app.time(Vm_ind);
                            amp_filt = sqrt((X_saccade_filt-X_saccade_filt(1)).^2+(Y_saccade_filt-Y_saccade_filt(1)).^2);
                            Am = max(amp_filt);
                            duration = (offset_ind-onset_ind + 1) / app.frequency * 1000;
                            validity = true;
                            mean_velocity = mean(V_filt);
                            Q_factor = Vm/mean_velocity;
                            acceleration_time = max([Vm_time-onset_time, 1/app.frequency*1000]);
                            deceleration_time = max([offset_time-Vm_time, 1/app.frequency*1000]);
                            acc_dec_ratio = acceleration_time/deceleration_time;
                            amplitude = amp_filt(end);
                            if ~isempty(app.Probability)
                                confidence = mean(app.Probability(onset_ind:offset_ind));
                            else
                                confidence = 1;
                            end
                            start_point_x = X_saccade_filt(1);
                            start_point_y = Y_saccade_filt(1);
                            start_point_radius = sqrt(start_point_x^2+start_point_y^2);
                            end_point_x = X_saccade_filt(end);
                            end_point_y = Y_saccade_filt(end);
                            end_point_radius = sqrt(end_point_x^2+end_point_y^2);
                            [angle, ~] = cart2pol(end_point_x-start_point_x, end_point_y-start_point_y);
                            angle = angle/pi*180;
                            if ~isempty(app.nan_distance_check_list)
                                nan_distance = app.nan_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                nan_distance = 0;
                            end
                            if ~isempty(app.trial_start_distance_check_list)
                                trial_start_distance = app.trial_start_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_start_distance = 0;
                            end
                            if ~isempty(app.trial_end_distance_check_list)
                                trial_end_distance = app.trial_end_distance_check_list(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_end_distance = 0;
                            end
                        else
                            Vm = 0;
                            Vm_time = nan;
                            Am = 0;
                            duration = (offset_ind-onset_ind + 1) / app.frequency;
                            validity = false;
                            mean_velocity = 0;
                            Q_factor = 0;
                            acceleration_time = 0;
                            deceleration_time = 0;
                            acc_dec_ratio = 0;
                            amplitude = 0;
                            confidence = 0;
                            start_point_x = 0;
                            start_point_y = 0;
                            start_point_radius = 0;
                            end_point_x = 0;
                            end_point_y = 0;
                            end_point_radius = 0;
                            angle = 0;
                            nan_distance = 0;
                            trial_start_distance = 0;
                            trial_end_distance = 0;
                        end
                    else
                        Vm = 0;
                        Vm_time = nan;
                        Am = 0;
                        duration = (offset_ind-onset_ind + 1) / app.frequency;
                        validity = false;
                        mean_velocity = 0;
                        Q_factor = 0;
                        acceleration_time = 0;
                        deceleration_time = 0;
                        acc_dec_ratio = 0;
                        amplitude = 0;
                        confidence = 0;
                        start_point_x = 0;
                        start_point_y = 0;
                        start_point_radius = 0;
                        end_point_x = 0;
                        end_point_y = 0;
                        end_point_radius = 0;
                        angle = 0;
                        nan_distance = 0;
                        trial_start_distance = 0;
                        trial_end_distance = 0;
                    end
                    trace = false;
                    label = false;
                    if ~isempty(app.TrialTag)
                        trial_tags = unique(app.TrialTag(onset_ind:offset_ind));
                        if length(trial_tags) == 1
                            trial = trial_tags(1);
                        else
                            trial = nan;
                        end
                    else
                        trial = nan;
                    end
                    tag = 0;
                    eval(['app.Saccade_Detail = [app.Saccade_Detail; ' app.Saccade_Detail_Feature_Text '];'])
                end
            end
        end

        function [time_onset, time_offset] = find_saccade_time_onset_and_time_offset(app, time_anchor)
            search_time_onset = max([time_anchor - 200/app.frequency*1000, app.time(1)]);
            search_time_offset = min([time_anchor + 200/app.frequency*1000, app.time(end)]);
            search_mask = app.time>=search_time_onset-app.inf_small & app.time<=search_time_offset+app.inf_small;
            search_time_period = app.time(search_mask);
            search_X_period = app.X(search_mask);
            search_Y_period = app.Y(search_mask);
            nan_mask = isnan(search_X_period) | isnan(search_Y_period);
            search_X_period_clean = search_X_period(~nan_mask);
            search_Y_period_clean = search_Y_period(~nan_mask);
            search_time_period_clean = search_time_period(~nan_mask);
            search_X_period_interp = interp1(search_time_period_clean, search_X_period_clean, search_time_period, 'linear', 'extrap');
            search_Y_period_interp = interp1(search_time_period_clean, search_Y_period_clean, search_time_period, 'linear', 'extrap');
            search_X_period_filt = filtfilt(app.b_butter,app.a_butter,search_X_period_interp);
            search_Y_period_filt = filtfilt(app.b_butter,app.a_butter,search_Y_period_interp);
            search_Vx_period_filt = diff(search_X_period_filt)./diff(search_time_period/1000);
            if size(search_Vx_period_filt, 1) == 1 && size(search_Vx_period_filt, 2) > 1
                search_Vx_period_filt = [search_Vx_period_filt(1) search_Vx_period_filt];
            elseif size(search_Vx_period_filt, 1) > 1 && size(search_Vx_period_filt, 2) == 1
                search_Vx_period_filt = [search_Vx_period_filt(1); search_Vx_period_filt];
            else
                assert(false, 'size of saccade search duration is not correct. One dimension should be 1 and another should be larger than 1.');
            end
            search_Vy_period_filt = diff(search_Y_period_filt)./diff(search_time_period/1000);
            if size(search_Vy_period_filt, 1) == 1 && size(search_Vy_period_filt, 2) > 1
                search_Vy_period_filt = [search_Vy_period_filt(1) search_Vy_period_filt];
            elseif size(search_Vy_period_filt, 1) > 1 && size(search_Vy_period_filt, 2) == 1
                search_Vy_period_filt = [search_Vy_period_filt(1); search_Vy_period_filt];
            else
                assert(false, 'size of saccade search duration is not correct. One dimension should be 1 and another should be larger than 1.');
            end
            search_Vm_period_filt = sqrt(search_Vx_period_filt.^2 + search_Vy_period_filt.^2);
            
            result_from_Sac_Finder = JYZ_Sac_Finder_for_GUI(search_Vm_period_filt);

            if result_from_Sac_Finder.validity
                time_onset = search_time_period(result_from_Sac_Finder.ind_start);
                time_offset = search_time_period(result_from_Sac_Finder.ind_finish);
            else
                time_onset = -1;
                time_offset = -1;
            end
        end
        
        function modify_saccade(app)
            left = min(app.select_time_first, app.select_time_second);
            right = max(app.select_time_first, app.select_time_second);
            if (right-left>app.min_draw_saccade_time_duration+app.inf_small)
                stack_push(app);
                if strcmp(app.click_type, 'normal')
                    mask = app.time>=left-app.inf_small & app.time<=right+app.inf_small;
                    app.Saccade(mask) = 1;
                elseif strcmp(app.click_type, 'alt')
                    mask = app.time>=left-app.inf_small & app.time<=right+app.inf_small;
                    app.Saccade(mask) = 0;
                elseif strcmp(app.click_type, 'open')
                    if strcmp(app.click_type_exclude_open, 'normal')
                        mask = app.time>=left-app.inf_small & app.time<=right+app.inf_small;
                        app.Saccade(mask) = 1;
                    elseif strcmp(app.click_type_exclude_open, 'alt')
                        mask = app.time>=left-app.inf_small & app.time<=right+app.inf_small;
                        app.Saccade(mask) = 0;
                    else
                        assert(true, 'something wrong with click type here, please check if last_click_type_exclude_open is nan or values other than normal and alt');
                    end
                else
                    assert(true, 'something wrong with click type here, please check if click_type is nan or values other than normal, alt and open');
                end
                update_regional_saccade_detail(app, app.window_time_onset, app.window_time_offset);
            end
        end

        function synchronize_labeled_region(app)
            % this function is currently not used.
            if app.reference_flag
                left = min(app.labeled_saccade_onset_time, app.labeled_saccade_offset_time);
                right = max(app.labeled_saccade_onset_time, app.labeled_saccade_offset_time);
                if (right-left>app.inf_small)
                    stack_push(app);
                    mask = app.time>=left+app.inf_small-10 & app.time<=right-app.inf_small+10;
                    app.Saccade(mask) = app.SaccadeReference(mask);
                end
                update_regional_saccade_detail(app, left-10-app.window_time_duration/2, right+10+app.window_time_duration/2);
            else
                disp('There is no reference saccade trace yet, please add the SaccadeReference in the input file');
            end
        end

        function erase_labeled_region(app)
            left = min(app.labeled_saccade_onset_time, app.labeled_saccade_offset_time);
            right = max(app.labeled_saccade_onset_time, app.labeled_saccade_offset_time);
            if (right-left>app.inf_small)
                stack_push(app);
                mask = app.time>=left-app.inf_small & app.time<=right+app.inf_small;
                app.Saccade(mask) = 0;
                update_regional_saccade_detail(app, left-10-app.window_time_duration/2, right+10+app.window_time_duration/2);
            end
        end

        function erase_ROI_region(app)
            if ~isempty(app.ROI_position)
                if strcmp(app.main_sequence_selected_trace,'nonreference')
                    stack_push(app);
                    trace = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trace");
                    label = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="label");
                    mask_delete = trace == 0;
                    if strcmp(app.main_sequence_showing_mode,'all')
                    elseif strcmp(app.main_sequence_showing_mode,'inclusive')
                        mask_delete = mask_delete & (label == 1);
                    elseif strcmp(app.main_sequence_showing_mode,'exclusive')
                        mask_delete = mask_delete & (label == 0);
                    else
                        assert(false, 'app.main_sequence_showing_mode should be either all, inclusive or exclusive, debug the code!')
                    end
                    ROI = images.roi.Polygon("Position", app.ROI_position);
                    mask_delete = mask_delete & inROI(ROI,app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_x_feature), ...
                        app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_y_feature));
                    delete(ROI);
                    onset_time_list = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="onset_time");
                    offset_time_list = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="offset_time");
                    onset_time_delete_list = onset_time_list(mask_delete);
                    offset_time_delete_list = offset_time_list(mask_delete);
                    for saccade_delete_counter = 1:length(onset_time_delete_list)
                        left = onset_time_delete_list(saccade_delete_counter);
                        right = offset_time_delete_list(saccade_delete_counter);
                        if (right-left>app.inf_small)
                            mask = app.time>=left-app.inf_small & app.time<=right+app.inf_small;
                            app.Saccade(mask) = 0;
                        end
                    end
                    calc_saccade_detail(app);
                end
            end
        end

        function update_regional_saccade_detail(app, region_onset_time, region_offset_time)
            region_onset_time = max(region_onset_time, 0);
            region_offset_time = min(region_offset_time, app.signal_time_length);
            region_onset_ind = find(app.time>region_onset_time-app.inf_small, 1, 'first');
            region_offset_ind = find(app.time<region_offset_time+app.inf_small, 1, 'last');
            if app.reference_flag
                signal = app.Saccade | app.SaccadeReference;
            else
                signal = logical(app.Saccade);
            end
            while(signal(region_onset_ind) == 1 && region_onset_ind > 1) 
                region_onset_ind = region_onset_ind - 1;
            end
            while(signal(region_offset_ind) == 1 && region_offset_ind < app.signal_bin_length) 
                region_offset_ind = region_offset_ind + 1;
            end
            region_onset_time = app.time(region_onset_ind);
            region_offset_time = app.time(region_offset_ind);
            Saccade_Detail_Regional = [];
            Saccade_Regional = app.Saccade(region_onset_ind:region_offset_ind);
            if app.reference_flag
                SaccadeReference_Regional = app.SaccadeReference(region_onset_ind:region_offset_ind);
            end
            Time_Regional = app.time(region_onset_ind:region_offset_ind);
            X_Regional = app.X(region_onset_ind:region_offset_ind);
            Y_Regional = app.Y(region_onset_ind:region_offset_ind);
            if ~isempty(app.TrialTag)
                TrialTag_Regional = app.TrialTag(region_onset_ind:region_offset_ind);
            else
                TrialTag_Regional = [];
            end
            if ~isempty(app.Probability)
                Probability_Regional = app.Probability(region_onset_ind:region_offset_ind);
            else
                Probability_Regional = [];
            end
            if ~isempty(app.nan_distance_check_list)
                nan_distance_check_list_regional = app.nan_distance_check_list(region_onset_ind:region_offset_ind);
            else
                nan_distance_check_list_regional = [];
            end
            if ~isempty(app.trial_start_distance_check_list)
                trial_start_distance_check_list_regianl = app.trial_start_distance_check_list(region_onset_ind:region_offset_ind);
            else
                trial_start_distance_check_list_regianl = [];
            end
            if ~isempty(app.trial_end_distance_check_list)
                trial_end_distance_check_list_regional = app.trial_end_distance_check_list(region_onset_ind:region_offset_ind);
            else
                trial_end_distance_check_list_regional = [];
            end
            if ~isempty(app.SaccadeTag)
                SaccadeTag_Regional = app.SaccadeTag(region_onset_ind:region_offset_ind);
            else
                SaccadeTag_Regional = [];
            end
            if app.reference_flag
                Saccade_Regional(1) = 0;
                Saccade_Regional(end) = 0;
                SaccadeReference_Regional(1) = 0;
                SaccadeReference_Regional(end) = 0;
                diff_sac = diff(Saccade_Regional);
                diff_sac_ref = diff(SaccadeReference_Regional);
                sac_onset_inds = find(diff_sac==1)+1;
                sac_offset_inds = find(diff_sac==-1);
                sac_ref_onset_inds = find(diff_sac_ref==1)+1;
                sac_ref_offset_inds = find(diff_sac_ref==-1);
                assert(length(sac_onset_inds)==length(sac_offset_inds), "length of onset should equal length of offset, debug the code!")
                assert(length(sac_ref_onset_inds)==length(sac_ref_offset_inds), "length of onset should equal length of offset for reference, debug the code!")
                if ~isempty(sac_onset_inds) && ~isempty(sac_ref_onset_inds)
                    [sac_inc_onset_mask, sac_inc_onset_link] = ismembertol(sac_onset_inds, ...
                        sac_ref_onset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_onset_inds(end), sac_ref_onset_inds(end)), "OutputAllIndices",true);
                    [sac_inc_offset_mask, sac_inc_offset_link] = ismembertol(sac_offset_inds, ...
                        sac_ref_offset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_offset_inds(end), sac_ref_offset_inds(end)), "OutputAllIndices",true);
                    sac_inc_mask = sac_inc_onset_mask & sac_inc_offset_mask & cellfun(@isequal, sac_inc_onset_link, sac_inc_offset_link);
                else % we want empty results here
                    sac_inc_mask = false(size(sac_onset_inds));
                end
                if ~isempty(sac_onset_inds) && ~isempty(sac_ref_onset_inds)
                    [sac_ref_inc_onset_mask, sac_ref_inc_onset_link] = ismembertol(sac_ref_onset_inds, ...
                        sac_onset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_onset_inds(end), sac_ref_onset_inds(end)), "OutputAllIndices",true);
                    [sac_ref_inc_offset_mask, sac_ref_inc_offset_link] = ismembertol(sac_ref_offset_inds, ...
                        sac_offset_inds, app.comparison_time_tolerance/1000*app.frequency/max(sac_offset_inds(end), sac_ref_offset_inds(end)), "OutputAllIndices",true);
                    sac_ref_inc_mask = sac_ref_inc_onset_mask & sac_ref_inc_offset_mask & cellfun(@isequal, sac_ref_inc_onset_link, sac_ref_inc_offset_link);
                else % we want empty results here
                    sac_ref_inc_mask = false(size(sac_ref_onset_inds));
                end
                assert(length(sac_onset_inds)==length(sac_inc_mask), "length of mask should equal number of saccade, debug the code!")
                assert(length(sac_ref_onset_inds)==length(sac_ref_inc_mask), "length of mask should equal number of saccade for reference, debug the code!")
                for counter_sac = 1:1:length(sac_onset_inds)
                    onset_ind = sac_onset_inds(counter_sac);
                    offset_ind = sac_offset_inds(counter_sac);
                    onset_time = Time_Regional(onset_ind);
                    offset_time = Time_Regional(offset_ind);
                    if offset_ind - onset_ind >= app.min_ind_length_for_butter
                        X_saccade = X_Regional(onset_ind:offset_ind);
                        Y_saccade = Y_Regional(onset_ind:offset_ind);
                        if (all(~isnan(X_saccade))&&all(~isnan(Y_saccade)))
                            X_saccade_filt = filtfilt(app.b_butter,app.a_butter, X_saccade);
                            Y_saccade_filt = filtfilt(app.b_butter,app.a_butter, Y_saccade);
                            Vx_filt = diff(X_saccade_filt)*app.frequency;
                            Vx_filt = [Vx_filt; Vx_filt(end)];
                            Vy_filt = diff(Y_saccade_filt)*app.frequency;
                            Vy_filt = [Vy_filt; Vy_filt(end)];
                            V_filt = sqrt(Vx_filt.^2 + Vy_filt.^2);
                            [Vm, Vm_ind_saccade] = max(V_filt);
                            Vm_ind = Vm_ind_saccade + onset_ind -1;
                            Vm_time = Time_Regional(Vm_ind);
                            amp_filt = sqrt((X_saccade_filt-X_saccade_filt(1)).^2+(Y_saccade_filt-Y_saccade_filt(1)).^2);
                            Am = max(amp_filt);
                            duration = (offset_ind-onset_ind + 1) / app.frequency * 1000;
                            validity = true;
                            mean_velocity = mean(V_filt);
                            Q_factor = Vm/mean_velocity;
                            acceleration_time = max([Vm_time-onset_time, 1/app.frequency*1000]);
                            deceleration_time = max([offset_time-Vm_time, 1/app.frequency*1000]);
                            acc_dec_ratio = acceleration_time/deceleration_time;
                            amplitude = amp_filt(end);
                            if ~isempty(Probability_Regional)
                                confidence = mean(Probability_Regional(onset_ind:offset_ind));
                            else
                                confidence = 1;
                            end
                            start_point_x = X_saccade_filt(1);
                            start_point_y = Y_saccade_filt(1);
                            start_point_radius = sqrt(start_point_x^2+start_point_y^2);
                            end_point_x = X_saccade_filt(end);
                            end_point_y = Y_saccade_filt(end);
                            end_point_radius = sqrt(end_point_x^2+end_point_y^2);
                            [angle, ~] = cart2pol(end_point_x-start_point_x, end_point_y-start_point_y);
                            angle = angle/pi*180;
                            if ~isempty(nan_distance_check_list_regional)
                                nan_distance = nan_distance_check_list_regional(ceil((onset_ind+offset_ind)/2));
                            else
                                nan_distance = 0;
                            end
                            if ~isempty(trial_start_distance_check_list_regianl)
                                trial_start_distance = trial_start_distance_check_list_regianl(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_start_distance = 0;
                            end
                            if ~isempty(trial_end_distance_check_list_regional)
                                trial_end_distance = trial_end_distance_check_list_regional(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_end_distance = 0;
                            end
                        else
                            Vm = 0;
                            Vm_time = nan;
                            Am = 0;
                            duration = (offset_ind-onset_ind + 1) / app.frequency;
                            validity = false;
                            mean_velocity = 0;
                            Q_factor = 0;
                            acceleration_time = 0;
                            deceleration_time = 0;
                            acc_dec_ratio = 0;
                            amplitude = 0;
                            confidence = 0;
                            start_point_x = 0;
                            start_point_y = 0;
                            start_point_radius = 0;
                            end_point_x = 0;
                            end_point_y = 0;
                            end_point_radius = 0;
                            angle = 0;
                            nan_distance = 0;
                            trial_start_distance = 0;
                            trial_end_distance = 0;
                        end
                    else
                        Vm = 0;
                        Vm_time = nan;
                        Am = 0;
                        duration = (offset_ind-onset_ind + 1) / app.frequency;
                        validity = false;
                        mean_velocity = 0;
                        Q_factor = 0;
                        acceleration_time = 0;
                        deceleration_time = 0;
                        acc_dec_ratio = 0;
                        amplitude = 0;
                        confidence = 0;
                        start_point_x = 0;
                        start_point_y = 0;
                        start_point_radius = 0;
                        end_point_x = 0;
                        end_point_y = 0;
                        end_point_radius = 0;
                        angle = 0;
                        nan_distance = 0;
                        trial_start_distance = 0;
                        trial_end_distance = 0;
                    end
                    trace = false;
                    label = sac_inc_mask(counter_sac);
                    if ~isempty(TrialTag_Regional)
                        tags = unique(TrialTag_Regional(onset_ind:offset_ind));
                        if length(tags) == 1
                            trial = tags(1);
                        else
                            trial = nan;
                        end
                    else
                        trial = nan;
                    end
                    tag = 0;
                    eval(['Saccade_Detail_Regional = [Saccade_Detail_Regional; ' app.Saccade_Detail_Feature_Text '];'])
                end
                for counter_sac = 1:1:length(sac_ref_onset_inds)
                    onset_ind = sac_ref_onset_inds(counter_sac);
                    offset_ind = sac_ref_offset_inds(counter_sac);
                    onset_time = Time_Regional(onset_ind);
                    offset_time = Time_Regional(offset_ind);
                    if offset_ind - onset_ind >= app.min_ind_length_for_butter
                        X_saccade = X_Regional(onset_ind:offset_ind);
                        Y_saccade = Y_Regional(onset_ind:offset_ind);
                        if (all(~isnan(X_saccade))&&all(~isnan(Y_saccade)))
                            X_saccade_filt = filtfilt(app.b_butter,app.a_butter, X_saccade);
                            Y_saccade_filt = filtfilt(app.b_butter,app.a_butter, Y_saccade);
                            Vx_filt = diff(X_saccade_filt)*app.frequency;
                            Vx_filt = [Vx_filt; Vx_filt(end)];
                            Vy_filt = diff(Y_saccade_filt)*app.frequency;
                            Vy_filt = [Vy_filt; Vy_filt(end)];
                            V_filt = sqrt(Vx_filt.^2 + Vy_filt.^2);
                            [Vm, Vm_ind_saccade] = max(V_filt);
                            Vm_ind = Vm_ind_saccade + onset_ind -1;
                            Vm_time = Time_Regional(Vm_ind);
                            amp_filt = sqrt((X_saccade_filt-X_saccade_filt(1)).^2+(Y_saccade_filt-Y_saccade_filt(1)).^2);
                            Am = max(amp_filt);
                            duration = (offset_ind-onset_ind + 1) / app.frequency * 1000;
                            validity = true;
                            mean_velocity = mean(V_filt);
                            Q_factor = Vm/mean_velocity;
                            acceleration_time = max([Vm_time-onset_time, 1/app.frequency*1000]);
                            deceleration_time = max([offset_time-Vm_time, 1/app.frequency*1000]);
                            acc_dec_ratio = acceleration_time/deceleration_time;
                            amplitude = amp_filt(end);
                            if ~isempty(Probability_Regional)
                                confidence = mean(Probability_Regional(onset_ind:offset_ind));
                            else
                                confidence = 1;
                            end
                            start_point_x = X_saccade_filt(1);
                            start_point_y = Y_saccade_filt(1);
                            start_point_radius = sqrt(start_point_x^2+start_point_y^2);
                            end_point_x = X_saccade_filt(end);
                            end_point_y = Y_saccade_filt(end);
                            end_point_radius = sqrt(end_point_x^2+end_point_y^2);
                            [angle, ~] = cart2pol(end_point_x-start_point_x, end_point_y-start_point_y);
                            angle = angle/pi*180;
                            if ~isempty(nan_distance_check_list_regional)
                                nan_distance = nan_distance_check_list_regional(ceil((onset_ind+offset_ind)/2));
                            else
                                nan_distance = 0;
                            end
                            if ~isempty(trial_start_distance_check_list_regianl)
                                trial_start_distance = trial_start_distance_check_list_regianl(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_start_distance = 0;
                            end
                            if ~isempty(trial_end_distance_check_list_regional)
                                trial_end_distance = trial_end_distance_check_list_regional(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_end_distance = 0;
                            end
                        else
                            Vm = 0;
                            Vm_time = nan;
                            Am = 0;
                            duration = (offset_ind-onset_ind + 1) / app.frequency;
                            validity = false;
                            mean_velocity = 0;
                            Q_factor = 0;
                            acceleration_time = 0;
                            deceleration_time = 0;
                            acc_dec_ratio = 0;
                            amplitude = 0;
                            confidence = 0;
                            start_point_x = 0;
                            start_point_y = 0;
                            start_point_radius = 0;
                            end_point_x = 0;
                            end_point_y = 0;
                            end_point_radius = 0;
                            angle = 0;
                            nan_distance = 0;
                            trial_start_distance = 0;
                            trial_end_distance = 0;
                        end
                    else
                        Vm = 0;
                        Vm_time = nan;
                        Am = 0;
                        duration = (offset_ind-onset_ind + 1) / app.frequency;
                        validity = false;
                        mean_velocity = 0;
                        Q_factor = 0;
                        acceleration_time = 0;
                        deceleration_time = 0;
                        acc_dec_ratio = 0;
                        amplitude = 0;
                        confidence = 0;
                        start_point_x = 0;
                        start_point_y = 0;
                        start_point_radius = 0;
                        end_point_x = 0;
                        end_point_y = 0;
                        end_point_radius = 0;
                        angle = 0;
                        nan_distance = 0;
                        trial_start_distance = 0;
                        trial_end_distance = 0;
                    end
                    trace = true;
                    label = sac_ref_inc_mask(counter_sac);
                    if ~isempty(TrialTag_Regional)
                        tags = unique(TrialTag_Regional(onset_ind:offset_ind));
                        if length(tags) == 1
                            trial = tags(1);
                        else
                            trial = nan;
                        end
                    else
                        trial = nan;
                    end
                    if ~isempty(SaccadeTag_Regional)
                        tag = SaccadeTag_Regional(ceil((onset_ind+offset_ind)/2));
                    else
                        tag = 0;
                    end
                    eval(['Saccade_Detail_Regional = [Saccade_Detail_Regional; ' app.Saccade_Detail_Feature_Text '];'])
                end
            else
                Saccade_Regional(1) = 0;
                Saccade_Regional(end) = 0;
                diff_sac = diff(Saccade_Regional);
                sac_onset_inds = find(diff_sac==1)+1;
                sac_offset_inds = find(diff_sac==-1);
                assert(length(sac_onset_inds)==length(sac_offset_inds), "length of onset should equal length of offset, debug the code!")
                for counter_sac = 1:1:length(sac_onset_inds)
                    onset_ind = sac_onset_inds(counter_sac);
                    offset_ind = sac_offset_inds(counter_sac);
                    onset_time = Time_Regional(onset_ind);
                    offset_time = Time_Regional(offset_ind);
                    if offset_ind - onset_ind >= app.min_ind_length_for_butter
                        X_saccade = X_Regional(onset_ind:offset_ind);
                        Y_saccade = Y_Regional(onset_ind:offset_ind);
                        if (all(~isnan(X_saccade))&&all(~isnan(Y_saccade)))
                            X_saccade_filt = filtfilt(app.b_butter,app.a_butter, X_saccade);
                            Y_saccade_filt = filtfilt(app.b_butter,app.a_butter, Y_saccade);
                            Vx_filt = diff(X_saccade_filt)*app.frequency;
                            Vx_filt = [Vx_filt; Vx_filt(end)];
                            Vy_filt = diff(Y_saccade_filt)*app.frequency;
                            Vy_filt = [Vy_filt; Vy_filt(end)];
                            V_filt = sqrt(Vx_filt.^2 + Vy_filt.^2);
                            [Vm, Vm_ind_saccade] = max(V_filt);
                            Vm_ind = Vm_ind_saccade + onset_ind -1;
                            Vm_time = Time_Regional(Vm_ind);
                            amp_filt = sqrt((X_saccade_filt-X_saccade_filt(1)).^2+(Y_saccade_filt-Y_saccade_filt(1)).^2);
                            Am = max(amp_filt);
                            duration = (offset_ind-onset_ind + 1) / app.frequency * 1000;
                            validity = true;
                            mean_velocity = mean(V_filt);
                            Q_factor = Vm/mean_velocity;
                            acceleration_time = max([Vm_time-onset_time, 1/app.frequency*1000]);
                            deceleration_time = max([offset_time-Vm_time, 1/app.frequency*1000]);
                            acc_dec_ratio = acceleration_time/deceleration_time;
                            amplitude = amp_filt(end);
                            if ~isempty(Probability_Regional)
                                confidence = mean(Probability_Regional(onset_ind:offset_ind));
                            else
                                confidence = 1;
                            end
                            start_point_x = X_saccade_filt(1);
                            start_point_y = Y_saccade_filt(1);
                            start_point_radius = sqrt(start_point_x^2+start_point_y^2);
                            end_point_x = X_saccade_filt(end);
                            end_point_y = Y_saccade_filt(end);
                            end_point_radius = sqrt(end_point_x^2+end_point_y^2);
                            [angle, ~] = cart2pol(end_point_x-start_point_x, end_point_y-start_point_y);
                            angle = angle/pi*180;
                            if ~isempty(nan_distance_check_list_regional)
                                nan_distance = nan_distance_check_list_regional(ceil((onset_ind+offset_ind)/2));
                            else
                                nan_distance = 0;
                            end
                            if ~isempty(trial_start_distance_check_list_regianl)
                                trial_start_distance = trial_start_distance_check_list_regianl(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_start_distance = 0;
                            end
                            if ~isempty(trial_end_distance_check_list_regional)
                                trial_end_distance = trial_end_distance_check_list_regional(ceil((onset_ind+offset_ind)/2));
                            else
                                trial_end_distance = 0;
                            end
                        else
                            Vm = 0;
                            Vm_time = nan;
                            Am = 0;
                            duration = (offset_ind-onset_ind + 1) / app.frequency;
                            validity = false;
                            mean_velocity = 0;
                            Q_factor = 0;
                            acceleration_time = 0;
                            deceleration_time = 0;
                            acc_dec_ratio = 0;
                            amplitude = 0;
                            confidence = 0;
                            start_point_x = 0;
                            start_point_y = 0;
                            start_point_radius = 0;
                            end_point_x = 0;
                            end_point_y = 0;
                            end_point_radius = 0;
                            angle = 0;
                            nan_distance = 0;
                            trial_start_distance = 0;
                            trial_end_distance = 0;
                        end
                    else
                        Vm = 0;
                        Vm_time = nan;
                        Am = 0;
                        duration = (offset_ind-onset_ind + 1) / app.frequency;
                        validity = false;
                        mean_velocity = 0;
                        Q_factor = 0;
                        acceleration_time = 0;
                        deceleration_time = 0;
                        acc_dec_ratio = 0;
                        amplitude = 0;
                        confidence = 0;
                        start_point_x = 0;
                        start_point_y = 0;
                        start_point_radius = 0;
                        end_point_x = 0;
                        end_point_y = 0;
                        end_point_radius = 0;
                        angle = 0;
                        nan_distance = 0;
                        trial_start_distance = 0;
                        trial_end_distance = 0;
                    end
                    trace = false;
                    label = false;
                    if ~isempty(TrialTag_Regional)
                        tags = unique(TrialTag_Regional(onset_ind:offset_ind));
                        if length(tags) == 1
                            trial = tags(1);
                        else
                            trial = nan;
                        end
                    else
                        trial = nan;
                    end
                    tag = 0;
                    eval(['Saccade_Detail_Regional = [Saccade_Detail_Regional; ' app.Saccade_Detail_Feature_Text '];'])
                end
            end
            delete_mask = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="onset_time")>region_onset_time-app.inf_small;
            delete_mask = delete_mask & app.Saccade_Detail(:, app.Saccade_Detail_Feature=="onset_time")<region_offset_time+app.inf_small;
            app.Saccade_Detail(delete_mask, :) = [];
            app.Saccade_Detail = [app.Saccade_Detail; Saccade_Detail_Regional];
        end

        function label_another_saccade(app, trace, direction)
            if isempty(app.labeled_saccade_trace_class)
                start_time = mean([app.window_time_onset, app.window_time_offset]);
            else
                start_time = mean([app.labeled_saccade_onset_time, app.labeled_saccade_offset_time]);
            end
            if strcmp(trace, 'nonreference')
                mask = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trace") == 0;
            elseif strcmp(trace, 'reference')
                if app.reference_flag
                    mask = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trace") == 1;
                else
                    disp('There is no reference saccade trace yet, please add the SaccadeReference in the input file')
                    return
                end
            end
            if strcmp(trace, app.main_sequence_selected_trace)
                if strcmp(app.main_sequence_showing_mode, 'inclusive')
                    mask = mask & app.Saccade_Detail(:, app.Saccade_Detail_Feature=="label") == 1;
                elseif strcmp(app.main_sequence_showing_mode, 'exclusive')
                    mask = mask & app.Saccade_Detail(:, app.Saccade_Detail_Feature=="label") == 0;
                elseif strcmp(app.main_sequence_showing_mode, 'all')
                else
                    assert(false, 'trace could only be inclusive, exclusive or all')
                end
                if ~isempty(app.ROI_position)
                    ROI = images.roi.Polygon("Position", app.ROI_position);
                    mask = mask & inROI(ROI,app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_x_feature), ...
                        app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_y_feature));
                    delete(ROI);
                end
            end
            onset_time_list = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="onset_time");
            offset_time_list = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="offset_time");
            if strcmp(direction, 'next')
                mask = mask & onset_time_list>start_time+app.inf_small;
                if any(mask)
                    app.labeled_saccade_trace_class = trace;
                    app.labeled_saccade_onset_time = min(onset_time_list(mask));
                    app.labeled_saccade_offset_time = min(offset_time_list(mask));
                else
                    disp('No later saccade found.')
                end
            elseif strcmp(direction, 'previous')
                mask = mask & offset_time_list<start_time-app.inf_small;
                if any(mask)
                    app.labeled_saccade_trace_class = trace;
                    app.labeled_saccade_onset_time = max(onset_time_list(mask));
                    app.labeled_saccade_offset_time = max(offset_time_list(mask));
                else
                    disp('No previous saccade found.')
                end
            else
                assert(false, 'direction could only be next or previous');
            end
        end
        
        function label_next_saccade_on_nonreference_trace(app)
            % this function is currently not used.
            % note here this function could detect saccade for just
            % one bin, but this bin will not be highlighted on the
            % screen or be able to change by syncronization
            % provided by other function currently.The reason why
            % one bin could not be shown or saved is to turn down
            % the sensitivity of mouse selection function so that
            % you could cancel mouse selection area by clicking
            % somewhere else. Maybe deal with this in later
            % version.
            if isempty(app.labeled_saccade_trace_class)
                start_index = 1;
            else
                start_index = find(app.time>mean([app.labeled_saccade_onset_time, app.labeled_saccade_offset_time])-app.inf_small, 1, 'first');
            end
            scan_index = start_index;
            if app.Saccade(scan_index) == 0 % case 1: midline not currently on saccades from nonreference trace
                while(app.Saccade(scan_index) == 0) % try scan to find the first 1 on nonreference trace after midline
                    if (scan_index < app.signal_bin_length)
                        scan_index = scan_index + 1;
                    else
                        break;
                    end
                end
                if (app.Saccade(scan_index) == 0) % no saccade after midline from nonreference trace
                    disp('No later saccade on nonreference trace.')
                else
                    temp_onset_index = scan_index;
                    while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace after temp_onset_index
                        if (scan_index < app.signal_bin_length)
                            scan_index = scan_index + 1;
                        else
                            break;
                        end
                    end
                    if (app.Saccade(scan_index) == 0)
                        scan_index = scan_index-1;
                    end
                    temp_offset_index = scan_index;
                    app.labeled_saccade_trace_class = 'nonreference';
                    app.labeled_saccade_onset_time = app.time(temp_onset_index);
                    app.labeled_saccade_offset_time = app.time(temp_offset_index);
                end
            else    % case 2: midline currently on saccades from nonreference trace
                while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace after midline
                    if (scan_index < app.signal_bin_length)
                        scan_index = scan_index + 1;
                    else
                        break;
                    end
                end
                if(app.Saccade(scan_index) == 1) % no fixation after midline from nonreference trace
                    disp('No later saccade on nonreference trace.')
                else % since now the scan point is 0, we use code for case 1
                    while(app.Saccade(scan_index) == 0) % try scan to find the first 1 on nonreference trace after previous saccade
                        if (scan_index < app.signal_bin_length)
                            scan_index = scan_index + 1;
                        else
                            break;
                        end
                    end
                    if (app.Saccade(scan_index) == 0) % no next saccade after previous saccade from nonreference trace
                        disp('No later saccade on nonreference trace.')
                    else
                        temp_onset_index = scan_index;
                        while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace after temp_onset_index
                            if (scan_index < app.signal_bin_length)
                                scan_index = scan_index + 1;
                            else
                                break;
                            end
                        end
                        if (app.Saccade(scan_index) == 0)
                            scan_index = scan_index-1;
                        end
                        temp_offset_index = scan_index;
                        app.labeled_saccade_trace_class = 'nonreference';
                        app.labeled_saccade_onset_time = app.time(temp_onset_index);
                        app.labeled_saccade_offset_time = app.time(temp_offset_index);
                    end
                end
            end
        end
        
        function label_previous_saccade_on_nonreference_trace(app)
            % this function is currently not used.
            % note here this function could detect saccade for just
            % one bin, but this bin will not be highlighted on the
            % screen or be able to change by syncronization
            % provided by other function currently.The reason why
            % one bin could not be shown or saved is to turn down
            % the sensitivity of mouse selection function so that
            % you could cancel mouse selection area by clicking
            % somewhere else. Maybe deal with this in later
            % version.
            if isempty(app.labeled_saccade_trace_class)
                start_index = 1;
            else
                start_index = find(app.time<mean([app.labeled_saccade_onset_time, app.labeled_saccade_offset_time])+app.inf_small, 1, 'last');
            end
            scan_index = start_index;
            if app.Saccade(scan_index) == 0 % case 1: midline not currently on saccades from nonreference trace
                while(app.Saccade(scan_index) == 0) % try scan to find the first 1 on nonreference trace after midline
                    if (scan_index > 1)
                        scan_index = scan_index - 1;
                    else
                        break;
                    end
                end
                if (app.Saccade(scan_index) == 0) % no saccade before midline from nonreference trace
                    disp('No previous saccade on nonreference trace.')
                else
                    temp_offset_index = scan_index;
                    while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace before temp_offset_index
                        if (scan_index > 1)
                            scan_index = scan_index - 1;
                        else
                            break;
                        end
                    end
                    if (app.Saccade(scan_index) == 0)
                        scan_index = scan_index + 1;
                    end
                    temp_onset_index = scan_index;
                    app.labeled_saccade_trace_class = 'nonreference';
                    app.labeled_saccade_onset_time = app.time(temp_onset_index);
                    app.labeled_saccade_offset_time = app.time(temp_offset_index);
                end
            else    % case 2: midline currently on saccades from nonreference trace
                while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace before midline
                    if (scan_index > 1)
                        scan_index = scan_index - 1;
                    else
                        break;
                    end
                end
                if(app.Saccade(scan_index) == 1) % no fixation before midline from nonreference trace
                    disp('No later saccade on nonreference trace.')
                else % since now the scan point is 0, we use code for case 1
                    while(app.Saccade(scan_index) == 0) % try scan to find the first 1 on nonreference trace after midline
                        if (scan_index > 1)
                            scan_index = scan_index - 1;
                        else
                            break;
                        end
                    end
                    if (app.Saccade(scan_index) == 0) % no saccade before midline from nonreference trace
                        disp('No previous saccade on nonreference trace.')
                    else
                        temp_offset_index = scan_index;
                        while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace before temp_offset_index
                            if (scan_index > 1)
                                scan_index = scan_index - 1;
                            else
                                break;
                            end
                        end
                        if (app.Saccade(scan_index) == 0)
                           scan_index = scan_index+1;
                        end
                        temp_onset_index = scan_index;
                        app.labeled_saccade_trace_class = 'nonreference';
                        app.labeled_saccade_onset_time = app.time(temp_onset_index);
                        app.labeled_saccade_offset_time = app.time(temp_offset_index);
                    end
                end
            end
        end

        function label_next_saccade_on_reference_trace(app)
            % this function is currently not used.
            % note here this function could detect saccade for just
            % one bin, but this bin will not be highlighted on the
            % screen or be able to change by syncronization
            % provided by other function currently.The reason why
            % one bin could not be shown or saved is to turn down
            % the sensitivity of mouse selection function so that
            % you could cancel mouse selection area by clicking
            % somewhere else. Maybe deal with this in later
            % version.
            if(app.reference_flag)
                if isempty(app.labeled_saccade_trace_class)
                    start_index = 1;
                else
                    start_index = find(app.time>mean([app.labeled_saccade_onset_time, app.labeled_saccade_offset_time])-app.inf_small, 1, 'first');
                end
                scan_index = start_index;
                if app.SaccadeReference(scan_index) == 0 % case 1: midline not currently on saccades from reference trace
                    while(app.SaccadeReference(scan_index) == 0) % try scan to find the first 1 on reference trace after midline
                        if (scan_index < app.signal_bin_length)
                            scan_index = scan_index + 1;
                        else
                            break;
                        end
                    end
                    if (app.SaccadeReference(scan_index) == 0) % no saccade after midline from reference trace
                        disp('No later saccade on reference trace.')
                    else
                        temp_onset_index = scan_index;
                        while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace after temp_onset_index
                            if (scan_index < app.signal_bin_length)
                                scan_index = scan_index + 1;
                            else
                                break;
                            end
                        end
                        if (app.Saccade(scan_index) == 0)
                            scan_index = scan_index-1;
                        end
                        temp_offset_index = scan_index;
                        app.labeled_saccade_trace_class = 'reference';
                        app.labeled_saccade_onset_time = app.time(temp_onset_index);
                        app.labeled_saccade_offset_time = app.time(temp_offset_index);
                    end
                else    % case 2: midline currently on saccades from reference trace
                    while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace after midline
                        if (scan_index < app.signal_bin_length)
                            scan_index = scan_index + 1;
                        else
                            break;
                        end
                    end
                    if(app.SaccadeReference(scan_index) == 1) % no fixation after midline from reference trace
                        disp('No later saccade on reference trace.')
                    else % since now the scan point is 0, we use code for case 1
                        while(app.SaccadeReference(scan_index) == 0) % try scan to find the first 1 on reference trace after previous saccade
                            if (scan_index < app.signal_bin_length)
                                scan_index = scan_index + 1;
                            else
                                break;
                            end
                        end
                        if (app.SaccadeReference(scan_index) == 0) % no next saccade after previous saccade from reference trace
                            disp('No later saccade on reference trace.')
                        else
                            temp_onset_index = scan_index;
                            while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace after temp_onset_index
                                if (scan_index < app.signal_bin_length)
                                    scan_index = scan_index + 1;
                                else
                                    break;
                                end
                            end
                            if (app.Saccade(scan_index) == 0)
                               scan_index = scan_index-1;
                            end
                            temp_offset_index = scan_index;
                            app.labeled_saccade_trace_class = 'reference';
                            app.labeled_saccade_onset_time = app.time(temp_onset_index);
                            app.labeled_saccade_offset_time = app.time(temp_offset_index);
                        end
                    end
                end

            else
                disp('There is no reference saccade trace yet, please add the SaccadeReference in the input file')
            end
            
        end

        function label_previous_saccade_on_reference_trace(app)
            % this function is currently not used.
            % note here this function could detect saccade for just
            % one bin, but this bin will not be highlighted on the
            % screen or be able to change by syncronization
            % provided by other function currently.The reason why
            % one bin could not be shown or saved is to turn down
            % the sensitivity of mouse selection function so that
            % you could cancel mouse selection area by clicking
            % somewhere else. Maybe deal with this in later
            % version.
            if(app.reference_flag)
                if isempty(app.labeled_saccade_trace_class)
                    start_index = 1;
                else
                    start_index = find(app.time<mean([app.labeled_saccade_onset_time, app.labeled_saccade_offset_time])+app.inf_small, 1, 'last');
                end
                scan_index = start_index;
                if app.SaccadeReference(scan_index) == 0 % case 1: midline not currently on saccades from reference trace
                    while(app.SaccadeReference(scan_index) == 0) % try scan to find the first 1 on reference trace after midline
                        if (scan_index > 1)
                            scan_index = scan_index - 1;
                        else
                            break;
                        end
                    end
                    if (app.SaccadeReference(scan_index) == 0) % no saccade before midline from reference trace
                        disp('No previous saccade on reference trace.')
                    else
                        temp_offset_index = scan_index;
                        while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace before temp_offset_index
                            if (scan_index > 1)
                                scan_index = scan_index - 1;
                            else
                                break;
                            end
                        end
                        if (app.Saccade(scan_index) == 0)
                          scan_index = scan_index + 1;
                        end
                        temp_onset_index = scan_index;
                        app.labeled_saccade_trace_class = 'reference';
                        app.labeled_saccade_onset_time = app.time(temp_onset_index);
                        app.labeled_saccade_offset_time = app.time(temp_offset_index);
                    end
                else    % case 2: midline currently on saccades from reference trace
                    while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace before midline
                        if (scan_index > 1)
                            scan_index = scan_index - 1;
                        else
                            break;
                        end
                    end
                    if(app.SaccadeReference(scan_index) == 1) % no fixation before midline from reference trace
                        disp('No later saccade on reference trace.')
                    else % since now the scan point is 0, we use code for case 1
                        while(app.SaccadeReference(scan_index) == 0) % try scan to find the first 1 on reference trace after midline
                            if (scan_index > 1)
                                scan_index = scan_index - 1;
                            else
                                break;
                            end
                        end
                        if (app.SaccadeReference(scan_index) == 0) % no saccade before midline from reference trace
                            disp('No previous saccade on reference trace.')
                        else
                            temp_offset_index = scan_index;
                            while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace before temp_offset_index
                                if (scan_index > 1)
                                    scan_index = scan_index - 1;
                                else
                                    break;
                                end
                            end
                            if (app.Saccade(scan_index) == 0)
                                scan_index = scan_index + 1;
                            end
                            temp_onset_index = scan_index;
                            app.labeled_saccade_trace_class = 'reference';
                            app.labeled_saccade_onset_time = app.time(temp_onset_index);
                            app.labeled_saccade_offset_time = app.time(temp_offset_index);
                        end
                    end
                end
            else
                disp('There is no reference saccade trace yet, please add the SaccadeReference in the input file')
            end
        end

        function label_this_saccade(app)
            mouse_SaccadeUI = app.SaccadeUIAxes.CurrentPoint;
            mouse_SaccadeReferenceUI = app.SaccadeReferenceUIAxes.CurrentPoint;
            mouse_in_SaccadeUI_check = mouse_SaccadeUI(1,2) > 0+app.inf_small & ...
                mouse_SaccadeUI(1,2) < 1-app.inf_small & ...
                mouse_SaccadeUI(1,1) > app.window_time_onset+app.inf_small & ... 
                mouse_SaccadeUI(1,1) < app.window_time_offset-app.inf_small;
            mouse_in_SaccadeReferenceUI_check = mouse_SaccadeReferenceUI(1,2) > 0+app.inf_small & ...
                mouse_SaccadeReferenceUI(1,2) < 1-app.inf_small & ...
                mouse_SaccadeReferenceUI(1,1) > app.window_time_onset+app.inf_small & ...
                mouse_SaccadeReferenceUI(1,1) < app.window_time_offset-app.inf_small;
            if mouse_in_SaccadeUI_check 
                start_time = max([mouse_SaccadeUI(1,1), app.window_time_onset]);
                start_time = min([start_time, app.window_time_offset]);
                start_index = find(app.time<start_time+app.inf_small, 1, 'last');
                if app.Saccade(start_index) == 0
                    disp('Please put the mouse on the one Saccade before Manually labeling')
                else
                    scan_index = start_index;
                    while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace after start_index
                        if (scan_index < app.signal_bin_length)
                            scan_index = scan_index + 1;
                        else
                            break;
                        end
                    end
                    if (app.Saccade(scan_index) == 0)
                        scan_index = scan_index - 1;
                    end
                    temp_offset_index = scan_index;
                    scan_index = start_index;
                    while(app.Saccade(scan_index) == 1) % try scan to find the first 0 on nonreference trace before start_index
                        if (scan_index > 1)
                            scan_index = scan_index - 1;
                        else
                            break;
                        end
                    end
                    if (app.Saccade(scan_index) == 0)
                      scan_index = scan_index + 1;
                    end
                    temp_onset_index = scan_index;
                    app.labeled_saccade_trace_class = 'nonreference';
                    app.labeled_saccade_onset_time = app.time(temp_onset_index);
                    app.labeled_saccade_offset_time = app.time(temp_offset_index);
                end
            elseif mouse_in_SaccadeReferenceUI_check
                if app.reference_flag
                    start_time = max([mouse_SaccadeReferenceUI(1,1), app.window_time_onset]);
                    start_time = min([start_time, app.window_time_offset]);
                    start_index = find(app.time<start_time+app.inf_small, 1, 'last');
                    if app.SaccadeReference(start_index) == 0
                        disp('Please put the mouse on the one Saccade before Manually labeling')
                    else
                        scan_index = start_index;
                        while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace after start_index
                            if (scan_index < app.signal_bin_length)
                                scan_index = scan_index + 1;
                            else
                                break;
                            end
                        end
                        if (app.Saccade(scan_index) == 0)
                            scan_index = scan_index - 1;
                        end
                        temp_offset_index = scan_index;
                        scan_index = start_index;
                        while(app.SaccadeReference(scan_index) == 1) % try scan to find the first 0 on reference trace before start_index
                            if (scan_index > 1)
                                scan_index = scan_index - 1;
                            else
                                break;
                            end
                        end
                        if (app.Saccade(scan_index) == 0)
                            scan_index = scan_index + 1;
                        end
                        temp_onset_index = scan_index;
                        app.labeled_saccade_trace_class = 'reference';
                        app.labeled_saccade_onset_time = app.time(temp_onset_index);
                        app.labeled_saccade_offset_time = app.time(temp_offset_index);
                    end
                else
                    disp('There is no reference saccade trace yet, please add the SaccadeReference in the input file');
                end
            else 
                disp('Please put the mouse within Saccade or Saccade(ref) window when manually labeling Saccade')
            end
        end

        function show_frequency(app)
            app.FrequencyNumberLabel.Text = num2str(app.frequency);
        end
        
        function show_move_length(app)
            app.MoveLengthNumberLabel.Text = num2str(app.move_time_length);
        end

        function show_window_time_duration(app) 
            app.WindowTimeDurationNumberLabel.Text = num2str(app.window_time_duration);
        end

        function show_labeled_saccade_on_which_trace(app)
            switch app.labeled_saccade_trace_class
                case ''
                    app.SelectedOnTraceNumberLabel.Text = '';
                case 'nonreference'
                    app.SelectedOnTraceNumberLabel.Text = 'Saccade';
                case 'reference'
                    app.SelectedOnTraceNumberLabel.Text = 'Saccade(ref)';
            end
        end

        function show_saccade_number(app)
            app.GlobalSaccadeTotalNumberLabel.Text = num2str(app.global_saccade_total);
            app.GlobalSaccadeCounterNumberLabel.Text = num2str(app.global_saccade_counter);
            app.ROISaccadeTotalNumberLabel.Text = num2str(app.ROI_saccade_total);
            app.ROISaccadeCounterNumberLabel.Text = num2str(app.ROI_saccade_counter);
        end

        function show_main_sequence_X_Y_axis_feature_name(app)
            switch app.main_sequence_x_feature
                case "Am"
                    xlabel(app.MainSequenceUIAxes, "Maximum Amplitude (deg)");
                case "Vm"
                    xlabel(app.MainSequenceUIAxes, "Maximum Velocity (deg/s)");
                case "duration"
                    xlabel(app.MainSequenceUIAxes, "Duration (ms)");
                case "mean_velocity"
                    xlabel(app.MainSequenceUIAxes, "Mean Velocity (deg/s)");
                case "Q_factor"
                    xlabel(app.MainSequenceUIAxes, "Q Factor");
                case "acceleration_time"
                    xlabel(app.MainSequenceUIAxes, "Acceleration Time (ms)");
                case "deceleration_time"
                    xlabel(app.MainSequenceUIAxes, "Deceleration Time (ms)");
                case "acc_dec_ratio"
                    xlabel(app.MainSequenceUIAxes, "Acceleration Time / Deceleration Time");
                case "amplitude"
                    xlabel(app.MainSequenceUIAxes, "Amplitude (deg)");
                case "confidence"
                    xlabel(app.MainSequenceUIAxes, "Confidence");
                case "start_point_x"
                    xlabel(app.MainSequenceUIAxes, "Start Point X (deg)")
                case "start_point_y"
                    xlabel(app.MainSequenceUIAxes, "Start Point Y (deg)")
                case "start_point_radius"
                    xlabel(app.MainSequenceUIAxes, "Start Point Radius (deg)")
                case "end_point_x"
                    xlabel(app.MainSequenceUIAxes, "End Point X (deg)")
                case "end_point_y"
                    xlabel(app.MainSequenceUIAxes, "End Point Y (deg)")
                case "end_point_radius"
                    xlabel(app.MainSequenceUIAxes, "End Point Radius (deg)")
                case "angle"
                    xlabel(app.MainSequenceUIAxes, "Angle (deg)")
                case "nan_distance"
                    xlabel(app.MainSequenceUIAxes, "Distance from Nearest Blink (ms)")
                case "trial_start_distance"
                    xlabel(app.MainSequenceUIAxes, "Distance from Trial Start (ms)")
                case "trial_end_distance"
                    xlabel(app.MainSequenceUIAxes, "Distance from Trial End (ms)")
                case "tag"
                    xlabel(app.MainSequenceUIAxes, "Saccade Tag")
                otherwise
                    xlabel(app.MainSequenceUIAxes, "Unknown Features, Debug the Code!");
            end
            switch app.main_sequence_y_feature
                case "Am"
                    ylabel(app.MainSequenceUIAxes, "Maximum Amplitude (deg)");
                case "Vm"
                    ylabel(app.MainSequenceUIAxes, "Maximum Velocity (deg/s)");
                case "duration"
                    ylabel(app.MainSequenceUIAxes, "Duration (ms)");
                case "mean_velocity"
                    ylabel(app.MainSequenceUIAxes, "Mean Velocity (deg/s)");
                case "Q_factor"
                    ylabel(app.MainSequenceUIAxes, "Q Factor");
                case "acceleration_time"
                    ylabel(app.MainSequenceUIAxes, "Acceleration Time (ms)");
                case "deceleration_time"
                    ylabel(app.MainSequenceUIAxes, "Deceleration Time (ms)");
                case "acc_dec_ratio"
                    ylabel(app.MainSequenceUIAxes, "Acceleration Time / Deceleration Time");
                case "amplitude"
                    ylabel(app.MainSequenceUIAxes, "Amplitude (deg)");
                case "confidence"
                    ylabel(app.MainSequenceUIAxes, "Confidence");
                case "start_point_x"
                    ylabel(app.MainSequenceUIAxes, "Start Point X (deg)")
                case "start_point_y"
                    ylabel(app.MainSequenceUIAxes, "Start Point Y (deg)")
                case "start_point_radius"
                    ylabel(app.MainSequenceUIAxes, "Start Point Radius (deg)")
                case "end_point_x"
                    ylabel(app.MainSequenceUIAxes, "End Point X (deg)")
                case "end_point_y"
                    ylabel(app.MainSequenceUIAxes, "End Point Y (deg)")
                case "end_point_radius"
                    ylabel(app.MainSequenceUIAxes, "End Point Radius (deg)")
                case "angle"
                    ylabel(app.MainSequenceUIAxes, "Angle (deg)")
                case "nan_distance"
                    ylabel(app.MainSequenceUIAxes, "Distance from Nearest Blink (ms)")
                case "trial_start_distance"
                    ylabel(app.MainSequenceUIAxes, "Distance from Trial Start (ms)")
                case "trial_end_distance"
                    ylabel(app.MainSequenceUIAxes, "Distance from Trial End (ms)")
                case "tag"
                    ylabel(app.MainSequenceUIAxes, "Saccade Tag")
                otherwise
                    ylabel(app.MainSequenceUIAxes, "Unknown Features, Debug the Code!");
            end
        end

        function main_sequence_dropdown_init(app)
            if app.reference_flag
                app.MainSequenceSelectedTraceDropDown.Items = {'Saccade', 'Saccade(ref)'}; % default is 'Saccade'
                app.MainSequenceShowingModeDropDown.Items = {'all', 'inclusive', 'exclusive'}; % default is 'all'
            else
                app.MainSequenceSelectedTraceDropDown.Items = {'Saccade'};
                app.MainSequenceShowingModeDropDown.Items = {'all'};
            end
            app.MainSequenceShowingModeDropDown.Value = app.main_sequence_showing_mode;
            if strcmp(app.main_sequence_selected_trace, 'nonreference')
                app.MainSequenceSelectedTraceDropDown.Value =  'Saccade';
            elseif strcmp(app.main_sequence_selected_trace, 'reference')
                app.MainSequenceSelectedTraceDropDown.Value =  'Saccade(ref)';
            else
                assert(false, 'value from app.main_sequence_selected_trace could only be nonreference or reference, debug the code!')
            end
            app.MainSequenceXFeatureDropDown.Items = {'Maximum Amplitude', 'Maximum Velocity', 'Duration', 'Mean Velocity', 'Q Factor', ...
                'Acceleration Time', 'Deceleration Time', 'Acc/dec Ratio', 'Amplitude', 'Confidence', 'Start Point X', 'Start Point Y', ...
                'Start Point Radius','End Point X', 'End Point Y', 'End Point Radius', 'Angle', 'Nan Distance', 'Trial Start Distance', ...
                'Trial End Distance', 'Saccade Tag'};
            app.MainSequenceYFeatureDropDown.Items = {'Maximum Amplitude', 'Maximum Velocity', 'Duration', 'Mean Velocity', 'Q Factor', ...
                'Acceleration Time', 'Deceleration Time', 'Acc/dec Ratio', 'Amplitude', 'Confidence', 'Start Point X', 'Start Point Y', ...
                'Start Point Radius','End Point X', 'End Point Y', 'End Point Radius', 'Angle', 'Nan Distance', 'Trial Start Distance', ...
                'Trial End Distance', 'Saccade Tag'};
            if app.main_sequence_x_feature == "Am"
                app.MainSequenceXFeatureDropDown.Value =  'Maximum Amplitude';
            elseif app.main_sequence_x_feature == "Vm"
                app.MainSequenceXFeatureDropDown.Value =  'Maximum Velocity';
            elseif app.main_sequence_x_feature == "duration"
                app.MainSequenceXFeatureDropDown.Value =  'Duration';
            elseif app.main_sequence_x_feature == "mean_velocity"
                app.MainSequenceXFeatureDropDown.Value =  'Mean Velocity';
            elseif app.main_sequence_x_feature == "Q_factor"
                app.MainSequenceXFeatureDropDown.Value =  'Q Factor';
            elseif app.main_sequence_x_feature == "acceleration_time"
                app.MainSequenceXFeatureDropDown.Value =  'Acceleration Time';
            elseif app.main_sequence_x_feature == "deceleration_time"
                app.MainSequenceXFeatureDropDown.Value =  'Deceleration Time';
            elseif app.main_sequence_x_feature == "acc_dec_ratio"
                app.MainSequenceXFeatureDropDown.Value =  'Acc/dec Ratio';
            elseif app.main_sequence_x_feature == "amplitude"
                app.MainSequenceXFeatureDropDown.Value =  'Amplitude';
            elseif app.main_sequence_x_feature == "confidence"
                app.MainSequenceXFeatureDropDown.Value =  'Confidence';
            elseif app.main_sequence_x_feature == "start_point_x"
                app.MainSequenceXFeatureDropDown.Value =  'Start Point X';
            elseif app.main_sequence_x_feature == "start_point_y"
                app.MainSequenceXFeatureDropDown.Value =  'Start Point Y';
            elseif app.main_sequence_x_feature == "start_point_radius"
                app.MainSequenceXFeatureDropDown.Value =  'Start Point Radius';
            elseif app.main_sequence_x_feature == "end_point_x"
                app.MainSequenceXFeatureDropDown.Value =  'End Point X';
            elseif app.main_sequence_x_feature == "end_point_y"
                app.MainSequenceXFeatureDropDown.Value =  'End Point Y';
            elseif app.main_sequence_x_feature == "end_point_radius"
                app.MainSequenceXFeatureDropDown.Value =  'End Point Radius';
            elseif app.main_sequence_x_feature == "angle"
                app.MainSequenceXFeatureDropDown.Value =  'Angle';
            elseif app.main_sequence_x_feature == "nan_distance"
                app.MainSequenceXFeatureDropDown.Value =  'Nan Distance';
            elseif app.main_sequence_x_feature == "trial_start_distance"
                app.MainSequenceXFeatureDropDown.Value =  'Trial Start Distance';
            elseif app.main_sequence_x_feature == "trial_end_distance"
                app.MainSequenceXFeatureDropDown.Value =  'Trial End Distance';
            elseif app.main_sequence_x_feature == "tag"
                app.MainSequenceXFeatureDropDown.Value =  'Saccade Tag';
            else
                assert(false, 'value from app.main_sequence_x_feature could only be within saccade detail list, debug the code!')
            end
            if app.main_sequence_y_feature == "Am"
                app.MainSequenceYFeatureDropDown.Value =  'Maximum Amplitude';
            elseif app.main_sequence_y_feature == "Vm"
                app.MainSequenceYFeatureDropDown.Value =  'Maximum Velocity';
            elseif app.main_sequence_y_feature == "duration"
                app.MainSequenceYFeatureDropDown.Value =  'Duration';
            elseif app.main_sequence_y_feature == "mean_velocity"
                app.MainSequenceYFeatureDropDown.Value =  'Mean Velocity';
            elseif app.main_sequence_y_feature == "Q_factor"
                app.MainSequenceYFeatureDropDown.Value =  'Q Factor';
            elseif app.main_sequence_y_feature == "acceleration_time"
                app.MainSequenceYFeatureDropDown.Value =  'Acceleration Time';
            elseif app.main_sequence_y_feature == "deceleration_time"
                app.MainSequenceYFeatureDropDown.Value =  'Deceleration Time';
            elseif app.main_sequence_y_feature == "acc_dec_ratio"
                app.MainSequenceYFeatureDropDown.Value =  'Acc/dec Ratio';
            elseif app.main_sequence_y_feature == "amplitude"
                app.MainSequenceYFeatureDropDown.Value =  'Amplitude';
            elseif app.main_sequence_y_feature == "confidence"
                app.MainSequenceYFeatureDropDown.Value =  'Confidence';
            elseif app.main_sequence_y_feature == "start_point_x"
                app.MainSequenceYFeatureDropDown.Value =  'Start Point X';
            elseif app.main_sequence_y_feature == "start_point_y"
                app.MainSequenceYFeatureDropDown.Value =  'Start Point Y';
            elseif app.main_sequence_y_feature == "start_point_radius"
                app.MainSequenceYFeatureDropDown.Value =  'Start Point Radius';
            elseif app.main_sequence_y_feature == "end_point_x"
                app.MainSequenceYFeatureDropDown.Value =  'End Point X';
            elseif app.main_sequence_y_feature == "end_point_y"
                app.MainSequenceYFeatureDropDown.Value =  'End Point Y';
            elseif app.main_sequence_y_feature == "end_point_radius"
                app.MainSequenceYFeatureDropDown.Value =  'End Point Radius';
            elseif app.main_sequence_y_feature == "angle"
                app.MainSequenceYFeatureDropDown.Value =  'Angle';
            elseif app.main_sequence_y_feature == "nan_distance"
                app.MainSequenceYFeatureDropDown.Value =  'Nan Distance';
            elseif app.main_sequence_y_feature == "trial_start_distance"
                app.MainSequenceYFeatureDropDown.Value =  'Trial Start Distance';
            elseif app.main_sequence_y_feature == "trial_end_distance"
                app.MainSequenceYFeatureDropDown.Value =  'Trial End Distance';
            elseif app.main_sequence_y_feature == "tag"
                app.MainSequenceYFeatureDropDown.Value =  'Saccade Tag';
            else
                assert(false, 'value from app.main_sequence_y_feature could only be within saccade detail list, debug the code!')
            end
        end

        function main_sequence_log_scale_init(app)
            app.XAxisLogScaleCheckBox.Value = false;
            app.MainSequenceUIAxes.XScale = "linear";
            app.YAxisLogScaleCheckBox.Value = false;
            app.MainSequenceUIAxes.YScale = "linear";
        end
            

        function hide_all_objects(app)
            % this function is not currently used
            object_list = findobj(app.MyUIFigure);
            for counter_object = 1:1:length(object_list)
                if (isprop(object_list(counter_object), 'Enable'))
                    set(object_list(counter_object) , 'Enable', 'off');
                end
                if (isprop(object_list(counter_object), 'Visible'))
                    set(object_list(counter_object) , 'Visible', 'off');
                end
            end
        end

        function show_all_objects(app)
            % this function is not currently used
            object_list = findobj(app.MyUIFigure);
            for counter_object = 1:1:length(object_list)
                if (isprop(object_list(counter_object), 'Enable'))
                    set(object_list(counter_object) , 'Enable', 'on');
                end
                if (isprop(object_list(counter_object), 'Visible'))
                    set(object_list(counter_object) , 'Visible', 'on');
                end
            end
        end

        function disable_all_objects(app)
            % this function is not currently used
            object_list = findobj(app.MyUIFigure);
            for counter_object = 1:1:length(object_list)
                if (isprop(object_list(counter_object), 'Enable'))
                    set(object_list(counter_object) , 'Enable', 'off');
                end
            end
        end

        function enable_all_objects(app)
            % this function is not currently used
            object_list = findobj(app.MyUIFigure);
            for counter_object = 1:1:length(object_list)
                if (isprop(object_list(counter_object), 'Enable'))
                    set(object_list(counter_object) , 'Enable', 'on');
                end
            end
        end

        function disable_all_objects_except_main_panel(app)
            object_list = findobj(app.MyUIFigure);
            for counter_object = 1:1:length(object_list)
                if ~strcmp(object_list(counter_object).Tag, 'main_panel')
                    if (isprop(object_list(counter_object), 'Enable'))
                        set(object_list(counter_object) , 'Enable', 'off');
                    end
                end
            end
        end

        function enable_all_objects_except_main_panel(app)
            object_list = findobj(app.MyUIFigure);
            for counter_object = 1:1:length(object_list)
                if ~strcmp(object_list(counter_object).Tag, 'main_panel')
                    if (isprop(object_list(counter_object), 'Enable'))
                        set(object_list(counter_object) , 'Enable', 'on');
                    end
                end
            end
        end

        function Sac = Recording_Saccade_File_generator(app)
            onset_time = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="onset_time");
            offset_time = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="offset_time");
            Vm_time = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="Vm_time");
            trace = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trace");
            validity = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="validity");
            trial = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trial");
            if ~isempty(app.TrialTag)
                validity_filter = (validity==1)&(~isnan(trial));
                trial_trace = app.TrialTag;
            else
                validity_filter = (validity==1);
                trial_trace = ones(size(app.X));
            end
            [trial_unique,trial_start_ind] = unique(trial_trace, 'first');
            start_ind = changem(trial, trial_start_ind, trial_unique);
            mask = validity_filter & (trace == 0);
            onset_time = onset_time(mask);
            offset_time = offset_time(mask);
            Vm_time = Vm_time(mask);
            trial_tag = trial(mask);
            start_ind = start_ind(mask);
            saccade_number = length(onset_time);
            [tf,loc] = ismember(app.time,onset_time);
            tf = find(tf);
            [~,idx] = unique(loc(tf), 'first');
            onset_ind = tf(idx);
            assert(length(onset_ind) == saccade_number, 'index number is not equal to time number, debug the code!')
            [tf,loc] = ismember(app.time,offset_time);
            tf = find(tf);
            [~,idx] = unique(loc(tf), 'first');
            offset_ind = tf(idx);
            assert(length(offset_ind) == saccade_number, 'index number is not equal to time number, debug the code!')
            [tf,loc] = ismember(app.time,Vm_time);
            tf = find(tf);
            [~,idx] = unique(loc(tf), 'first');
            Vm_ind = tf(idx);
            assert(length(Vm_ind) == saccade_number, 'index number is not equal to time number, debug the code!')
            onset_ind_trial = onset_ind - start_ind + 1;
            offset_ind_trial = offset_ind - start_ind + 1;
            Vm_ind_trial = Vm_ind - start_ind + 1 - 1;
            for counter_trial = min(trial_unique):1:max(trial_unique)
                trial_mask = trial_tag == counter_trial;
                trial_mask_sessional = trial_trace == counter_trial;
                Sac.(sprintf('trial_%d',counter_trial)).all_sac_ind_onset = sort(onset_ind_trial(trial_mask));
                Sac.(sprintf('trial_%d',counter_trial)).all_sac_ind_vmax = sort(Vm_ind_trial(trial_mask));
                Sac.(sprintf('trial_%d',counter_trial)).all_sac_ind_offset = sort(offset_ind_trial(trial_mask));
                Sac.(sprintf('trial_%d',counter_trial)).all_sac_validity = true(size(onset_ind_trial(trial_mask)));
            end
            if ~isempty(app.Probability)
                for counter_trial = min(trial_unique):1:max(trial_unique)
                    trial_mask_sessional = trial_trace == counter_trial;
                    Sac.(sprintf('trial_%d',counter_trial)).probability = app.Probability(trial_mask_sessional);
                end
            end
        end

        function stack_init(app)
            app.stack_Saccade = {};
            app.stack_Saccade_Detail = {};
        end
        
        function stack_push(app)
            if length(app.stack_Saccade) < 5
                app.stack_Saccade{end+1} = app.Saccade;
                app.stack_Saccade_Detail{end+1} = app.Saccade_Detail;
            else
                app.stack_Saccade(1) = [];
                app.stack_Saccade_Detail(1) = [];
                app.stack_Saccade{end+1} = app.Saccade;
                app.stack_Saccade_Detail{end+1} = app.Saccade_Detail;
            end
        end

        function stack_pop(app)
            if length(app.stack_Saccade) >= 1
                app.Saccade = app.stack_Saccade{end};
                app.Saccade_Detail = app.stack_Saccade_Detail{end};
                app.stack_Saccade(end) = [];
                app.stack_Saccade_Detail(end) = [];
            end
        end

        function backup_save(app)
            app.backup_Saccade = app.Saccade;
            app.backup_Saccade_Detail = app.Saccade_Detail;
        end

        function backup_recovery(app)
            app.Saccade = app.backup_Saccade;
            app.Saccade_Detail = app.backup_Saccade_Detail;
        end

        function count_saccade(app)
            if isempty(app.labeled_saccade_trace_class)
                start_time = mean([app.window_time_onset, app.window_time_offset]);
            else
                start_time = mean([app.labeled_saccade_onset_time, app.labeled_saccade_offset_time]);
            end
            onset_time_list = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="onset_time");
            trace = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trace");
            label = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="label");
            if strcmp(app.main_sequence_selected_trace,'nonreference')
                mask = trace == 0;
            elseif strcmp(app.main_sequence_selected_trace,'reference')
                mask = trace == 1;
            else
                assert(false, 'app.main_sequence_selected_trace should be either nonreference, or reference, debug the code!')
            end
            if strcmp(app.main_sequence_showing_mode,'all')
            elseif strcmp(app.main_sequence_showing_mode,'inclusive')
                mask = mask & (label == 1);
            elseif strcmp(app.main_sequence_showing_mode,'exclusive')
                mask = mask & (label == 0);
            else
                assert(false, 'app.main_sequence_showing_mode should be either all, inclusive or exclusive, debug the code!')
            end
            temp_global_saccade_total = sum(mask);
            temp_global_saccade_counter = sum(onset_time_list(mask)<start_time);
            if ~isempty(app.ROI_position)
                ROI = images.roi.Polygon("Position", app.ROI_position);
                mask_in_ROI = inROI(ROI,app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_x_feature), ...
                    app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.ROI_y_feature));
                delete(ROI);
                temp_ROI_saccade_total = sum(mask & mask_in_ROI);
                temp_ROI_saccade_counter = sum(onset_time_list(mask & mask_in_ROI)<start_time);
            else
                temp_ROI_saccade_total = nan;
                temp_ROI_saccade_counter = nan;
            end
            app.global_saccade_total = temp_global_saccade_total;
            app.global_saccade_counter = temp_global_saccade_counter;
            app.ROI_saccade_total = temp_ROI_saccade_total;
            app.ROI_saccade_counter = temp_ROI_saccade_counter;
        end

        function detect_outliers(app)
            outlier_pct_threshold = app.outlier_percentage_threshold/100;
            x = app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.main_sequence_x_feature);
            y = app.Saccade_Detail(:, app.Saccade_Detail_Feature==app.main_sequence_y_feature);
            trace = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="trace");
            validity = app.Saccade_Detail(:, app.Saccade_Detail_Feature=="validity");
            x = x(trace==0&validity==1);
            y = y(trace==0&validity==1);
            if app.XAxisLogScaleCheckBox.Value
                mask_log_x = x>app.inf_small;
            else
                mask_log_x = true(size(x));
            end
            if app.YAxisLogScaleCheckBox.Value
                mask_log_y = y>app.inf_small;
            else
                mask_log_y = true(size(y));
            end
            mask_log = mask_log_x & mask_log_y;
            x = x(mask_log);
            y = y(mask_log);
            if app.XAxisLogScaleCheckBox.Value
                x = log(x);
            end
            if app.YAxisLogScaleCheckBox.Value
                y = log(y);
            end
            data = [x, y];
            if length(x) > 5
                scaler = JYZ_Scaler();
                scaler = scaler.fit(data);
                data_scaled = scaler.transform(data);
                x_scaled = data_scaled(:, 1);
                y_scaled = data_scaled(:, 2);
                [~,~,~,distance] = kmedoids(data_scaled, 1, 'Distance', 'mahalanobis');
                [distance_counts, bin_centers] = hist(distance, 1000);
                cdf = cumsum(distance_counts);
                cdf = cdf / cdf(end);
                outlier_distance_threshold = bin_centers(find(cdf>1-outlier_pct_threshold, 1, 'first'));
                x_outliers = x_scaled(distance>outlier_distance_threshold);
                y_outliers = y_scaled(distance>outlier_distance_threshold);
                outlier_boundary_ind = boundary(x_outliers, y_outliers, 1);
                x_boundary_outliers = x_outliers(outlier_boundary_ind);
                y_boundary_outliers = y_outliers(outlier_boundary_ind);
                data_boundary_outliers = [x_boundary_outliers y_boundary_outliers];
                if ~isempty(x_boundary_outliers)
                    image_resolution = 500;
                    image_margin = 100;
                    x_boundary_outliers = x_boundary_outliers.*image_resolution;
                    y_boundary_outliers = y_boundary_outliers.*image_resolution;
                    x_shift = min(x_boundary_outliers);
                    y_shift = min(y_boundary_outliers);
                    x_boundary_outliers_shifted = x_boundary_outliers - x_shift + image_margin;
                    y_boundary_outliers_shifted = y_boundary_outliers - y_shift + image_margin;
                    data_boundary_outliers_shifted = [y_boundary_outliers_shifted, x_boundary_outliers_shifted];
                    FigH = figure(Visible="off");
                    ax1 = axes('Parent', FigH);
                    image_size_x = int32(max(x_boundary_outliers_shifted)+image_margin);
                    image_size_y = int32(max(y_boundary_outliers_shifted)+image_margin);
                    imshow(false(image_size_x, image_size_y), 'Parent', ax1);
                    ROI = images.roi.Polygon(ax1, "Position", data_boundary_outliers_shifted);
                    mask_roi = createMask(ROI);
                    SE = strel('square',10);
                    mask_roi_dilated = imdilate(mask_roi,SE);
                    data_boundary_dilated = bwboundaries(mask_roi_dilated);
                    data_boundary_dilated = data_boundary_dilated{1};
                    data_boundary_shifted_back = data_boundary_dilated + [-image_margin+x_shift, -image_margin+y_shift];
                    data_boundary_shifted_back = data_boundary_shifted_back./image_resolution;
                    temp1 = repmat(data_boundary_shifted_back(:, 1), [1 size(data_boundary_outliers, 1)]);
                    temp2 = repmat(data_boundary_shifted_back(:, 2), [1 size(data_boundary_outliers, 1)]);
                    [~, inds_downsample] = min(sqrt((temp1-data_boundary_outliers(:, 1)').^2+(temp2-data_boundary_outliers(:, 2)').^2));
                    data_boundary_outliers = data_boundary_shifted_back(inds_downsample, :);

                    data_boundary_outliers_unscaled = scaler.reverse(data_boundary_outliers);
                    x_boundary_outliers_unscaled = data_boundary_outliers_unscaled(:, 1);
                    y_boundary_outliers_unscaled = data_boundary_outliers_unscaled(:, 2);
                    if app.XAxisLogScaleCheckBox.Value
                        x_boundary_outliers_unscaled = exp(x_boundary_outliers_unscaled);
                    end
                    if app.YAxisLogScaleCheckBox.Value
                        y_boundary_outliers_unscaled = exp(y_boundary_outliers_unscaled);
                    end
                    roi_postion = [x_boundary_outliers_unscaled, y_boundary_outliers_unscaled];
                    app.ROI_position = roi_postion;
                    app.ROI_x_feature = app.main_sequence_x_feature;
                    app.ROI_y_feature = app.main_sequence_y_feature;
                else
                    clear_ROI(app);
                end
            else
                clear_ROI(app);
            end
        end

        function curation_metadata_add_on(app)
            [~, computer_name] = system('hostname');
            computer_name = erase(computer_name, newline);
            app.curation_metadata.curator = [app.curation_metadata.curator; string(computer_name)];
            app.curation_metadata.time = [app.curation_metadata.time; string(datetime)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, directory)
%             object_list = findobj(app.MyUIFigure);
%             for counter_object = 1:1:length(object_list)
%                 set(object_list(counter_object) , 'Interruptible', 'off');
%                 set(object_list(counter_object) , 'BusyAction', 'cancel');
%             end
            app.MyUIFigure.Name = 'SACCURATE 1.0';
            app.HelpImage.ImageSource = 'JYZ_manual_curation_tool_help_image.png';
            warning('off', 'all');
%             ytickformat(app.XUIAxes, '%.2f');
%             ytickformat(app.YUIAxes, '%.2f');
%             ytickformat(app.VUIAxes, '%.2f');
%             ytickformat(app.SaccadeUIAxes, '%.2f');
%             ytickformat(app.SaccadeReferenceUIAxes, '%.2f');
            trace_plot_init(app)
            main_sequence_plot_and_highlight_init(app)
            time_range_plot_and_highlight_init(app)
            app.inf_small = 0.0000001;
            app.min_draw_saccade_time_duration = 5;
            app.signal_bin_length = 1000;
            app.frequency = 1000;
            app.cutoff_freq = 100.0;
            [app.b_butter,app.a_butter] = butter(3,(app.cutoff_freq/(app.frequency/2)), 'low');
            app.min_ind_length_for_butter = max(length(app.b_butter)-1,length(app.a_butter)-1)*3 + 1;
            app.comparison_time_tolerance = 10;
            app.current_task_list = ["", "StartUp", "MoveWindowToRight", "MoveWindowToLeft", "IncreaseMoveTimeLength", ...
                "DecreaseMoveTimeLength", "MoveToPreviousSaccadeOnNonreferenceTrace", "MoveToNextSaccadeOnNonreferenceTrace", ...
                "MoveToPreviousSaccadeOnReferenceTrace", "MoveToNextSaccadeOnReferenceTrace", "LabelThisSaccade", ...
                "EraseLabeledRegion", "ManuallyLabelSaccade", "ManuallyLabelFixation", "AutomaticallyLabelSaccade", ...
                "AutomaticallyLabelFixation", "ClearROI", "DrawROI", "ReadEyeMovementFile", "SaveEyeMovementFile", ...
                "SaveEyeMovementFileAs", "ZoomOutWindow", "ZoomInWindow", "ZoomOutMainSequence", "ZoomInMainSequence", ...
                "MainSequenceChangeTrace", "MainSequenceChangeMode", ...
                "MoveMainSequencePlot", "ViewHelp", "ChangeShowTargetTraceMode", "ChangeMainSequencePlotXLogScale", ...
                "ChangeMainSequencePlotYLogScale", "MainSequenceChangeXFeature", "MainSequenceChangeYFeature", "EraseROIRegion", ...
                "Undo", "UndoAll", "GoToStart", "GoToEnd", "ChangeOutlierPercentage", "DetectOutlier", "DefaultDetectOutlier", ...
                "ZoomOutTrace", "ZoomInTrace"];
            app.current_task = 'StartUp';
            app.primary_target_amplitude = nan;
            app.secondary_target_amplitude = nan;
            app.X_origin = nan;
            app.Y_origin = nan;
            app.TrialList = [];
            app.X_primary_target_for_trial = [];
            app.Y_primary_target_for_trial = [];
            app.X_secondary_target_for_trial = [];
            app.Y_secondary_target_for_trial = [];
            app.time = (0:app.signal_bin_length-1)./app.frequency.*1000;
            app.time = app.time';
            app.signal_time_length = app.time(end);app.SaccadeTag
            app.X = sin(100.*app.time);
            app.Y = sin(100.*app.time);
            app.nan_distance_check_list = [];
            app.X_target = [];
            app.Y_target = [];
            vx = diff(app.X).*app.frequency;
            vx = [vx; vx(end)]./1000;
            vy = diff(app.Y).*app.frequency;
            vy = [vy; vy(end)]./1000;
            app.V = sqrt(vx.^2 + vy.^2);
            app.Saccade = int8(app.V>0.5);
            app.SaccadeReference = app.Saccade;
            app.SaccadeTag = zeros(1, length(app.SaccadeReference));
            app.TrialTag = [];
            app.trial_start_distance_check_list = [];
            app.trial_end_distance_check_list = [];
            app.Probability = [];
            app.Saccade_Detail_Feature_Text = ['[onset_time, offset_time, Vm_time, ' ...
                'Am, Vm, duration, trace, label, validity, trial, mean_velocity, ' ...
                'Q_factor, acceleration_time, deceleration_time, acc_dec_ratio, amplitude, confidence, ' ...
                'start_point_x, start_point_y, start_point_radius, end_point_x, end_point_y, end_point_radius, ' ...
                'angle, nan_distance, trial_start_distance, trial_end_distance, tag]'];
            app.Saccade_Detail_Feature = ["onset_time", "offset_time", "Vm_time", ...
                "Am", "Vm", "duration", "trace", "label", "validity", "trial", "mean_velocity", ...
                "Q_factor", "acceleration_time", "deceleration_time", "acc_dec_ratio", "amplitude", "confidence", ...
                "start_point_x", "start_point_y", "start_point_radius", "end_point_x", "end_point_y", "end_point_radius", ...
                "angle", "nan_distance", "trial_start_distance", "trial_end_distance", "tag"]; 
            % please always keep app.Saccade_Detail_Feature_Text and
            % app.Saccade_Detail_Feature in same order with same names as
            % shown in app.calc_saccade_details()
            app.Saccade_Detail = [];
            calc_saccade_detail(app);
            app.window_time_onset = 0;
            app.window_time_duration = 200;
            app.window_time_offset = min(app.window_time_duration+app.window_time_onset, ...
                app.signal_time_length);
            change_main_sequence_higher_lower_bound(app, 0, 30, 0, 3000);
            app.main_sequence_selected_trace = 'nonreference'; % nonreference at first, either 'reference' or 'nonreference' after this
            app.main_sequence_showing_mode = 'all'; % all at first, either 'all', 'inclusive' or 'exclusive' after this.
            app.main_sequence_x_feature = "Am";
            app.main_sequence_y_feature = "Vm";
            app.trace_plot_magnification_rate = 1.2;
            app.move_time_length = 50;
            app.select_time_first = -1;
            app.select_time_second = -1;
            app.labeled_saccade_trace_class = ''; % empty at first, either 'reference' or 'nonreference' once starting labeling
            app.labeled_saccade_onset_time = -1;
            app.labeled_saccade_offset_time = -1;
            app.main_sequence_anchor_x = nan;
            app.main_sequence_anchor_y = nan;
            app.main_sequence_anchor_bounds = [];
            app.ROI_position = [];
            app.ROI_x_feature = '';
            app.ROI_y_feature = '';
            app.mouse_down_flag = false;
            app.reference_flag = false;
            app.show_target_trace_flag = true;
            app.click_type = '';
            app.click_type_exclude_open = '';
            app.eye_movement_file_path = '';
            app.session_directory = '';
            app.recording_id_list = {};
            app.recording_id = '';
            app.RecordingDropDown.Items = {'toy example'};
            app.outlier_percentage_threshold = 0;
            backup_save(app);
            main_sequence_dropdown_init(app);
            main_sequence_log_scale_init(app);
            stack_init(app);
            count_saccade(app);
            time_range_plot(app);
            main_sequence_plot(app);
            trace_plot(app);
            show_saccade_number(app);
            show_frequency(app);
            show_move_length(app);
            show_window_time_duration(app);
            show_labeled_saccade_on_which_trace(app);
            show_main_sequence_X_Y_axis_feature_name(app);
            app.current_task = '';
            
        end

        % Window key press function: MyUIFigure
        function MyUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if isempty(app.current_task)
                switch key
                    case {'rightarrow', 'f'}
                        if ~app.mouse_down_flag
                            app.current_task = 'MoveWindowToRight';
                            change_time_onset_and_time_offset(app, app.window_time_onset+app.move_time_length);
                        end
                    case {'leftarrow', 's'}
                        if ~app.mouse_down_flag
                            app.current_task = 'MoveWindowToLeft';
                            change_time_onset_and_time_offset(app, app.window_time_onset-app.move_time_length);
                        end
                    case {'uparrow', 'e'}
                        app.current_task = 'IncreaseMoveTimeLength';
                        if(app.move_time_length >= 100)
                            change_move_time_length(app, app.move_time_length+100);
                        else
                            change_move_time_length(app, app.move_time_length+10);
                        end
                    case {'downarrow', 'd'}
                        app.current_task = 'DecreaseMoveTimeLength';
                        if(app.move_time_length > 100)
                            change_move_time_length(app, app.move_time_length-100);
                        else
                            change_move_time_length(app, app.move_time_length-10);
                        end
                    case 'a'
                        app.current_task = 'MoveToPreviousSaccadeOnNonreferenceTrace';
                        % label_previous_saccade_on_nonreference_trace(app);
                        label_another_saccade(app, 'nonreference', 'previous')
                        time_onset = (app.labeled_saccade_onset_time+app.labeled_saccade_offset_time)/2-app.window_time_duration/2;
                        change_time_onset_and_time_offset(app, time_onset);
                    case 'g'
                        app.current_task = 'MoveToNextSaccadeOnNonreferenceTrace';
                        % label_next_saccade_on_nonreference_trace(app);
                        label_another_saccade(app, 'nonreference', 'next')
                        time_onset = (app.labeled_saccade_onset_time+app.labeled_saccade_offset_time)/2-app.window_time_duration/2;
                        change_time_onset_and_time_offset(app, time_onset);
                    case 'q'
                        app.current_task = 'MoveToPreviousSaccadeOnReferenceTrace';
                        % label_previous_saccade_on_reference_trace(app);
                        label_another_saccade(app, 'reference', 'previous')
                        time_onset = (app.labeled_saccade_onset_time+app.labeled_saccade_offset_time)/2-app.window_time_duration/2;
                        change_time_onset_and_time_offset(app, time_onset);
                    case 't'
                        app.current_task = 'MoveToNextSaccadeOnReferenceTrace';
                        % label_next_saccade_on_reference_trace(app);
                        label_another_saccade(app, 'reference', 'next')
                        time_onset = (app.labeled_saccade_onset_time+app.labeled_saccade_offset_time)/2-app.window_time_duration/2;
                        change_time_onset_and_time_offset(app, time_onset);
                    case 'r'
                        app.current_task = 'LabelThisSaccade';
                        label_this_saccade(app);
                    case 'space'
                        app.current_task = 'EraseLabeledRegion';
                        erase_labeled_region(app);
                    case 'h'
                        app.current_task = 'ChangeShowTargetTraceMode';
                        saccade_detail = app.Saccade_Detail;
                        save('check_detail.mat', "saccade_detail")
                        app.show_target_trace_flag = ~app.show_target_trace_flag;
                    case 'delete'
                        app.current_task = 'EraseROIRegion';
                        progress_dlg = uiprogressdlg(app.MyUIFigure, 'Title','Please Wait','Message','Deleting All Saccades Within ROI');
                        erase_ROI_region(app);
                        progress_dlg.Value = 1;
                        progress_dlg.Message = 'Done';
                        close(progress_dlg);
                    case 'backspace'
                        app.current_task = 'Undo';
                        stack_pop(app);
                end
                count_saccade(app);
                time_range_plot(app);
                main_sequence_plot(app);
                trace_plot(app);
                if app.mouse_down_flag
                    highlight_mouse_region_plot(app, app.select_time_first, app.SaccadeUIAxes.CurrentPoint(1,1));
                else
                    highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                end
                highlight_labeled_region_plot(app);
                highlight_ROI_plot(app);
                show_saccade_number(app);
                show_move_length(app);
                show_labeled_saccade_on_which_trace(app);
                app.current_task = '';
            end
        end

        % Window button down function: MyUIFigure
        function MyUIFigureWindowButtonDown(app, event)
            if (app.mouse_down_flag)
                MyUIFigureWindowButtonUp(app, event)
            end
            app.mouse_down_flag = true;
            if isempty(app.current_task)
                MainSequenceUI_mouse_x = app.MainSequenceUIAxes.CurrentPoint(1, 1);
                MainSequenceUI_mouse_y = app.MainSequenceUIAxes.CurrentPoint(1, 2);
                check_in_main_sequence_plot = MainSequenceUI_mouse_x >= app.main_sequence_x_lower_bound ...
                    && MainSequenceUI_mouse_x <= app.main_sequence_x_higher_bound ...
                    && MainSequenceUI_mouse_y >= app.main_sequence_y_lower_bound ...
                    && MainSequenceUI_mouse_y <= app.main_sequence_y_higher_bound;
                MyUIFigure_mouse_x = app.MyUIFigure.CurrentPoint(1, 1);
                MyUIFigure_mouse_y = app.MyUIFigure.CurrentPoint(1, 2);
                check_in_ToolPanel = MyUIFigure_mouse_x >= app.ToolPanel.Position(1) ...
                    && MyUIFigure_mouse_x <= app.ToolPanel.Position(1) + app.ToolPanel.Position(3) ...
                    && MyUIFigure_mouse_y >= app.ToolPanel.Position(2) ...
                    && MyUIFigure_mouse_y <= app.ToolPanel.Position(2) + app.ToolPanel.Position(4);
                check_in_OutlierPercentagePanel = MyUIFigure_mouse_x >= app.OutlierPercentagePanel.Position(1) ...
                    && MyUIFigure_mouse_x <= app.OutlierPercentagePanel.Position(1) + app.OutlierPercentagePanel.Position(3) ...
                    && MyUIFigure_mouse_y >= app.OutlierPercentagePanel.Position(2) ...
                    && MyUIFigure_mouse_y <= app.OutlierPercentagePanel.Position(2) + app.OutlierPercentagePanel.Position(4);
                current_click_type = app.MyUIFigure.SelectionType;
                temp_click_type_exclude_open = app.click_type_exclude_open;
                temp_click_type = app.click_type;
                switch current_click_type
                    case 'normal'
                        temp_click_type_exclude_open = current_click_type;
                        temp_click_type = current_click_type;
                    case 'alt'
                        temp_click_type_exclude_open = current_click_type;
                        temp_click_type = current_click_type;
                    case 'extend'
                        temp_click_type_exclude_open = current_click_type;
                        temp_click_type = 'alt';
                    case 'open'
                        temp_click_type = current_click_type;
                end
                if ~check_in_ToolPanel && ~check_in_OutlierPercentagePanel
                    if ~check_in_main_sequence_plot
                        app.click_type_exclude_open = temp_click_type_exclude_open;
                        app.click_type = temp_click_type;
                        switch app.click_type
                            case 'normal'
                                app.current_task = 'ManuallyLabelSaccade';
                            case 'alt'
                                app.current_task = 'ManuallyLabelFixation';
                            case 'open'
                                if strcmp(app.click_type_exclude_open, 'normal')
                                    app.current_task = 'AutomaticallyLabelSaccade';
                                elseif strcmp(app.click_type_exclude_open, 'alt')
                                    app.current_task = 'AutomaticallyLabelFixation';
                                else
                                    assert(false, 'app.click_type_exclude_open should should be either normal or alt, debug the code!')
                                end
                        end
                        app.select_time_first = app.SaccadeUIAxes.CurrentPoint(1, 1);
                        app.select_time_first = min(app.select_time_first, app.window_time_offset);
                        app.select_time_first = max(app.select_time_first, app.window_time_onset);
                        clear_mouse_patch(app);
                    else
                        app.select_time_first = -1;
                        app.select_time_second = -1;
                        clear_mouse_patch(app);
                        switch current_click_type
                            case 'normal'
                                app.click_type_exclude_open = temp_click_type_exclude_open;
                                app.click_type = temp_click_type;
                                if ~isempty(app.ROI_position)
                                    app.current_task = 'ClearROI';
                                    clear_ROI(app);
                                else
                                    app.current_task = 'DrawROI';
                                    disable_all_objects_except_main_panel(app);
                                    ROI = drawpolygon(app.MainSequenceUIAxes,...
                                        'InteractionsAllowed','none');
                                    app.ROI_position = ROI.Position;
                                    app.ROI_x_feature = app.main_sequence_x_feature;
                                    app.ROI_y_feature = app.main_sequence_y_feature;
                                    delete(ROI);
                                    enable_all_objects_except_main_panel(app);
                                end
                                count_saccade(app);
                                highlight_ROI_plot(app);
                                main_sequence_plot(app);
                                show_saccade_number(app);
                                app.current_task = '';
                            case 'alt'
                                app.click_type_exclude_open = temp_click_type_exclude_open;
                                app.click_type = temp_click_type;
                                app.current_task = 'MoveMainSequencePlot';
    %                             pan(app.MainSequenceUIAxes, 'on');
                                app.main_sequence_anchor_x = app.SaccadeUIAxes.CurrentPoint(1, 1);
                                app.main_sequence_anchor_y = app.SaccadeUIAxes.CurrentPoint(1, 2);
                                if app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                                    app.main_sequence_anchor_bounds = [log(app.main_sequence_x_lower_bound), ...
                                    log(app.main_sequence_x_higher_bound), log(app.main_sequence_y_lower_bound), ...
                                    log(app.main_sequence_y_higher_bound)];
                                elseif app.XAxisLogScaleCheckBox.Value && ~app.YAxisLogScaleCheckBox.Value
                                    app.main_sequence_anchor_bounds = [log(app.main_sequence_x_lower_bound), ...
                                    log(app.main_sequence_x_higher_bound), app.main_sequence_y_lower_bound, ...
                                    app.main_sequence_y_higher_bound];
                                elseif ~app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                                    app.main_sequence_anchor_bounds = [app.main_sequence_x_lower_bound, ...
                                    app.main_sequence_x_higher_bound, log(app.main_sequence_y_lower_bound), ...
                                    log(app.main_sequence_y_higher_bound)];
                                else
                                    app.main_sequence_anchor_bounds = [app.main_sequence_x_lower_bound, ...
                                    app.main_sequence_x_higher_bound, app.main_sequence_y_lower_bound, ...
                                    app.main_sequence_y_higher_bound];
                                end
                            case 'extend'
                            case 'open'
                        end
                    end
                end
            end
        end

        % Window button motion function: MyUIFigure
        function MyUIFigureWindowButtonMotion(app, event)
            if app.mouse_down_flag
                if ismember(app.current_task, ["ManuallyLabelSaccade", "ManuallyLabelFixation"])
                    mouse_point = app.SaccadeUIAxes.CurrentPoint;
                    mouse_x = mouse_point(1, 1);
                    highlight_mouse_region_plot(app, app.select_time_first, mouse_x);
                end
                if strcmp(app.current_task, 'MoveMainSequencePlot')
                    if ~isnan(app.main_sequence_anchor_x) && ~isnan(app.main_sequence_anchor_y) && ~isempty(app.main_sequence_anchor_bounds)
                        mouse_point = app.SaccadeUIAxes.CurrentPoint;
                        x_displacement = mouse_point(1, 1)-app.main_sequence_anchor_x;
                        y_displacement = mouse_point(1, 2)-app.main_sequence_anchor_y;
                        % use another UI to convert displacement. The text
                        % witdth should be constant, but the exact value of witdh is estimated and experimental.
                        y_label_width = 55;
                        x_label_width = 33+10;
                        % title_width = 17;
                        title_width = 0;
                        main_sequence_window_x_length = app.MainSequenceUIAxes.Position(3)- y_label_width;
                        saccade_window_x_length = app.SaccadeUIAxes.Position(3)- y_label_width;
                        main_sequence_window_y_length = app.MainSequenceUIAxes.Position(4)- x_label_width - title_width;
                        saccade_window_y_length = app.SaccadeUIAxes.Position(4)- x_label_width;
                        if app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                            x_displacement = x_displacement*((log(app.main_sequence_x_higher_bound)-log(app.main_sequence_x_lower_bound))/app.window_time_duration*saccade_window_x_length/main_sequence_window_x_length); 
                            y_displacement = y_displacement*((log(app.main_sequence_y_higher_bound)-log(app.main_sequence_y_lower_bound))/1*saccade_window_y_length/main_sequence_window_y_length);
                            change_main_sequence_higher_lower_bound(app, ...
                                real(exp(app.main_sequence_anchor_bounds(1) - x_displacement)), ...
                                real(exp(app.main_sequence_anchor_bounds(2) - x_displacement)), ...
                                real(exp(app.main_sequence_anchor_bounds(3) - y_displacement)), ...
                                real(exp(app.main_sequence_anchor_bounds(4) - y_displacement)))
                        elseif app.XAxisLogScaleCheckBox.Value && ~app.YAxisLogScaleCheckBox.Value
                            x_displacement = x_displacement*((log(app.main_sequence_x_higher_bound)-log(app.main_sequence_x_lower_bound))/app.window_time_duration*saccade_window_x_length/main_sequence_window_x_length); 
                            y_displacement = y_displacement*((app.main_sequence_y_higher_bound-app.main_sequence_y_lower_bound)/1*saccade_window_y_length/main_sequence_window_y_length);
                            change_main_sequence_higher_lower_bound(app, ...
                                real(exp(app.main_sequence_anchor_bounds(1) - x_displacement)), ...
                                real(exp(app.main_sequence_anchor_bounds(2) - x_displacement)), ...
                                app.main_sequence_anchor_bounds(3) - y_displacement, ...
                                app.main_sequence_anchor_bounds(4) - y_displacement)
                        elseif ~app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                            x_displacement = x_displacement*((app.main_sequence_x_higher_bound-app.main_sequence_x_lower_bound)/app.window_time_duration*saccade_window_x_length/main_sequence_window_x_length); 
                            y_displacement = y_displacement*((log(app.main_sequence_y_higher_bound)-log(app.main_sequence_y_lower_bound))/1*saccade_window_y_length/main_sequence_window_y_length);
                            change_main_sequence_higher_lower_bound(app, ...
                                app.main_sequence_anchor_bounds(1) - x_displacement, ...
                                app.main_sequence_anchor_bounds(2) - x_displacement, ...
                                real(exp(app.main_sequence_anchor_bounds(3) - y_displacement)), ...
                                real(exp(app.main_sequence_anchor_bounds(4) - y_displacement)))
                        else
                            x_displacement = x_displacement*((app.main_sequence_x_higher_bound-app.main_sequence_x_lower_bound)/app.window_time_duration*saccade_window_x_length/main_sequence_window_x_length); 
                            y_displacement = y_displacement*((app.main_sequence_y_higher_bound-app.main_sequence_y_lower_bound)/1*saccade_window_y_length/main_sequence_window_y_length);
                            change_main_sequence_higher_lower_bound(app, ...
                                app.main_sequence_anchor_bounds(1) - x_displacement, ...
                                app.main_sequence_anchor_bounds(2) - x_displacement, ...
                                app.main_sequence_anchor_bounds(3) - y_displacement, ...
                                app.main_sequence_anchor_bounds(4) - y_displacement)
                        end
                        main_sequence_plot(app);
                        highlight_ROI_plot(app);
                    end
                end
            end
        end

        % Window button up function: MyUIFigure
        function MyUIFigureWindowButtonUp(app, event)
            if (app.mouse_down_flag)
                app.mouse_down_flag = false;
                if ismember(app.current_task, ["ManuallyLabelSaccade", "ManuallyLabelFixation", ...
                        "AutomaticallyLabelSaccade", "AutomaticallyLabelFixation"])
                    app.select_time_second = app.SaccadeUIAxes.CurrentPoint(1, 1);
                    app.select_time_second = min(app.select_time_second, app.window_time_offset);
                    app.select_time_second = max(app.select_time_second, app.window_time_onset);
                    if strcmp(app.click_type, 'open')
                        [temp_select_time_first, temp_select_time_second] = find_saccade_time_onset_and_time_offset(app, app.select_time_second);
                        if(temp_select_time_second ~= -1)
                            app.select_time_first = temp_select_time_first;
                            app.select_time_second = temp_select_time_second;
                        end
                    end
                    modify_saccade(app);
                    count_saccade(app);
                    time_range_plot(app);
                    main_sequence_plot(app);
                    highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                    highlight_labeled_region_plot(app);
                    highlight_ROI_plot(app);
                    show_saccade_number(app);
                    app.current_task = '';
                elseif strcmp(app.current_task, 'MoveMainSequencePlot')
%                     pan(app.MainSequenceUIAxes, 'off');
                    app.current_task = '';
                end
            end
        end

        % Window scroll wheel function: MyUIFigure
        function MyUIFigureWindowScrollWheel(app, event)
            if isempty(app.current_task)
                % verticalScrollAmount = event.VerticalScrollAmount;
                verticalScrollCount = event.VerticalScrollCount;
                SaccadeUI_mouse_x = app.SaccadeUIAxes.CurrentPoint(1, 1);
                MainSequenceUI_mouse_x = app.MainSequenceUIAxes.CurrentPoint(1, 1);
                MainSequenceUI_mouse_y = app.MainSequenceUIAxes.CurrentPoint(1, 2);
                TraceUI_mouse_x = app.TraceUIAxes.CurrentPoint(1, 1);
                TraceUI_mouse_y = app.TraceUIAxes.CurrentPoint(1, 2);
                if SaccadeUI_mouse_x >= app.window_time_onset && SaccadeUI_mouse_x <= app.window_time_offset
                    time_anchor = max([SaccadeUI_mouse_x app.window_time_onset]);
                    time_anchor = min([time_anchor app.window_time_offset]);
                    pre_onset = app.window_time_onset;
                    pre_duration = app.window_time_duration;
                    if (verticalScrollCount == 1)
                        app.current_task = 'ZoomOutWindow';
                        if(app.window_time_duration >= 100)
                            if(app.window_time_duration + 100 < app.signal_time_length)
                                change_time_duration(app, app.window_time_duration + 100);
                            end
                        else
                            if(app.window_time_duration + 10 < app.signal_time_length)
                                change_time_duration(app, app.window_time_duration + 10);
                            end
                        end
                    elseif (verticalScrollCount == -1)
                        app.current_task = 'ZoomInWindow';
                        if(app.window_time_duration > 100)
                            change_time_duration(app, app.window_time_duration - 100);
                        else
                            if(app.window_time_duration - 10>0)
                                change_time_duration(app, app.window_time_duration - 10);
                            end
                        end
                    else
                        disp('Error value for vertical scroll count')
                    end
                    post_duration = app.window_time_duration;
                    duration_ratio = post_duration/pre_duration;
                    post_onset = duration_ratio*pre_onset+(1-duration_ratio)*time_anchor;
                    post_onset = max([post_onset 0]);
                    change_time_onset_and_time_offset(app, post_onset);
                end
                check_in_main_sequence_plot = MainSequenceUI_mouse_x >= app.main_sequence_x_lower_bound ...
                    && MainSequenceUI_mouse_x <= app.main_sequence_x_higher_bound ...
                    && MainSequenceUI_mouse_y >= app.main_sequence_y_lower_bound ...
                    && MainSequenceUI_mouse_y <= app.main_sequence_y_higher_bound; % Jinyu
                check_in_trace_plot = TraceUI_mouse_x >= app.TraceUIAxes.XLim(1) ...
                    && TraceUI_mouse_x <= app.TraceUIAxes.XLim(2) ...
                    && TraceUI_mouse_y >= app.TraceUIAxes.YLim(1) ...
                    && TraceUI_mouse_y <= app.TraceUIAxes.YLim(2);
                if (check_in_main_sequence_plot)
                    x_anchor = max([MainSequenceUI_mouse_x app.main_sequence_x_lower_bound]);
                    x_anchor = min([x_anchor app.main_sequence_x_higher_bound]);
                    y_anchor = max([MainSequenceUI_mouse_y app.main_sequence_y_lower_bound]);
                    y_anchor = min([y_anchor app.main_sequence_y_higher_bound]);
                    if (verticalScrollCount == 1)
                        app.current_task = 'ZoomOutMainSequence';
                        rate = 1.1;
                        if app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                            x_lower = real(exp(rate*log(app.main_sequence_x_lower_bound)+(1-rate)*log(x_anchor)));
                            x_higher = real(exp(rate*log(app.main_sequence_x_higher_bound)+(1-rate)*log(x_anchor)));
                            y_lower = real(exp(rate*log(app.main_sequence_y_lower_bound)+(1-rate)*log(y_anchor)));
                            y_higher = real(exp(rate*log(app.main_sequence_y_higher_bound)+(1-rate)*log(y_anchor)));
                        elseif app.XAxisLogScaleCheckBox.Value && ~app.YAxisLogScaleCheckBox.Value
                            x_lower = real(exp(rate*log(app.main_sequence_x_lower_bound)+(1-rate)*log(x_anchor)));
                            x_higher = real(exp(rate*log(app.main_sequence_x_higher_bound)+(1-rate)*log(x_anchor)));
                            y_lower = rate*app.main_sequence_y_lower_bound+(1-rate)*y_anchor;
                            y_higher = rate*app.main_sequence_y_higher_bound+(1-rate)*y_anchor;
                        elseif ~app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                            x_lower = rate*app.main_sequence_x_lower_bound+(1-rate)*x_anchor;
                            x_higher = rate*app.main_sequence_x_higher_bound+(1-rate)*x_anchor;
                            y_lower = real(exp(rate*log(app.main_sequence_y_lower_bound)+(1-rate)*log(y_anchor)));
                            y_higher = real(exp(rate*log(app.main_sequence_y_higher_bound)+(1-rate)*log(y_anchor)));
                        else
                            x_lower = rate*app.main_sequence_x_lower_bound+(1-rate)*x_anchor;
                            x_higher = rate*app.main_sequence_x_higher_bound+(1-rate)*x_anchor;
                            y_lower = rate*app.main_sequence_y_lower_bound+(1-rate)*y_anchor;
                            y_higher = rate*app.main_sequence_y_higher_bound+(1-rate)*y_anchor;
                        end
                        change_main_sequence_higher_lower_bound(app, x_lower, x_higher, y_lower, y_higher);
                    elseif (verticalScrollCount == -1)
                        app.current_task = 'ZoomInMainSequence';
                        rate = 0.9;
                        if app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                            x_lower = real(exp(rate*log(app.main_sequence_x_lower_bound)+(1-rate)*log(x_anchor)));
                            x_higher = real(exp(rate*log(app.main_sequence_x_higher_bound)+(1-rate)*log(x_anchor)));
                            y_lower = real(exp(rate*log(app.main_sequence_y_lower_bound)+(1-rate)*log(y_anchor)));
                            y_higher = real(exp(rate*log(app.main_sequence_y_higher_bound)+(1-rate)*log(y_anchor)));
                        elseif app.XAxisLogScaleCheckBox.Value && ~app.YAxisLogScaleCheckBox.Value
                            x_lower = real(exp(rate*log(app.main_sequence_x_lower_bound)+(1-rate)*log(x_anchor)));
                            x_higher = real(exp(rate*log(app.main_sequence_x_higher_bound)+(1-rate)*log(x_anchor)));
                            y_lower = rate*app.main_sequence_y_lower_bound+(1-rate)*y_anchor;
                            y_higher = rate*app.main_sequence_y_higher_bound+(1-rate)*y_anchor;
                        elseif ~app.XAxisLogScaleCheckBox.Value && app.YAxisLogScaleCheckBox.Value
                            x_lower = rate*app.main_sequence_x_lower_bound+(1-rate)*x_anchor;
                            x_higher = rate*app.main_sequence_x_higher_bound+(1-rate)*x_anchor;
                            y_lower = real(exp(rate*log(app.main_sequence_y_lower_bound)+(1-rate)*log(y_anchor)));
                            y_higher = real(exp(rate*log(app.main_sequence_y_higher_bound)+(1-rate)*log(y_anchor)));
                        else
                            x_lower = rate*app.main_sequence_x_lower_bound+(1-rate)*x_anchor;
                            x_higher = rate*app.main_sequence_x_higher_bound+(1-rate)*x_anchor;
                            y_lower = rate*app.main_sequence_y_lower_bound+(1-rate)*y_anchor;
                            y_higher = rate*app.main_sequence_y_higher_bound+(1-rate)*y_anchor;
                        end
                        change_main_sequence_higher_lower_bound(app, x_lower, x_higher, y_lower, y_higher);
                    else
                        disp('Error value for vertical scroll count')
                    end
                end
                if (check_in_trace_plot)
                    if (verticalScrollCount == 1)
                        app.current_task = 'ZoomOutTrace';
                        app.trace_plot_magnification_rate = app.trace_plot_magnification_rate + 0.1;
                    elseif (verticalScrollCount == -1)
                        app.current_task = 'ZoomInWindowTrace';
                        app.trace_plot_magnification_rate = app.trace_plot_magnification_rate - 0.1;
                    end
                end
                time_range_plot(app);
                main_sequence_plot(app);
                trace_plot(app);
                highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                highlight_labeled_region_plot(app);
                highlight_ROI_plot(app);
                show_window_time_duration(app);
                app.current_task = '';
            end
        end

        % Menu selected function: ReadEyeMovementFileMenu
        function ReadEyeMovementFileMenuSelected(app, event)
            if isempty(app.current_task)
                app.current_task = 'ReadEyeMovementFile';
                disp('Reading eye movement file...');
                progress_dlg = uiprogressdlg(app.MyUIFigure, 'Title','Please Wait','Message','Reading Eye Movement File');
                try
                    [FileName, PathName] = uigetfile('.mat', 'Read Eye Movement File');
                    assert(~isnumeric(FileName), 'Eye Movement File not received')
                    if (exist([PathName FileName], 'file'))
                        temp_eye_movement_file_path = [PathName FileName];
                        load(temp_eye_movement_file_path, 'eye_movement_data');
                        change_eye_movement_data(app, eye_movement_data);
                        progress_dlg.Value = 0.5;
                        progress_dlg.Message = 'Calculating Saccade Features';
                        calc_saccade_detail(app);
    
                        trace_plot_init(app)
                        main_sequence_plot_and_highlight_init(app)
                        time_range_plot_and_highlight_init(app)
                        app.eye_movement_file_path = temp_eye_movement_file_path;
                        app.session_directory = '';
                        app.recording_id_list = {};
                        app.recording_id = '';
                        app.signal_time_length = app.time(end);
                        app.window_time_onset = 0;
                        app.window_time_duration = 1000;
                        app.window_time_offset = min(app.window_time_duration+app.window_time_onset, ...
                            app.signal_time_length);
                        app.main_sequence_selected_trace = 'nonreference'; % nonreference at first, either 'reference' or 'nonreference' after this
                        app.main_sequence_showing_mode = 'all'; % all at first, either 'all', 'inclusive' or 'exclusive' after this.
                        app.main_sequence_x_feature = "Am";
                        app.main_sequence_y_feature = "Vm";
                        app.trace_plot_magnification_rate = 1.2;
                        app.move_time_length = 200;
                        app.select_time_first = -1;
                        app.select_time_second = -1;
                        app.labeled_saccade_trace_class = ''; % empty at first, either 'reference' or 'nonreference' once starting labeling
                        app.labeled_saccade_onset_time = -1;
                        app.labeled_saccade_offset_time = -1;
                        app.main_sequence_anchor_x = nan;
                        app.main_sequence_anchor_y = nan;
                        app.main_sequence_anchor_bounds = [];
                        app.ROI_position = [];
                        app.ROI_x_feature = '';
                        app.ROI_y_feature = '';
                        app.mouse_down_flag = false;
                        app.show_target_trace_flag = true;
                        app.click_type = '';
                        app.click_type_exclude_open = '';
                        app.RecordingDropDown.Items = {FileName};
                        app.outlier_percentage_threshold = 0;
                        backup_save(app);
                        main_sequence_dropdown_init(app);
                        stack_init(app);
                        count_saccade(app);
                        time_range_plot(app);
                        main_sequence_and_ROI_auto_scaled_plot(app);
                        trace_plot(app);
                        show_saccade_number(app);
                        show_frequency(app);
                        show_move_length(app);
                        show_window_time_duration(app);
                        show_labeled_saccade_on_which_trace(app);
                        show_main_sequence_X_Y_axis_feature_name(app);
                        disp('Reading eye movement file succeed');
                    else
                        disp('Reading fail: Eye movement file not found')
                        fail_dlg = warndlg('Reading failed: Eye movement file not found');
                        uiwait(fail_dlg)
                    end
                catch ME
                    disp(ME.message)
                    fail_dlg = warndlg('Reading failed: command window for more details');
                    uiwait(fail_dlg)
                end
                progress_dlg.Value = 1;
                progress_dlg.Message = 'Done';
                close(progress_dlg);
                app.current_task = '';
            else
                disp('Reading failed: please finish current manipulation before reading.')
            end
        end

        % Menu selected function: SaveEyeMovementFileMenu
        function SaveEyeMovementFileMenuSelected(app, event)
            if isempty(app.current_task)
                app.current_task = 'SaveEyeMovementFile';
                disp('Saving eye movement file...');
                progress_dlg = uiprogressdlg(app.MyUIFigure, 'Title','Please Wait','Message','Saving Eye Movement File');
                try
                    if (exist(app.eye_movement_file_path, 'file'))
                        load(app.eye_movement_file_path, 'eye_movement_data');
                        eye_movement_data.Saccade = reshape(app.Saccade, size(eye_movement_data.X));
                        curation_metadata_add_on(app);
                        eye_movement_data.CurationMetadata = app.curation_metadata;
                        save(app.eye_movement_file_path, "eye_movement_data");
                        backup_save(app);
                        stack_init(app);
                        disp('Saving eye movement file succeed')
                    else
                        disp('Saving failed: Eye movement file not indicated or exist, please use save as function instead')
                        fail_dlg = warndlg('Saving failed: Eye movement file not indicated or exist, please use save as function instead');
                        uiwait(fail_dlg)
                    end
                catch ME
                    disp(ME.message)
                    fail_dlg = warndlg('Saving failed: command window for more details');
                    uiwait(fail_dlg)
                end
                progress_dlg.Value = 1;
                progress_dlg.Message = 'Done';
                close(progress_dlg);
                app.current_task = '';
            else
                disp('Saving failed: please finish current manipulation before saving.')
            end
        end

        % Menu selected function: SaveEyeMovementFileAsMenu
        function SaveEyeMovementFileAsMenuSelected(app, event)
            if isempty(app.current_task)
                app.current_task = 'SaveEyeMovementFileAs';
                disp('Saving eye movement file...');
                progress_dlg = uiprogressdlg(app.MyUIFigure, 'Title','Please Wait','Message','Saving As Eye Movement File');
                if ~isempty(app.session_directory) && ~isempty(app.recording_id)
                    recording_dir = [app.session_directory '\' app.recording_id];
                    [raw_data_path, sac_path, ~] = JYZ_Recording_Path_Finder_for_GUI(recording_dir);
                    if ~strcmp(raw_data_path, '0') && exist(sac_path, "file") && exist(raw_data_path, "file")
                        [FileName, PathName] = uiputfile([sac_path(1:end-10) '_eye_movement_data.mat']);
                    else
                        [FileName, PathName] = uiputfile('eye_movement_data.mat');
                    end
                else
                    [FileName, PathName] = uiputfile('eye_movement_data.mat');
                end
                try
                    if (~isnumeric(FileName))
                        if app.reference_flag
                            if isempty(app.SaccadeTag)
                                eye_movement_data = struct('X', app.X, 'Y', app.Y, 'Frequency', app.frequency, 'Saccade', app.Saccade, 'SaccadeReference', app.SaccadeReference);
                            else
                                eye_movement_data = struct('X', app.X, 'Y', app.Y, 'Frequency', app.frequency, 'Saccade', app.Saccade, 'SaccadeReference', app.SaccadeReference, 'SaccadeTag', app.SaccadeTag);
                            end
                        else
                            eye_movement_data = struct('X', app.X, 'Y', app.Y, 'Frequency', app.frequency, 'Saccade', app.Saccade);
                        end
                        if ~isnan(app.primary_target_amplitude) && ~isnan(app.secondary_target_amplitude) && ...
                                ~isnan(app.X_origin) && ~isnan(app.Y_origin)
                            eye_movement_data.PrimaryTargetAmplitude = app.primary_target_amplitude;
                            eye_movement_data.SecondaryTargetAmplitude = app.secondary_target_amplitude;
                            eye_movement_data.XOrigin = app.X_origin;
                            eye_movement_data.YOrigin = app.Y_origin;
                        end
                        if ~isempty(app.TrialList) && ~isempty(app.X_primary_target_for_trial) && ...
                                ~isempty(app.Y_primary_target_for_trial) && ~isempty(app.X_secondary_target_for_trial) && ...
                                ~isempty(app.Y_secondary_target_for_trial)
                            eye_movement_data.TrialList = app.TrialList;
                            eye_movement_data.XPrimaryTargetForTrial = app.X_primary_target_for_trial;
                            eye_movement_data.YPrimaryTargetForTrial = app.Y_primary_target_for_trial;
                            eye_movement_data.XSecondaryTargetForTrial = app.X_secondary_target_for_trial;
                            eye_movement_data.YSecondaryTargetForTrial = app.Y_secondary_target_for_trial;
                        end
                        if ~isempty(app.X_target) && ~isempty(app.Y_target)
                            eye_movement_data.XTarget = app.X_target;
                            eye_movement_data.YTarget = app.Y_target;
                        end
                        if ~isempty(app.TrialTag)
                            eye_movement_data.TrialTag = app.TrialTag;
                        end
                        if ~isempty(app.Probability)
                            eye_movement_data.Probability = app.Probability;
                        end
                        curation_metadata_add_on(app);
                        eye_movement_data.CurationMetadata = app.curation_metadata;
                        save([PathName, FileName], "eye_movement_data");
                        app.eye_movement_file_path = [PathName, FileName];
                        backup_save(app);
                        stack_init(app);
                        disp('Saving as eye movement file succeed')
                    else
                        disp('Saving failed: Eye movement file not saved')
                        fail_dlg = warndlg('Saving failed: Eye movement file not saved');
                        uiwait(fail_dlg)
                    end
                catch ME
                    disp(ME.message)
                    fail_dlg = warndlg('Saving failed: command window for more details');
                    uiwait(fail_dlg)
                end
                progress_dlg.Value = 1;
                progress_dlg.Message = 'Done';
                close(progress_dlg);
                app.current_task = '';
            else
                disp('Saving failed: please finish current manipulation before saving.')
            end
        end

        % Value changed function: MainSequenceSelectedTraceDropDown
        function MainSequenceSelectedTraceDropDownValueChanged(app, event)
            if isempty(app.current_task)
                app.current_task = 'MainSequenceChangeTrace';
                value = app.MainSequenceSelectedTraceDropDown.Value;
                if strcmp(value, 'Saccade')
                    app.main_sequence_selected_trace = 'nonreference';
                elseif strcmp(value, 'Saccade(ref)')
                    app.main_sequence_selected_trace = 'reference';
                else
                    assert(false, 'value from app.MainSequenceSelectedTraceDropDown could only be Saccade or Saccadef(ref), debug the code!')
                end
                count_saccade(app);
                main_sequence_plot(app);
                highlight_ROI_plot(app);
                show_saccade_number(app);
                app.current_task = '';
            else
                if strcmp(app.main_sequence_selected_trace, 'nonreference')
                    app.MainSequenceSelectedTraceDropDown.Value =  'Saccade';
                elseif strcmp(app.main_sequence_selected_trace, 'reference')
                    app.MainSequenceSelectedTraceDropDown.Value =  'Saccade(ref)';
                else
                    assert(false, 'value from app.main_sequence_selected_trace could only be nonreference or reference, debug the code!')
                end
                disp('Main Sequence Change failed: please finish current manipulation before Changing.')
            end
        end

        % Value changed function: MainSequenceShowingModeDropDown
        function MainSequenceShowingModeDropDownValueChanged(app, event)
            if isempty(app.current_task)
                app.current_task = 'MainSequenceChangeMode';
                value = app.MainSequenceShowingModeDropDown.Value;
                if strcmp(value, 'all') || strcmp(value, 'inclusive') || strcmp(value, 'exclusive')
                    app.main_sequence_showing_mode = value;
                else
                    assert(false, 'value from app.MainSequenceShowingModeDropDown could only be all, inclusive or exclusive, debug the code!')
                end
                count_saccade(app);
                main_sequence_plot(app);
                highlight_ROI_plot(app);
                show_saccade_number(app);
                app.current_task = '';
            else
                app.MainSequenceShowingModeDropDown.Value =  app.main_sequence_showing_mode;
                disp('Main Sequence Change failed: please finish current manipulation before Changing.')
            end
        end

        % Menu selected function: ViewHelpMenu
        function ViewHelpMenuSelected(app, event)
            if isempty(app.current_task)
                app.current_task = 'ViewHelp';
                %hide_all_objects(app);
                %view_help_app = JYZ_view_help_for_manual_curation_GUI();
                %uiwait(view_help_app.UIFigure);
                %show_all_objects(app);
                JYZ_view_help_for_manual_curation_GUI();
                app.current_task = '';
            else
                disp('View help failed: please finish current manipulation before viewing.')
            end
        end

        % Value changed function: MainSequenceXFeatureDropDown
        function MainSequenceXFeatureDropDownValueChanged(app, event)
            if isempty(app.current_task)
                app.current_task = 'MainSequenceChangeXFeature';
                value = app.MainSequenceXFeatureDropDown.Value;
                if strcmp(value, 'Maximum Amplitude')
                    app.main_sequence_x_feature = "Am";
                elseif strcmp(value, 'Maximum Velocity')
                    app.main_sequence_x_feature = "Vm";
                elseif strcmp(value, 'Duration')
                    app.main_sequence_x_feature = "duration";
                elseif strcmp(value, 'Mean Velocity')
                    app.main_sequence_x_feature = "mean_velocity";
                elseif strcmp(value, 'Q Factor')
                    app.main_sequence_x_feature = "Q_factor";
                elseif strcmp(value, 'Acceleration Time')
                    app.main_sequence_x_feature = "acceleration_time";
                elseif strcmp(value, 'Deceleration Time')
                    app.main_sequence_x_feature = "deceleration_time";
                elseif strcmp(value, 'Acc/dec Ratio')
                    app.main_sequence_x_feature = "acc_dec_ratio";
                elseif strcmp(value, 'Amplitude')
                    app.main_sequence_x_feature = "amplitude";
                elseif strcmp(value, 'Confidence')
                    app.main_sequence_x_feature = "confidence";
                elseif strcmp(value, 'Start Point X')
                    app.main_sequence_x_feature = "start_point_x";
                elseif strcmp(value, 'Start Point Y')
                    app.main_sequence_x_feature = "start_point_y";
                elseif strcmp(value, 'Start Point Radius')
                    app.main_sequence_x_feature = "start_point_radius";
                elseif strcmp(value, 'End Point X')
                    app.main_sequence_x_feature = "end_point_x";
                elseif strcmp(value, 'End Point Y')
                    app.main_sequence_x_feature = "end_point_y";
                elseif strcmp(value, 'End Point Radius')
                    app.main_sequence_x_feature = "end_point_radius";
                elseif strcmp(value, 'Angle')
                    app.main_sequence_x_feature = "angle";
                elseif strcmp(value, 'Nan Distance')
                    app.main_sequence_x_feature = "nan_distance";
                elseif strcmp(value, 'Trial Start Distance')
                    app.main_sequence_x_feature = "trial_start_distance";
                elseif strcmp(value, 'Trial End Distance')
                    app.main_sequence_x_feature = "trial_end_distance";
                elseif strcmp(value, 'Saccade Tag')
                    app.main_sequence_x_feature = "tag";
                else
                    assert(false, 'value from app.MainSequenceXFeatureDropDown does not match any case here, debug the code!')
                end
                main_sequence_and_ROI_auto_scaled_plot(app);
                show_main_sequence_X_Y_axis_feature_name(app);
                app.current_task = '';
            else
                main_sequence_dropdown_init(app);
                disp('Main Sequence Change failed: please finish current manipulation before Changing.')
            end
        end

        % Value changed function: MainSequenceYFeatureDropDown
        function MainSequenceYFeatureDropDownValueChanged(app, event)
            if isempty(app.current_task)
                app.current_task = 'MainSequenceChangeYFeature';
                value = app.MainSequenceYFeatureDropDown.Value;
                if strcmp(value, 'Maximum Amplitude')
                    app.main_sequence_y_feature = "Am";
                elseif strcmp(value, 'Maximum Velocity')
                    app.main_sequence_y_feature = "Vm";
                elseif strcmp(value, 'Duration')
                    app.main_sequence_y_feature = "duration";
                elseif strcmp(value, 'Mean Velocity')
                    app.main_sequence_y_feature = "mean_velocity";
                elseif strcmp(value, 'Q Factor')
                    app.main_sequence_y_feature = "Q_factor";
                elseif strcmp(value, 'Acceleration Time')
                    app.main_sequence_y_feature = "acceleration_time";
                elseif strcmp(value, 'Deceleration Time')
                    app.main_sequence_y_feature = "deceleration_time";
                elseif strcmp(value, 'Acc/dec Ratio')
                    app.main_sequence_y_feature = "acc_dec_ratio";
                elseif strcmp(value, 'Amplitude')
                    app.main_sequence_y_feature = "amplitude";
                elseif strcmp(value, 'Confidence')
                    app.main_sequence_y_feature = "confidence";
                elseif strcmp(value, 'Start Point X')
                    app.main_sequence_y_feature = "start_point_x";
                elseif strcmp(value, 'Start Point Y')
                    app.main_sequence_y_feature = "start_point_y";
                elseif strcmp(value, 'Start Point Radius')
                    app.main_sequence_y_feature = "start_point_radius";
                elseif strcmp(value, 'End Point X')
                    app.main_sequence_y_feature = "end_point_x";
                elseif strcmp(value, 'End Point Y')
                    app.main_sequence_y_feature = "end_point_y";
                elseif strcmp(value, 'End Point Radius')
                    app.main_sequence_y_feature = "end_point_radius";
                elseif strcmp(value, 'Angle')
                    app.main_sequence_y_feature = "angle";
                elseif strcmp(value, 'Nan Distance')
                    app.main_sequence_y_feature = "nan_distance";
                elseif strcmp(value, 'Trial Start Distance')
                    app.main_sequence_y_feature = "trial_start_distance";
                elseif strcmp(value, 'Trial End Distance')
                    app.main_sequence_y_feature = "trial_end_distance";
                elseif strcmp(value, 'Saccade Tag')
                    app.main_sequence_y_feature = "tag";
                else
                    assert(false, 'value from app.MainSequenceYFeatureDropDown does not match any case here, debug the code!')
                end
                main_sequence_and_ROI_auto_scaled_plot(app);
                show_main_sequence_X_Y_axis_feature_name(app);
                app.current_task = '';
            else
                main_sequence_dropdown_init(app);
                disp('Main Sequence Change failed: please finish current manipulation before Changing.')
            end
        end

        % Value changed function: XAxisLogScaleCheckBox
        function XAxisLogScaleCheckBoxValueChanged(app, event)
            if isempty(app.current_task)
                app.current_task = 'ChangeMainSequencePlotXLogScale';
                value = app.XAxisLogScaleCheckBox.Value;
                if value
                    app.MainSequenceUIAxes.XScale = "log";
                else
                    app.MainSequenceUIAxes.XScale = "linear";
                end
                main_sequence_and_ROI_auto_scaled_plot(app);
                highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                app.current_task = '';
            else
                disp('Change Log Scale failed: please finish current manipulation before viewing.')
            end
        end

        % Value changed function: YAxisLogScaleCheckBox
        function YAxisLogScaleCheckBoxValueChanged(app, event)
            if isempty(app.current_task)
                app.current_task = 'ChangeMainSequencePlotYLogScale';
                value = app.YAxisLogScaleCheckBox.Value;
                if value
                    app.MainSequenceUIAxes.YScale = "log";
                else
                    app.MainSequenceUIAxes.YScale = "linear";
                end
                main_sequence_and_ROI_auto_scaled_plot(app);
                highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                app.current_task = '';
            else
                disp('Change Log Scale failed: please finish current manipulation before viewing.')
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            ReadEyeMovementFileMenuSelected(app, event);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SaveEyeMovementFileMenuSelected(app, event);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if isempty(app.current_task)
                app.current_task = 'Undo';
                stack_pop(app);
                count_saccade(app);
                time_range_plot(app);
                main_sequence_plot(app);
                trace_plot(app);
                if app.mouse_down_flag
                    highlight_mouse_region_plot(app, app.select_time_first, app.SaccadeUIAxes.CurrentPoint(1,1));
                else
                    highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                end
                highlight_labeled_region_plot(app);
                highlight_ROI_plot(app);
                show_saccade_number(app);
                show_move_length(app);
                show_labeled_saccade_on_which_trace(app);
                app.current_task = '';
            else
                disp('Undo failed: please finish current manipulation before undoing.')
            end
        end

        % Button pushed function: UndoAllButton
        function UndoAllButtonPushed(app, event)
            if isempty(app.current_task)
                app.current_task = 'UndoAll';
                option = uiconfirm(app.MyUIFigure, 'Do you want to undo all modification?', ...
                    'Undo All Warning', 'Options', {'yes, undo all', 'no, return'}, 'DefaultOption', 2, 'CancelOption', 2);
                switch option
                    case 'no, return'
                    case 'yes, undo all'
                        backup_recovery(app);
                        stack_init(app);
                        count_saccade(app);
                        time_range_plot(app);
                        main_sequence_plot(app);
                        trace_plot(app);
                        if app.mouse_down_flag
                            highlight_mouse_region_plot(app, app.select_time_first, app.SaccadeUIAxes.CurrentPoint(1,1));
                        else
                            highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                        end
                        highlight_labeled_region_plot(app);
                        highlight_ROI_plot(app);
                        show_saccade_number(app);
                        show_move_length(app);
                        show_labeled_saccade_on_which_trace(app);
                end
                app.current_task = '';
            else
                disp('Undo All failed: please finish current manipulation before undoing.')
            end
        end

        % Button pushed function: GoStartButton
        function GoStartButtonPushed(app, event)
            if isempty(app.current_task)
                app.current_task = 'GoToStart';
                change_time_onset_and_time_offset(app, 0);
                count_saccade(app);
                time_range_plot(app);
                main_sequence_plot(app);
                trace_plot(app);
                if app.mouse_down_flag
                    highlight_mouse_region_plot(app, app.select_time_first, app.SaccadeUIAxes.CurrentPoint(1,1));
                else
                    highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                end
                highlight_labeled_region_plot(app);
                highlight_ROI_plot(app);
                show_saccade_number(app);
                show_move_length(app);
                show_labeled_saccade_on_which_trace(app);
                app.current_task = '';
            else
                disp('Go to Start failed: please finish current manipulation before going.')
            end
        end

        % Button pushed function: GoEndButton
        function GoEndButtonPushed(app, event)
            if isempty(app.current_task)
                app.current_task = 'GoToEnd';
                change_time_onset_and_time_offset(app, app.time(end));
                count_saccade(app);
                time_range_plot(app);
                main_sequence_plot(app);
                trace_plot(app);
                if app.mouse_down_flag
                    highlight_mouse_region_plot(app, app.select_time_first, app.SaccadeUIAxes.CurrentPoint(1,1));
                else
                    highlight_mouse_region_plot(app, app.select_time_first, app.select_time_second);
                end
                highlight_labeled_region_plot(app);
                highlight_ROI_plot(app);
                show_saccade_number(app);
                show_move_length(app);
                show_labeled_saccade_on_which_trace(app);
                app.current_task = '';
            else
                disp('Go to End failed: please finish current manipulation before going.')
            end
        end

        % Close request function: MyUIFigure
        function MyUIFigureCloseRequest(app, event)
            function CloseFcnForFigure(source, event)
                switch event.SelectedOption
                    case 'no, return'
                    case 'yes, exit'
                        delete(source);
                end
            end
            if ~isequal(app.backup_Saccade, app.Saccade)
                uiconfirm(app.MyUIFigure, 'Saccade Change Detected. Do you want to exit without saving?', ...
                    'Exit Warning', 'Options', {'yes, exit', 'no, return'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'CloseFcn', @CloseFcnForFigure);
            else
                delete(app)
            end
        end

        % Value changed function: OutlierPercentageSlider
        function OutlierPercentageSliderValueChanged(app, event)
            if isempty(app.current_task)
                app.current_task = 'ChangeOutlierPercentage';
                disp('Outlier Dectecting...')
                progress_dlg = uiprogressdlg(app.MyUIFigure, 'Title','Please Wait','Message','Detecting Outliers');
                try
                    value = app.OutlierPercentageSlider.Value;
                    app.outlier_percentage_threshold = value;
                    detect_outliers(app);
                    count_saccade(app);
                    main_sequence_plot(app);
                    highlight_ROI_plot(app);
                    show_saccade_number(app);
                    disp('Detection Done')
                catch ME
                    app.OutlierPercentageSlider.Value = app.outlier_percentage_threshold;
                    disp(ME.message)
                    disp('This might because your saccade number is too small, or your feature value do not have variance. Please select ROI manually')
                    fail_dlg = warndlg('Outlier Detection failed: command window for more details');
                    uiwait(fail_dlg)
                end
                progress_dlg.Value = 1;
                progress_dlg.Message = 'Done';
                close(progress_dlg);
                app.current_task = '';
            else
                disp('Change Outlier Percentage failed: please finish current manipulation before changing.')
            end
        end

        % Button pushed function: DetectOutlierButton
        function DetectOutlierButtonPushed(app, event)
            if isempty(app.current_task)
                app.current_task = 'DetectOutlier';
                progress_dlg = uiprogressdlg(app.MyUIFigure, 'Title','Please Wait','Message','Detecting Outliers');
                try
                    detect_outliers(app);
                    count_saccade(app);
                    main_sequence_plot(app);
                    highlight_ROI_plot(app);
                    show_saccade_number(app);
                    disp('Detection Done')
                catch ME
                    app.OutlierPercentageSlider.Value = app.outlier_percentage_threshold;
                    disp(ME.message)
                    disp('This might because your saccade number is too small, or your feature value do not have variance. Please select ROI manually')
                    fail_dlg = warndlg('Outlier Detection failed: command window for more details');
                    uiwait(fail_dlg)
                end
                progress_dlg.Value = 1;
                progress_dlg.Message = 'Done';
                close(progress_dlg);
                app.current_task = '';
            else
                disp('Change Outlier Percentage failed: please finish current manipulation before changing.')
            end
        end

        % Button pushed function: DefaultDetectOutlierButton
        function DefaultDetectOutlierButtonPushed(app, event)
            if isempty(app.current_task)
                app.current_task = 'DefaultDetectOutlier';
                progress_dlg = uiprogressdlg(app.MyUIFigure, 'Title','Please Wait','Message','Detecting Outliers');
                temp_outlier_percentage_threshold = app.outlier_percentage_threshold;
                try
                    app.MainSequenceSelectedTraceDropDown.Value = 'Saccade';
                    app.main_sequence_selected_trace = 'nonreference';
                    app.MainSequenceShowingModeDropDown.Value = 'all';
                    app.main_sequence_showing_mode = 'all';
                    if ~isempty(app.Probability)
                        app.MainSequenceXFeatureDropDown.Value = 'Confidence';
                        app.main_sequence_x_feature = "confidence";
                    else
                        app.MainSequenceXFeatureDropDown.Value = 'Q Factor';
                        app.main_sequence_x_feature = "Q_factor";
                    end
                    app.MainSequenceYFeatureDropDown.Value = 'Acc/dec Ratio';
                    app.main_sequence_y_feature = "acc_dec_ratio";
                    app.XAxisLogScaleCheckBox.Value = false;
                    app.MainSequenceUIAxes.XScale = "linear";
                    app.YAxisLogScaleCheckBox.Value = true;
                    app.MainSequenceUIAxes.YScale = "log";
                    app.outlier_percentage_threshold = 8;
                    detect_outliers(app);
                    count_saccade(app);
                    while app.ROI_saccade_total/app.global_saccade_total > (app.outlier_percentage_threshold+1)/100 && ...
                            app.outlier_percentage_threshold > 1
                        app.outlier_percentage_threshold = app.outlier_percentage_threshold - 1;
                        detect_outliers(app);
                        count_saccade(app);
                    end
                    if app.outlier_percentage_threshold == 1
                        app.outlier_percentage_threshold = 0;
                        app.OutlierPercentageSlider.Value = app.outlier_percentage_threshold;
                        app.ROI_position = [];
                        app.ROI_x_feature = '';
                        app.ROI_y_feature = '';
                        disp('Unable to auto detect outlier ROI, please manually draw ROI')
                    else
                        app.OutlierPercentageSlider.Value = app.outlier_percentage_threshold;
                        disp('Detection Done')
                    end
                catch ME
                    app.OutlierPercentageSlider.Value = temp_outlier_percentage_threshold;
                    disp(ME.message)
                    disp('This might because your saccade number is too small, your feature value do not have variance, or simply too hard to auto detect. Please select ROI manually')
                    fail_dlg = warndlg('Outlier Detection failed: command window for more details');
                    uiwait(fail_dlg)
                end
                count_saccade(app);
                main_sequence_and_ROI_auto_scaled_plot(app);
                show_saccade_number(app);
                show_main_sequence_X_Y_axis_feature_name(app);
                progress_dlg.Value = 1;
                progress_dlg.Message = 'Done';
                close(progress_dlg);
                app.current_task = '';
            else
                disp('Change Outlier Percentage failed: please finish current manipulation before changing.')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MyUIFigure = uifigure('Visible', 'off');
            app.MyUIFigure.Name = 'MATLAB App';
            app.MyUIFigure.CloseRequestFcn = createCallbackFcn(app, @MyUIFigureCloseRequest, true);
            app.MyUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MyUIFigureWindowButtonDown, true);
            app.MyUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @MyUIFigureWindowButtonUp, true);
            app.MyUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MyUIFigureWindowButtonMotion, true);
            app.MyUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @MyUIFigureWindowScrollWheel, true);
            app.MyUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MyUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.MyUIFigure);
            app.FileMenu.Text = 'File';
            app.ReadEyeMovementFileMenu = uimenu(app.FileMenu);
            app.ReadEyeMovementFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadEyeMovementFileMenuSelected, true);
            app.ReadEyeMovementFileMenu.Text = 'Read Eye Movement File';
            app.SaveEyeMovementFileMenu = uimenu(app.FileMenu);
            app.SaveEyeMovementFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveEyeMovementFileMenuSelected, true);
            app.SaveEyeMovementFileMenu.Text = 'Save Eye Movement File';
            app.SaveEyeMovementFileAsMenu = uimenu(app.FileMenu);
            app.SaveEyeMovementFileAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveEyeMovementFileAsMenuSelected, true);
            app.SaveEyeMovementFileAsMenu.Text = 'Save Eye Movement File As';
            app.HelpMenu = uimenu(app.MyUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ViewHelpMenu = uimenu(app.HelpMenu);
            app.ViewHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewHelpMenuSelected, true);
            app.ViewHelpMenu.Text = 'View Help';
            app.Panel = uipanel(app.MyUIFigure);
            app.Panel.Tag = 'main_panel';
            app.SaccadeUIAxes = uiaxes(app.Panel);
            xlabel(app.SaccadeUIAxes, 'Time(ms)')
            ylabel(app.SaccadeUIAxes, 'Saccade')
            zlabel(app.SaccadeUIAxes, 'Z')
            app.YUIAxes = uiaxes(app.Panel);
            ylabel(app.YUIAxes, 'Y(deg)')
            zlabel(app.YUIAxes, 'Z')
            app.VUIAxes = uiaxes(app.Panel);
            ylabel(app.VUIAxes, 'V(deg/ms)')
            zlabel(app.VUIAxes, 'Z')
            app.XUIAxes = uiaxes(app.Panel);
            ylabel(app.XUIAxes, 'X(deg)')
            zlabel(app.XUIAxes, 'Z')
            app.SaccadeReferenceUIAxes = uiaxes(app.Panel);
            ylabel(app.SaccadeReferenceUIAxes, 'Saccade(ref)')
            zlabel(app.SaccadeReferenceUIAxes, 'Z')
            app.MainSequenceUIAxes = uiaxes(app.Panel);
            zlabel(app.MainSequenceUIAxes, 'Z')
            app.TraceUIAxes = uiaxes(app.Panel);
            title(app.TraceUIAxes, 'XY Trace Plot')
            xlabel(app.TraceUIAxes, 'Y (deg)')
            ylabel(app.TraceUIAxes, 'X (deg)')
            zlabel(app.TraceUIAxes, 'Z')
            app.HelpImage = uiimage(app.Panel);
            app.HelpImage.ImageSource = fullfile(pathToMLAPP, 'figures', 'JYZ_manual_curation_tool_help_image.png');
            app.FrequencyLabel = uilabel(app.Panel);
            app.FrequencyLabel.Text = 'Frequency (1/s): ';
            app.FrequencyNumberLabel = uilabel(app.Panel);
            app.FrequencyNumberLabel.Text = 'NaN';
            app.MoveLengthLabel = uilabel(app.Panel);
            app.MoveLengthLabel.Text = 'Move Length (ms): ';
            app.MoveLengthNumberLabel = uilabel(app.Panel);
            app.MoveLengthNumberLabel.Text = 'NaN';
            app.WindowTimeDurationLabel = uilabel(app.Panel);
            app.WindowTimeDurationLabel.Text = 'Time Duration (ms): ';
            app.WindowTimeDurationNumberLabel = uilabel(app.Panel);
            app.WindowTimeDurationNumberLabel.Text = 'NaN';
            app.SelectedOnTraceLabel = uilabel(app.Panel);
            app.SelectedOnTraceLabel.Text = 'Selected on Trace:';
            app.SelectedOnTraceNumberLabel = uilabel(app.Panel);
            app.SelectedOnTraceNumberLabel.Text = 'NaN';
            app.TraceLabel = uilabel(app.Panel);
            app.TraceLabel.Text = 'Trace:';
            app.MainSequenceSelectedTraceDropDown = uidropdown(app.Panel);
            app.MainSequenceSelectedTraceDropDown.ValueChangedFcn = createCallbackFcn(app, @MainSequenceSelectedTraceDropDownValueChanged, true);
            app.ModeLabel = uilabel(app.Panel);
            app.ModeLabel.Text = 'Mode:';
            app.MainSequenceShowingModeDropDown = uidropdown(app.Panel);
            app.MainSequenceShowingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @MainSequenceShowingModeDropDownValueChanged, true);
            app.XAxisLogScaleCheckBox = uicheckbox(app.Panel);
            app.XAxisLogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @XAxisLogScaleCheckBoxValueChanged, true);
            app.XAxisLogScaleCheckBox.Text = 'X Axis Log Scale';
            app.TraceLabel_2 = uilabel(app.Panel);
            app.TraceLabel_2.Text = 'X Axis:';
            app.TraceLabel_3 = uilabel(app.Panel);
            app.TraceLabel_3.Text = 'Y Axis:';
            app.MainSequenceYFeatureDropDown = uidropdown(app.Panel);
            app.MainSequenceYFeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @MainSequenceYFeatureDropDownValueChanged, true);
            app.MainSequenceXFeatureDropDown = uidropdown(app.Panel);
            app.MainSequenceXFeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @MainSequenceXFeatureDropDownValueChanged, true);
            app.YAxisLogScaleCheckBox = uicheckbox(app.Panel);
            app.YAxisLogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @YAxisLogScaleCheckBoxValueChanged, true);
            app.YAxisLogScaleCheckBox.Text = 'Y Axis Log Scale';
            app.GlobalSaccadeCounterLabel = uilabel(app.Panel);
            app.GlobalSaccadeCounterLabel.Text = 'Global Saccade Counter:';
            app.GlobalSaccadeCounterNumberLabel = uilabel(app.Panel);
            app.GlobalSaccadeCounterNumberLabel.Text = 'NaN';
            app.GlobalSaccadeTotalNumberLabel = uilabel(app.Panel);
            app.GlobalSaccadeTotalNumberLabel.Text = 'NaN';
            app.ROISaccadeCounterLabel = uilabel(app.Panel);
            app.ROISaccadeCounterLabel.Text = 'ROI Saccade Counter:';
            app.ROISaccadeCounterNumberLabel = uilabel(app.Panel);
            app.ROISaccadeCounterNumberLabel.Text = 'NaN';
            app.ROISaccadeTotalNumberLabel = uilabel(app.Panel);
            app.ROISaccadeTotalNumberLabel.Text = 'NaN';
            app.SlashLabel_1 = uilabel(app.Panel);
            app.SlashLabel_1.Text = '/';
            app.SlashLabel_2 = uilabel(app.Panel);
            app.SlashLabel_2.Text = '/';
            app.OutlierPercentagePanel = uipanel(app.Panel);
            app.OutlierPercentageLabel = uilabel(app.OutlierPercentagePanel);
            app.OutlierPercentageLabel.Text = {'Outlier '; 'Percentage %'};
            app.OutlierPercentageSlider = uislider(app.OutlierPercentagePanel);
            app.OutlierPercentageSlider.Limits = [0 50];
            app.OutlierPercentageSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.OutlierPercentageSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50'};
            app.OutlierPercentageSlider.ValueChangedFcn = createCallbackFcn(app, @OutlierPercentageSliderValueChanged, true);
            app.DetectOutlierButton = uibutton(app.OutlierPercentagePanel, 'push');
            app.DetectOutlierButton.ButtonPushedFcn = createCallbackFcn(app, @DetectOutlierButtonPushed, true);
            app.DetectOutlierButton.Text = 'Detect';
            app.DefaultDetectOutlierButton = uibutton(app.OutlierPercentagePanel, 'push');
            app.DefaultDetectOutlierButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultDetectOutlierButtonPushed, true);
            app.DefaultDetectOutlierButton.Text = 'Default Detect';
            app.ToolPanel = uipanel(app.MyUIFigure);
            app.RecordingLabel = uilabel(app.ToolPanel);
            app.RecordingLabel.Text = 'Recording:';
            app.RecordingDropDown = uidropdown(app.ToolPanel);
            app.OpenButton = uibutton(app.ToolPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = fullfile(pathToMLAPP, 'icons', 'JYZ_manual_curation_tool_open_image.png');
            app.OpenButton.Tooltip = {'open session directory'};
            app.OpenButton.Text = '';
            app.SaveButton = uibutton(app.ToolPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'icons', 'JYZ_manual_curation_tool_save_image.png');
            app.SaveButton.Tooltip = {'save recording file'};
            app.SaveButton.Text = '';
            app.UndoButton = uibutton(app.ToolPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Icon = fullfile(pathToMLAPP, 'icons', 'JYZ_manual_curation_tool_undo_image.png');
            app.UndoButton.Tooltip = {'undo'};
            app.UndoButton.Text = '';
            app.UndoAllButton = uibutton(app.ToolPanel, 'push');
            app.UndoAllButton.ButtonPushedFcn = createCallbackFcn(app, @UndoAllButtonPushed, true);
            app.UndoAllButton.Icon = fullfile(pathToMLAPP, 'icons', 'JYZ_manual_curation_tool_undo_all_image.png');
            app.UndoAllButton.Tooltip = {'undo all'};
            app.UndoAllButton.Text = '';
            app.GoStartButton = uibutton(app.ToolPanel, 'push');
            app.GoStartButton.ButtonPushedFcn = createCallbackFcn(app, @GoStartButtonPushed, true);
            app.GoStartButton.Icon = fullfile(pathToMLAPP, 'icons', 'JYZ_manual_curation_tool_go_start_image.png');
            app.GoStartButton.Tooltip = {'go to start'};
            app.GoStartButton.Text = '';
            app.GoEndButton = uibutton(app.ToolPanel, 'push');
            app.GoEndButton.ButtonPushedFcn = createCallbackFcn(app, @GoEndButtonPushed, true);
            app.GoEndButton.Icon = fullfile(pathToMLAPP, 'icons', 'JYZ_manual_curation_tool_go_end_image.png');
            app.GoEndButton.Tooltip = {'go to end'};
            app.GoEndButton.Text = '';
            % Show the figure after all components are created
            app.MyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SACCURATE(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyUIFigure)
        end
    end
end