classdef JYZ_view_help_for_manual_curation_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EffectDescriptionLabel     matlab.ui.control.Label
        EventLabel                 matlab.ui.control.Label
        EffectDescriptionTextArea  matlab.ui.control.TextArea
        EventTextArea              matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'View Help Document';
            % length_display = 30;
            event_text_list = {'event', ...
                'f', ...
                'rightarrow', ...
                's', ...
                'leftarrow', ...
                'e', ...
                'uparrow', ...
                'd', ...
                'downarrow', ...
                'a', ...
                'g', ...
                'q', ...
                't', ...
                'r', ...
                'space', ...
                'h', ...
                'delete', ...
                'backspace', ... 
                'LeftClick&Drag_on_TimeWindow', ...
                'RightClick&Drag_on_TimeWindow', ...
                'LeftDoubleClick_on_TimeWindow', ...
                'RightDoubleClick_on_TimeWindow', ...
                'Scroll_on_TimeWindow', ...
                'Scroll_on_TracePlot', ...
                'LeftClick_on_FeaturePlot', ...
                'RightClick&Drag_on_FeaturePlot', ...
                'Scroll_on_FeaturePlot'};
            description_text_list = {'effect description', ...
                'move time window to the right by move length', ...
                'move time window to the right by move length', ...
                'move time window to the left by move length', ...
                'move time window to the left by move length', ...
                'increase move length', ...
                'increase move length', ...
                'decrease move length', ...
                'decrease move length', ...
                'select and move to previous saccade on Saccade trace', ...
                'select and move to next saccade on Saccade trace', ...
                'select and move to previous saccade on Saccade(ref) trace', ...
                'select and move to next saccade on Saccade(ref) trace', ...
                'select current saccade the mouse is pointing at', ...
                'modify all selected regions to fixation period (0)', ...
                'show or hide target trace in time window', ...
                'batch delete all saccades in ROI', ...
                'undo one step, with up limit of 5 steps', ...
                'manually label saccade period (1)', ...
                'manually label fixation period (0)', ...
                'automatically label saccade period (1)', ...
                'automatically label fixation period (0)', ...
                'change window time duration displayed', ...
                'zoom in or zoom out trace plot', ...
                'selected ROI which could be selected, finished with left double click or quit with right click', ...
                'move feature plot', ...
                'zoom in or zoom out feature plot'};
            for i = 1:1:length(event_text_list)
                % event_text = [blanks(2*(length_display-length(event_text_list{i}))) event_text_list{i} ': ' description_text_list{i}];
                app.EventTextArea.Value{i} = event_text_list{i};
                app.EffectDescriptionTextArea.Value{i} = [': ' description_text_list{i}];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EventTextArea = uitextarea(app.UIFigure);
            app.EventTextArea.Editable = 'off';
            app.EffectDescriptionTextArea = uitextarea(app.UIFigure);
            app.EffectDescriptionTextArea.Editable = 'off';
            app.EventLabel = uilabel(app.UIFigure);
            app.EventLabel.Text = 'Event';
            app.EffectDescriptionLabel = uilabel(app.UIFigure);
            app.EffectDescriptionLabel.Text = 'Effect Description';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JYZ_view_help_for_manual_curation_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end